classdef GSLab < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GSLabUIFigure                   matlab.ui.Figure
        Suman                           matlab.ui.control.CheckBox
        colpost                         matlab.ui.control.CheckBox
        AxisContainer                   matlab.ui.container.Panel
        TabEixos                        matlab.ui.control.UIAxes
        CalibHolder                     matlab.ui.container.Panel
        Calibrationtype                 matlab.ui.container.ButtonGroup
        Manualcalib                     matlab.ui.control.RadioButton
        Filecalib                       matlab.ui.control.RadioButton
        CalibrationLifetimensLabel      matlab.ui.control.Label
        LT                              matlab.ui.control.NumericEditField
        DoCalib                         matlab.ui.control.CheckBox
        CalibrationPanel                matlab.ui.container.Panel
        manualcalibpanel                matlab.ui.container.Panel
        modL                            matlab.ui.control.Label
        phaL                            matlab.ui.control.Label
        modS                            matlab.ui.control.Spinner
        phaS                            matlab.ui.control.Spinner
        ClearButton                     matlab.ui.control.Button
        calTable                        matlab.ui.control.Table
        TabGroup                        matlab.ui.container.TabGroup
        FileTab                         matlab.ui.container.Tab
        hidecalib                       matlab.ui.control.Button
        Tree                            matlab.ui.container.CheckBoxTree
        ImageTab                        matlab.ui.container.Tab
        IntensityLabel_2                matlab.ui.control.Label
        GaussianFilter                  matlab.ui.control.NumericEditField
        LoadmaskButton                  matlab.ui.control.Button
        masktype                        matlab.ui.control.DropDown
        hdr                             matlab.ui.control.CheckBox
        logint                          matlab.ui.control.CheckBox
        invertmk                        matlab.ui.control.CheckBox
        Applymask                       matlab.ui.control.CheckBox
        relative                        matlab.ui.control.CheckBox
        RGBLabel                        matlab.ui.control.Label
        ChanButton4                     matlab.ui.control.Button
        ChanButton3                     matlab.ui.control.Button
        SavemaskButton                  matlab.ui.control.Button
        TomaskButton                    matlab.ui.control.Button
        Logp                            matlab.ui.control.CheckBox
        Excess                          matlab.ui.control.CheckBox
        Islide                          matlab.ui.control.RangeSlider
        IntensityLabel                  matlab.ui.control.Label
        Imin                            matlab.ui.control.NumericEditField
        Imax                            matlab.ui.control.NumericEditField
        MKax                            matlab.ui.control.UIAxes
        histax                          matlab.ui.control.UIAxes
        PhasorTab                       matlab.ui.container.Tab
        LoadCursorsButton               matlab.ui.control.Button
        SaveCursorsButton               matlab.ui.control.Button
        AutoButton                      matlab.ui.control.Button
        DownsamplingEditField           matlab.ui.control.NumericEditField
        DownsamplingEditFieldLabel      matlab.ui.control.Label
        Filterbefore                    matlab.ui.control.CheckBox
        PlotTrueSize                    matlab.ui.control.Button
        PhasorresolutionEditField       matlab.ui.control.NumericEditField
        PhasorresolutionEditFieldLabel  matlab.ui.control.Label
        MaxharmonicsEditField           matlab.ui.control.NumericEditField
        MaxharmonicsEditFieldLabel      matlab.ui.control.Label
        Filtermatch                     matlab.ui.control.Label
        MeasuredmagnitudeButtonGroup    matlab.ui.container.ButtonGroup
        CustomAngular                   matlab.ui.control.RadioButton
        CustomRadial                    matlab.ui.control.RadioButton
        TauNormrad                      matlab.ui.control.RadioButton
        NormPhaserad                    matlab.ui.control.RadioButton
        TauModrad                       matlab.ui.control.RadioButton
        Modrad                          matlab.ui.control.RadioButton
        TauPhaserad                     matlab.ui.control.RadioButton
        Phaserad                        matlab.ui.control.RadioButton
        Grad                            matlab.ui.control.RadioButton
        Srad                            matlab.ui.control.RadioButton
        ColorrangeEditFieldLabel        matlab.ui.control.Label
        GrangeEditFieldLabel            matlab.ui.control.Label
        SrangeEditFieldLabel            matlab.ui.control.Label
        IterationsLabel                 matlab.ui.control.Label
        CenterLabel                     matlab.ui.control.Label
        Scenter                         matlab.ui.control.NumericEditField
        EbLabel                         matlab.ui.control.Label
        Gcenter                         matlab.ui.control.NumericEditField
        Cmax                            matlab.ui.control.NumericEditField
        Cmin                            matlab.ui.control.NumericEditField
        Gmax                            matlab.ui.control.NumericEditField
        Gmin                            matlab.ui.control.NumericEditField
        Smax                            matlab.ui.control.NumericEditField
        Smin                            matlab.ui.control.NumericEditField
        Ti                              matlab.ui.control.NumericEditField
        Eb                              matlab.ui.control.NumericEditField
        DisplayTab                      matlab.ui.container.Tab
        CmapFlipMag                     matlab.ui.control.CheckBox
        CmapFlipDens                    matlab.ui.control.CheckBox
        freqEF                          matlab.ui.control.NumericEditField
        freqLab                         matlab.ui.control.Label
        SpecMax                         matlab.ui.control.NumericEditField
        SpecMin                         matlab.ui.control.NumericEditField
        sBandLab                        matlab.ui.control.Label
        CursorcolorsPanel               matlab.ui.container.Panel
        fontsizeLabel_2                 matlab.ui.control.Label
        fliprows                        matlab.ui.control.CheckBox
        margns                          matlab.ui.control.CheckBox
        imagecols                       matlab.ui.control.NumericEditField
        imagerows                       matlab.ui.control.NumericEditField
        tilinglabel                     matlab.ui.control.Label
        imagewindowtile                 matlab.ui.control.CheckBox
        SoftCursor                      matlab.ui.control.CheckBox
        PhBg                            matlab.ui.control.CheckBox
        AFSef                           matlab.ui.control.NumericEditField
        tickfontsize                    matlab.ui.control.NumericEditField
        Harm                            matlab.ui.control.NumericEditField
        tickNumber                      matlab.ui.control.NumericEditField
        ticks                           matlab.ui.control.CheckBox
        UCcb                            matlab.ui.control.CheckBox
        contours                        matlab.ui.control.CheckBox
        normFile                        matlab.ui.control.CheckBox
        logDens                         matlab.ui.control.CheckBox
        RainbowcolormapDD               matlab.ui.control.DropDown
        PhasordensitycolorLabel_2       matlab.ui.control.Label
        PhasorcolormapDD                matlab.ui.control.DropDown
        PhasordensitycolorLabel         matlab.ui.control.Label
        Style                           matlab.ui.container.ButtonGroup
        UmapStyle                       matlab.ui.control.RadioButton
        SpectralStyle                   matlab.ui.control.RadioButton
        LifetimeStyle                   matlab.ui.control.RadioButton
        fontsizeLabel                   matlab.ui.control.Label
        AxesfontsizeEditFieldLabel_2    matlab.ui.control.Label
        HarmonicLabel                   matlab.ui.control.Label
        UnmixTab                        matlab.ui.container.Tab
        ComponentTree                   matlab.ui.container.CheckBoxTree
        SourceButtonGroup               matlab.ui.container.ButtonGroup
        CursorButton                    matlab.ui.control.RadioButton
        FileButton                      matlab.ui.control.RadioButton
        InputButton                     matlab.ui.control.RadioButton
        mergecomps                      matlab.ui.control.CheckBox
        groupfile                       matlab.ui.control.CheckBox
        includeint                      matlab.ui.control.CheckBox
        addmargins                      matlab.ui.control.CheckBox
        singleimage                     matlab.ui.control.CheckBox
        ratiocsv                        matlab.ui.control.CheckBox
        comp2                           matlab.ui.control.NumericEditField
        comp1                           matlab.ui.control.NumericEditField
        ComponentvsLabel                matlab.ui.control.Label
        exportratiometric               matlab.ui.control.CheckBox
        CompImsCol                      matlab.ui.control.CheckBox
        CompImsCSVExp                   matlab.ui.control.CheckBox
        CompImsIntExp                   matlab.ui.control.CheckBox
        CompImsExp                      matlab.ui.control.CheckBox
        ComputeLR                       matlab.ui.control.Button
        ComputeHR                       matlab.ui.control.Button
        AddLines                        matlab.ui.control.CheckBox
        ShowComponents                  matlab.ui.control.CheckBox
        ComponentsPanel                 matlab.ui.container.Panel
        minusB                          matlab.ui.control.Button
        plusB                           matlab.ui.control.Button
        ClusterTab                      matlab.ui.container.Tab
        gmmexport                       matlab.ui.control.CheckBox
        blendamount                     matlab.ui.control.NumericEditField
        blend                           matlab.ui.control.CheckBox
        ApplyGMMButton                  matlab.ui.control.Button
        dispStyle2                      matlab.ui.container.ButtonGroup
        prob                            matlab.ui.control.RadioButton
        hard                            matlab.ui.control.RadioButton
        dispStyle                       matlab.ui.container.ButtonGroup
        conf                            matlab.ui.control.NumericEditField
        elipse                          matlab.ui.control.RadioButton
        contseparate                    matlab.ui.control.RadioButton
        conttogether                    matlab.ui.control.RadioButton
        ClearGMMButton                  matlab.ui.control.Button
        TrainGMMButton                  matlab.ui.control.Button
        ExportTab                       matlab.ui.container.Tab
        allasLabel                      matlab.ui.control.Label
        groupfiles                      matlab.ui.control.CheckBox
        Combine                         matlab.ui.control.CheckBox
        ExCurS                          matlab.ui.control.CheckBox
        ExCurH                          matlab.ui.control.CheckBox
        ExMagS                          matlab.ui.control.CheckBox
        ExMagH                          matlab.ui.control.CheckBox
        MagValFmtDropDown               matlab.ui.control.DropDown
        ExMagV                          matlab.ui.control.CheckBox
        ExportImageFileDropDown         matlab.ui.control.DropDown
        ExInt                           matlab.ui.control.CheckBox
        ReLab                           matlab.ui.control.Label
        ExportPhasorFileDropDown        matlab.ui.control.DropDown
        ExPhasor                        matlab.ui.control.CheckBox
        Expvec                          matlab.ui.control.CheckBox
        Expptrans                       matlab.ui.control.CheckBox
        Expwithax                       matlab.ui.control.CheckBox
        PhRb                            matlab.ui.control.CheckBox
        Cbar                            matlab.ui.control.CheckBox
        DeacayFileDropDown              matlab.ui.control.DropDown
        ExDecay                         matlab.ui.control.CheckBox
        ExpPhWinButton                  matlab.ui.control.Button
        ExpImWinButton                  matlab.ui.control.Button
        ExportHRButton                  matlab.ui.control.Button
        ExportLRButton                  matlab.ui.control.Button
        BrowseResults                   matlab.ui.control.Button
        Botonera                        matlab.ui.container.Panel
        X                               matlab.ui.control.Button
        PhasorPanel                     matlab.ui.container.Panel
        coolab                          matlab.ui.control.Label
        PP                              matlab.ui.control.UIAxes
        ContextMenu                     matlab.ui.container.ContextMenu
        CalibrationFileMenu             matlab.ui.container.Menu
        Load                            matlab.ui.container.Menu
        FLAMEcalibration                matlab.ui.container.Menu
        LoadTIFSequenceContextMenu      matlab.ui.container.Menu
        LoadFLAMErawContextMenu         matlab.ui.container.Menu
        LoadFALCONSnGContextMenu        matlab.ui.container.Menu
        LoadZeisslsmContextMenu         matlab.ui.container.Menu
        LoadSimFCSrefContextMenu        matlab.ui.container.Menu
        SavedSessionMenu                matlab.ui.container.Menu
        SaveMenu                        matlab.ui.container.Menu
        asSimFCSrefMenu                 matlab.ui.container.Menu
        CurrentSessionMenu              matlab.ui.container.Menu
        MoveMenu                        matlab.ui.container.Menu
        TopMenu                         matlab.ui.container.Menu
        UpMenu                          matlab.ui.container.Menu
        DownMenu                        matlab.ui.container.Menu
        BotMenu                         matlab.ui.container.Menu
        EvenOddMenu                     matlab.ui.container.Menu
        CheckMenu                       matlab.ui.container.Menu
        AllNoneMenu                     matlab.ui.container.Menu
        InvertMenu                      matlab.ui.container.Menu
        AlternateMenu                   matlab.ui.container.Menu
        ClearMenu                       matlab.ui.container.Menu
        AllMenu                         matlab.ui.container.Menu
        UncheckedMenu                   matlab.ui.container.Menu
    end


    properties (Access = private)
        % gg=addpath('scripts');
        folderC=[];
        folderD=[];
        folderR=[];
        PCM='wSbZctglyGorrmp';% phasor colormap
        RCM='wvbclyorpw';% rainbow colormap
        CCM='rgbcmyauFltZ';%cursor colors
        single='rb';% single colors for cmap
        singhans=[];%handles for change color btn
        IIo=cell(0);SSo=cell(0);GGo=cell(0);% accumulate all images (HR dont change)
        IIa=cell(0);SSa=cell(0);GGa=cell(0);% accumulate all images (LR dont change)
        IIi=cell(0);SSi=cell(0);GGi=cell(0);% filtered versions (do change)
        MK=cell(0);MKL=cell(0);% masks, loaded and on the fly
        dda=cell(0);nms=cell(0);nmsx=cell(0);pat=cell(0);%  decays and file names
        hdrkeep=cell(0);% keep last hdr
        % clus=cell(0);%
        curs1=[];% handle for mouse circular cursor
        blk=NaN(16,16);%blank cursor
        curs=0;
        % curs2=0;
        % inorout=0;% used to detect moment cursor leaves
        radi=.02;
        phasores=256;
        dwnsmp=256;% size of downsampled images (set to zero to prevent downsampling)
        running=0;
        figsz=[100 100 100 100];% figure for int ims size
        currCol=1;% current cursor index used for colors
        colMks=[];% cursor colors
        roidad=[];% roidata where we keep position and radius of clusters being diplayed (radius at 0 means not in display)
        imcur=[];% cursor handle for reciprocal
        curhan=[];% handles for cursors sitting on phasor plot (so they can be remove without clearing the whole axes)
        IWh=[];% image window handle
        AXh=[];% axes in im window handles
        blankIm=[];
        compNum=0;% component number
        compNumT=0;% theo component number
        compNumE=0;% empiric component number
        comphans=[];% handles for component markers
        complif=[1:20];% initial lifetimes allowing 20 components
        compdad=zeros(20,2,20);%  sng allowing 20 harms
        colcomps=repmat('rgby',[1 5]);
        GMfit=[];% var to hold trained model
        GMfitcolinds=[];
        curp=[];% keep track of last list of files for which phasors have been filtered and plotted
        ALLcols=[]; % available cols set in startup
        BGcol=[ ]; % Default bg col
        BGcol2=[ ];% darker bg col
        BGcol3=[ ];% lighter bg col
        FGcol=[ ];% default foreground (colors are set in setcolors function
        currcol=1;
        DM=0;% darkmode flag
        ApplyImageMasks=0; %
        MKoutim=0;
        harmonic=1; % unused i think
        HarmLim=2;% max harm to compute
        exportHR=1; % allow export low res
        PD=zeros(20,100);% calibration values (first 4 rows: 32Sp1, 32sp2, 32A0, 32A1)
        MF=ones(20,100);% allowing for 100 harms is overkill
        multical=cell(1,20); % allow multiple calibration files
        multilif=zeros(1,20);
        calibflag=[]; % calibration associated to each loaded file
        calibtags={'32sp','','A0','A1','','','','','All'}; % user defined tags start at 10;
        currPP=0;
        currIC=0;
        Imaxrange=1;% default value for intensity range when set to absolute
        LoadedIms=0; % count loaded images

        freq=8e7; % laser freq
        curcolbuthan=[] ;% handles for cursor color buttons

    end

    methods (Access = private)
        function [] = applyUserSettings(app,ruta)
            app.running=1;
            if(nargin==1),ruta='params3.mat';end
            try
                if(nargin>1)
                    ensenya('Applying settings','b');
                end
                load(ruta,'files','paths','p01','p02','p03','p04','p05','p06','p07','p08','p09','p10','p11','p12','p13','p14','p15','p16','p17','p18','p19','p20','p21','p22','r01','r02','r03','r04','r05','r06','r07','r08','r09','r10','r11','r12','r13','r14','r15','r16','r17','r18','r19','r20','r21','r22','m01','m02','m03','m04','m05','m06','m07','m08','m09','m10','v01','v02','v03','v04','v05','v06','v07','v08','v09','v10','v11','v12','v13','v14','v15','v16','v17','v18','v19','c01','c02','c03','c04','c05','c06','c07','c08','c09','c10','c11','c12','c13','c14','c15','c16','c17','c18','c19','s00','u01','u02','u03','u04','u05','u06');


                pop=app.GSLabUIFigure.Position;
                app.GSLabUIFigure.Position=[p01(1:2) pop(3:4)];
                app.figsz=p02;
                app.Eb.Value=p03;
                app.Filtermatch.Text=[' x ' num2str(p03)];
                app.Ti.Value=p04;
                app.Imin.Value=p05;
                app.Imax.Value=p06;
                app.Islide.Value=[p05 p06];
                app.Smin.Value=p07;
                app.Smax.Value=p08;
                app.Gmin.Value=p09;
                app.Gmax.Value=p10;
                app.Cmin.Value=p11;
                app.Cmax.Value=p12;
                app.folderC=p13;
                app.folderD=p14;
                app.LT.Value=p15;
                app.DoCalib.Value=p16;
                app.Cbar.Value=p17;
                app.Harm.Value=p18;
                app.HarmLim=p19;
                app.MaxharmonicsEditField.Value=p19;
                app.Filterbefore.Value=p20;
                app.phaS.Value=p21;
                app.modS.Value=p22;

                app.folderR=r01;
                app.ExDecay.Value=r02;
                app.DeacayFileDropDown.Value=r03;
                app.Combine.Value=r04;
                app.ExPhasor.Value=r05;
                app.ExMagH.Value=r06;
                app.ExInt.Value=r07;
                app.PhBg.Value=r08;
                app.ExMagS.Value=r09;
                app.PhRb.Value=r10;
                app.blend.Value=r11;
                app.PhRb.Value=r12;
                app.ExCurH.Value=r13;
                app.ExCurS.Value=r14;
                app.Expptrans.Value=r15;
                app.Expvec.Value=r16;
                app.Expwithax.Value=r17;
                app.ExportPhasorFileDropDown.Value=r18;
                app.ExportImageFileDropDown.Value=r19;
                app.groupfiles.Value=r20;
                app.ExMagV.Value=r21;
                app.MagValFmtDropDown.Value=r22;

                app.Srad.Value=m01;
                app.Grad.Value=m02;
                app.Phaserad.Value=m03;
                app.TauPhaserad.Value=m04;
                app.Modrad.Value=m05;
                app.TauModrad.Value=m06;
                app.NormPhaserad.Value=m07;
                app.TauNormrad.Value=m08;
                app.CustomRadial.Value=m09;
                app.CustomAngular.Value=m10;

                app.AFSef.Value=v01;
                app.UCcb.Value=v02;
                app.ticks.Value=v03;
                app.tickfontsize.Value=v04;
                if(v05==0)
                    app.SpectralStyle.Value=0;
                    app.LifetimeStyle.Value=1;
                    app.UmapStyle.Value=0;
                end
                if(v05==1)
                    app.SpectralStyle.Value=1;
                    app.LifetimeStyle.Value=0;
                    app.UmapStyle.Value=0;
                end
                if(v05==2)
                    app.SpectralStyle.Value=0;
                    app.LifetimeStyle.Value=0;
                    app.UmapStyle.Value=1;
                end

                app.CCM=v06;
                app.freq=v07;
                app.freqEF.Value=v07*1e-6;
                if(nargin>1) % drop downs arent yet constructed!
                    app.PhasorcolormapDD.ValueIndex=v08;
                    app.RainbowcolormapDD.ValueIndex=v09;
                end
                app.SoftCursor.Value=v10;
                app.logDens.Value=v11;
                app.normFile.Value=v12;
                app.contours.Value=v13;
                app.margns.Value=v14;
                try
                    app.imagewindowtile.Value=v15;
                    app.imagecols.Value=v16;
                    app.imagerows.Value=v17;
                    app.SpecMin.Value=v18;
                    app.SpecMax.Value=v19;
                end

                app.complif=c01;
                app.colcomps=c02;
                app.ShowComponents.Value=c03;
                app.AddLines.Value=c04;
                app.CompImsExp.Value=c05;
                app.CompImsIntExp.Value=c06;
                app.CompImsCSVExp.Value=c07;
                app.CompImsCol.Value=c08;
                app.exportratiometric.Value=c09;
                app.comp1.Value=c10;
                app.comp2.Value=c11;
                app.ratiocsv.Value=c12;
                app.Filterbefore.Value=p20;

                app.Suman.Value=s00;

                app.singleimage.Value=c13;
                app.currcol=c14;
                app.DM=c15;
                app.addmargins.Value=c16;
                app.includeint.Value=c17;
                app.groupfile.Value=c18;
                app.mergecomps.Value=c19;


                if(u01==0),app.conttogether.Value=1;app.contseparate.Value=0;app.elipse.Value=0;end
                if(u01==1),app.conttogether.Value=0;app.contseparate.Value=1;app.elipse.Value=0;end
                if(u01==2),app.conttogether.Value=0;app.contseparate.Value=0;app.elipse.Value=1;end
                if(u02==0),app.hard.Value=1;app.prob.Value=0;end
                if(u02==1),app.hard.Value=0;app.prob.Value=1;end
                app.conf.Value=u03;
                app.blend.Value=u04;
                app.blendamount.Value=u05;
                app.gmmexport.Value=u06;
                han= barraprogress(app,1,han,'Done');
            end
            if(nargin>1)%
                triggercallbacks(app);

                prev='';% save last path so that flimsplits arent loaded twice
                for ii=1:numel(paths)
                    aux=[paths{ii} filesep files{ii}];
                    switch files{ii}(end-3:end)
                        case '.tif'
                            LoadTifSeqFun(app,{aux});
                        case '.lsm'
                            LoadZeissLSMFun(app,{aux});
                        case {'.R64','.ref','.re1','.re3','.re4'}
                            LoadSimFCSrefFun(app,{aux});
                        case 'CON)'
                            LoadFalconSnGFun(app,paths{ii});
                        otherwise% flame
                            if(strcmp(prev,aux)==0)
                                app.folderD=aux;
                                app.RGBLabel.Enable=1;
                                app.ChanButton3.Enable=1;
                                app.ChanButton4.Enable=1;
                                LoadFlameRawFun(app);
                            end
                            prev=aux;
                    end
                end
            end


            app.running=0;
        end
        function [han] = barraprogress(app,frac,han,msg)
            
            if(0)
                if(frac==0)
                    han = waitbar(0,msg,'Name','Running');
                    han.Children.Title.Interpreter = 'none';
                else
                    try % in case user closed window
                        exx='';
                        % exx=[niceNums(fra*100,0),'% '];
                        if(nargin>3)
                            han = waitbar(frac,han,[exx msg]);
                        else
                            han = waitbar(frac,han);
                        end
                    end
                end
            else
                if(frac==0)
                    han = uiprogressdlg(app.GSLabUIFigure,'Title','Please Wait','Message',msg);
                else
                    if(frac>1),frac=1;end
                    han.Value = frac;
                    han.Message = msg;
                end
            end
        end
        function rang = checkedFiles(app)
            % see what nodes are checked
            rang=[];
            for ii=1:numel(app.Tree.CheckedNodes)
                rang=[rang app.Tree.CheckedNodes(ii).NodeData];
            end
        end


        function [] = calibrationApply(app)
            N=app.LoadedIms;
            calibrationDisplayUpdate(app);
            % % apply calibration
            if(N>0)
                d=barraprogress(app,0,[],'Applying calibration');
                if(app.DoCalib.Value==1)
                    for ii=1:N
                        if(app.calibflag(ii)>0)||strcmp(app.Calibrationtype.SelectedObject.Text,'Manual')
                            % if(sum(app.PD(app.calibflag(ii),1:app.HarmLim))~=0)
                            %     if(prod(app.MF(app.calibflag(ii),1:app.HarmLim))~=1)
                            for hh=1:app.HarmLim

                                S1=app.SSo{ii,hh};
                                G1=app.GGo{ii,hh};


                                % fill nans before resizing (otherwise only nearest algorithm works)!
                                S1=phasorFillNaN2(S1);
                                G1=phasorFillNaN2(G1);

                                % downsample images
                                if(app.dwnsmp>0)
                                    nwsz=[app.dwnsmp size(S1,2)*app.dwnsmp/size(S1,1)];
                                    S1=imresize(S1,nwsz);%
                                    G1=imresize(G1,nwsz);%
                                end
                                if(strcmp(app.Calibrationtype.SelectedObject.Text,'Manual'))
                                    PDi=app.phaS.Value;
                                    MFi=app.modS.Value;
                                else
                                    PDi=app.PD(app.calibflag(ii),hh);
                                    MFi=app.MF(app.calibflag(ii),hh);
                                end

                                pha=atan2(S1,G1)+PDi;
                                modu=sqrt((S1.*S1)+(G1.*G1))*MFi;

                                S1=modu.*sin(pha);G1=modu.*cos(pha);

                                app.SSa{ii,hh}=S1;
                                app.GGa{ii,hh}=G1;
                                d=barraprogress(app,((ii-1)*app.HarmLim+hh)/N/app.HarmLim,d,'Applying calibration');
                            end
                        end
                        %     end
                        % end
                    end
                end
            end
            try,close(d);end

        end

        function [] = calibrationRemove(app)
            N=size(app.SSa,1);
            if(N>0)
                d=barraprogress(app,0,[],'Removing calibration');
                for ii=1:N
                    if(app.calibflag(ii)>0)||strcmp(app.Calibrationtype.SelectedObject.Text,'Manual')
                        % if(sum(app.PD(app.calibflag(ii),1:app.HarmLim))~=0)
                        %     if(prod(app.MF(app.calibflag(ii),1:app.HarmLim))~=1)
                        for hh=1:app.HarmLim

                            S1=app.SSo{ii,hh};
                            G1=app.GGo{ii,hh};


                            % fill nans before resizing (otherwise only nearest algorithm works)!
                            S1=phasorFillNaN2(S1);
                            G1=phasorFillNaN2(G1);

                            % downsample images
                            if(app.dwnsmp>0)
                                nwsz=[app.dwnsmp size(S1,2)*app.dwnsmp/size(S1,1)];
                                S1=imresize(S1,nwsz);%
                                G1=imresize(G1,nwsz);%
                            end

                            app.SSa{ii,hh}=S1;
                            app.GGa{ii,hh}=G1;
                            d=barraprogress(app,((ii-1)*app.HarmLim+hh)/N/app.HarmLim,d,'Removing calibration');
                        end
                        %     end
                        % end
                    end
                end
            end
            try,close(d);end
        end

        function [] =calibrationDisplayUpdate(app)
            aux={};
            chnams=app.calibtags;
            jj=0;
            for ii=1:numel(app.multical)
                if(~isempty(app.multical{ii}))
                    jj=jj+1;
                    % aux{ii}=app.multical{ii};
                    % if(strcmp(whosPC,'Alex'))
                    aux{jj,1}=chnams{ii};
                    aux{jj,2}=niceNums(app.multilif(ii),2);
                    aux{jj,3}=app.multical{ii};
                    % aux{jj,4}='X';
                    % else
                    %     aux{jj}=[chnams{ii} '(' num2str(app.multilif(ii)) 'ns) ' app.multical{ii} ];
                    % end
                end
            end
            if(isempty(aux))
                aux='';%app.DoCalib.Value=0;app.CalibrationPanel.Enable=0;
                % msgbox('Use File Menu - Load Calibration')
            end

            % if(strcmp(whosPC,'Alex'))
            app.calTable.Data=aux;
            % else
            %     app.FiLab.Text=aux;
            % end
        end

        function [] = clearMasks(app)
            for ii=1:numel(app.MK)
                app.MK{ii}(:)=1;
            end
        end

        function [] = createMasksFromRois(app)
            N=size(app.SSa,1);%
            for ii=1:N
                % dd=zeros(size(app.SSi{ii},1),size(app.SSi{ii},2),3);
                % cc=0;
                for kk=1:size(app.roidad,1)
                    if(app.roidad(kk,3)>0)
                        % disp('y');
                        S1=app.SSi{ii}-app.roidad(kk,2);
                        G1=app.GGi{ii}-app.roidad(kk,1);
                        % blank=G1;blank(:)=1;
                        blank=app.MK{ii};
                        % if(size(blank,1)~=size(G1,1)),blank=zeros(size(G1,1),size(G1,2),3);end
                        colmk=app.colMks(kk,:);
                        aux=(S1.*S1)+(G1.*G1)-app.roidad(kk,3)^2;
                        % aux2=aux;
                        % aux2(aux2<0)=0;
                        % aux2=1-aux2;aux2(aux2<0)=0;
                        % aux2=cat(3,aux2*colmk(1),aux2*colmk(2),aux2*colmk(3));
                        % dd=dd+aux2;
                        % cc=cc+1;
                        aux=aux<0;
                        for jj=1:3
                            aux0=blank(:,:,jj);
                            aux0(aux==1)=colmk(jj);
                            app.MK{ii}(:,:,jj)=aux0;
                        end
                        % gg=0;
                        % if(kk==1)&&(ii==1)
                        %                              figure(55);clf;
                        %     hh=axes();
                        %     images.roi.Circle(hh,'center',app.roidad(kk,1:2),'color',app.colMks(app.currCol,:),'radius',app.roidad(kk,3));
                        %     fov=[app.Gmin.Value app.Gmax.Value;app.Smin.Value app.Smax.Value];
                        %     xlim(fov(1,:));ylim(fov(2,:));
                        %     universalCircle(fov,'k',8e7);
                        %     axis equal;
                        % end
                    end
                end
                % gg=0;
            end

        end

        function [] = clearCalib(app)
            app.PD=zeros(20,100);% calibration values (first 4 rows: 32Sp1, 32sp2, 32A0, 32A1
            app.MF=ones(20,100);
            app.multical={'','','',''};
        end

        function [] = CursorColPush(app,~,~,q)
            h=app.curcolbuthan(q);
            pos=app.GSLabUIFigure.Position;
            tam=[200 200];
            % pos=pos(1:2)+(pos(3:4)-tam)/2;
            pos(2)=pos(2)+sum(app.CursorcolorsPanel.Position([2,4]));
            pos(3:4)=tam;
            try
                nou=colorPicker2(1,pos);
                app.CCM(q)=nou;
                nou=superjet(1,nou);
                set(h,'BackgroundColor',nou);
                app.colMks(q,:)=nou;
            end
            app.currCol=q;
            app.curs1.Color=app.colMks(q,:);

        end

        function [] = ComponentColPush(app,~,~,q)
            h=app.comphans(q,2);
            pos=app.GSLabUIFigure.Position;
            tam=[200 200];
            % pos=pos(1:2)+(pos(3:4)-tam)/2;
            pos(2)=pos(2)+sum(app.ComponentsPanel.Position([2,4]));
            pos(3:4)=tam;
            try
                nou=colorPicker2(1,pos);
                app.colcomps(q)=nou;
                nou=superjet(1,nou);
                set(h,'BackgroundColor',nou);
                componentLifeChange(app);
                % app.colMks(q,:)=nou;
                % app.curs1.Color=nou;
                % app.currCol=q;
            end
        end

        function [] = componentAddTheo(app)
            maxComps=1+2*app.HarmLim;
            app.minusB.Enable=1;
            if(app.compNumT<maxComps)
                app.compNumT=app.compNumT+1;
                ref=app.plusB.Position;
                sep=1;
                tam=12;
                % Component EditFieldLabel
                h = uilabel(app.ComponentsPanel);
                h.HorizontalAlignment = 'left';
                h.Position = [ref(1)-5 ref(2) 100 20];
                h.Text = [niceDigits(app.compNumT,2) '. Lifetime:'];
                h.FontSize=12;
                h.FontColor=app.FGcol;
                app.comphans(app.compNumT,1)=h;
                % % component color button
                % h = uibutton(app.ComponentsPanel);
                % h.Position = [ref(1)+18 ref(2)+4 tam tam];
                % h.Text = '';
                % h.BackgroundColor= superjet(1,app.colcomps(app.compNumT));
                % h.Tooltip=['Component' num2str(app.compNumT) ' color'];
                % h.ButtonPushedFcn = {@app.ComponentColPush, app.compNumT};
                % app.comphans(app.compNumT,2)=h;
                h=app.comphans(app.compNumT,2);
                set(h,'Visible',1);
                set(h,'Position',[ref(1)-16 ref(2)+7 tam tam]);
                % component color edit field
                h = uieditfield(app.ComponentsPanel, 'numeric');
                h.UserData = app.compNumT;
                h.ValueChangedFcn = {@app.componentLifeChange, app.compNumT};
                h.HorizontalAlignment = 'center';
                h.Limits=[0 Inf];
                h.Tooltip = ['Component' num2str(app.compNumT) ' lifetime(ns)'];
                h.Position = [ref(1)+81-21  ref(2) 36 20];
                h.Value = app.complif(app.compNumT);
                app.comphans(app.compNumT,3)=h;

                % shift plus minus buttons
                app.plusB.Position=ref-[0 sep+20 0 0];
                app.minusB.Position=app.minusB.Position-[0 sep+20 0 0];
                if(app.compNumT==maxComps)
                    app.plusB.Enable=0;
                    app.plusB.Tooltip='Increase max harmonics for more components';
                end
                app.compNum=app.compNumT;
                % update S n g and plot
                componentLifeChange(app);

            end
        end

        function [] = componentRemoveTheo(app)
            app.plusB.Enable=1;
            app.plusB.Tooltip='Add component';
            if(app.compNumT>0)
                h=app.comphans(app.compNumT,1);
                delete(h);
                h=app.comphans(app.compNumT,2);
                set(h,'Visible',0);
                h=app.comphans(app.compNumT,3);
                delete(h);
                app.compNumT=app.compNumT-1;
                if(app.compNumT==0)
                    app.minusB.Enable=0;
                    replotPP(app);% gotta replot bc otherwise component circle is left
                end
                % shift plus minus buttons
                ref=app.plusB.Position;
                app.plusB.Position=ref-[0 -21 0 0];
                app.minusB.Position=app.minusB.Position-[0 -21 0 0];
                app.compNum=app.compNumT;
                componentLifeChange(app);

            end
        end


        function [] = componentTreeColors(app)

            tst0=uistyle('FontColor','black');

            checkedNodes = app.ComponentTree.CheckedNodes;
            rang=[];
            for ii=1:numel(app.ComponentTree.Children)
                addStyle(app.ComponentTree,tst0,'node',app.ComponentTree.Children(ii));
            end
            for ii=1:numel(checkedNodes)
                rang=[rang app.ComponentTree.CheckedNodes(ii).NodeData];
                h=app.comphans(ii,2);
                tst=uistyle('FontColor',get(h,'BackgroundColor'));
                addStyle(app.ComponentTree,tst,'node',app.ComponentTree.CheckedNodes(ii));
            end

        end

        function [] = componentGetEmpiricCoords(app)
            HH=app.HarmLim;
            rang=[];
            checkedNodes = app.ComponentTree.CheckedNodes;
            for ii=1:numel(checkedNodes)
                rang=[rang app.ComponentTree.CheckedNodes(ii).NodeData];
                % h=app.comphans(ii,2);
                % tst=uistyle('FontColor',get(h,'BackgroundColor'));
                % addStyle(app.ComponentTree,tst,'node',app.ComponentTree.CheckedNodes(ii));
            end
            if(numel(rang)>1+2*HH)
                warndlg(['Increase computed harmonics to unmix more than ' num2str(1+2*HH) ' components'],'Warning');
            end
            app.compNumE=numel(rang);
            cc=0;
            for ii=1:8
                h=app.comphans(ii,2);
                set(h,'Visible',0);
            end
            qv=[app.Imin.Value app.Imax.Value];
            filterfactor=1;%
            for ii=rang
                cc=cc+1;
                % h=app.comphans(cc,2);
                %  set(h,'Visible',1);
                Im0=app.IIa{ii};
                if(app.relative.Value==1)
                    Im0=Im0/max(Im0(:));
                end
                Im0=(Im0-qv(1))/diff(qv);
                Im0(Im0<0)=0;
                if(app.Excess.Value==0)
                    Im0(Im0>1)=1;
                else
                    Im0(Im0>1)=0;
                end
                for hh=1:HH
                    % photon weighted mean
                    Im1=Im0;
                    S1=app.SSa{ii,hh};
                    G1=app.GGa{ii,hh};
                    % disp(ii)
                    if(app.Filterbefore.Value==0)  % filter being smooth before int thresh
                        S1(Im1==0)=NaN;
                        G1(Im1==0)=NaN;
                    end
                    [S1,G1] = phasorSmooth(S1,G1,app.Eb.Value*filterfactor,app.Ti.Value,0,[],1);
                    [S1,G1,~,Im1] = phasorSmoothCrop(S1,G1,app.Eb.Value*filterfactor,app.Ti.Value,Im1);
                    % [Im1,Im1] = phasorSmoothCrop(Im1,Im1,app.Eb.Value*filterfactor,app.Ti.Value);
                    if(app.Filterbefore.Value==1)
                        S1(Im1==0)=NaN;
                        G1(Im1==0)=NaN;
                    end
                    if(app.ApplyImageMasks==1)
                        % incase we loadded a single image
                        ind=mod(ii,numel(app.MKL));if(ind==0),ind=numel(app.MKL);end
                        aux=app.MKL{ind};
                        out=(size(Im1,1)-size(aux,1))/2;
                        if(out>0)% image bigger than mask
                            aux=imresize(aux,size(Im1));
                            % Im1=Im1(1+out:end-out,1+out:end-out);
                            % S1=S1(1+out:end-out,1+out:end-out);
                            % G1=G1(1+out:end-out,1+out:end-out);
                        end
                        if(out<0)
                            out=-out;
                            aux=aux(1+out:end-out,1+out:end-out);
                        end
                        try
                            Im1=Im1.*aux;
                            S1(aux==0)=NaN;
                            G1(aux==0)=NaN;
                        end
                    end
                    out=find(isnan(S1));
                    S1(out)=[];G1(out)=[];Im1(out)=[];
                    S1=S1(:);G1=G1(:);Im1=Im1(:);
                    aux2=sum(Im1);
                    s=(S1.*Im1)./aux2;s=sum(s);
                    g=(G1.*Im1)./aux2;g=sum(g);
                    app.compdad(cc,2,hh)=s;
                    app.compdad(cc,1,hh)=g;
                end
            end
        end

        function [] = componentLifeChange(app,A,~,q)
            % q=get(A,'UserData')
            if(nargin<2)
                q=app.compNum;
            else
                if(~isempty(A))
                    if(nargin<4)
                        q=A.UserData;
                    end
                    app.complif(q)=A.Value;
                end
            end
            if(q>0)
                for ii=1:numel(q)
                    for hh=1:10
                        if(app.InputButton.Value==1)
                            [s,g] = tau2coords(app.complif(q(ii)),app.freq,hh);
                        else
                            inds=find(app.roidad(:,3)>0);
                            s=app.roidad(inds(ii),2);
                            g=app.roidad(inds(ii),1);
                        end
                        app.compdad(q(ii),2,hh)=s;
                        app.compdad(q(ii),1,hh)=g;
                    end
                end
                if(app.ShowComponents.Value==1)
                    replotPP(app);
                end
            end
        end
        function [S1,G1,Im1,pp,I1,I2,IC] = FilterAndPlotPhasor(app,S1,G1,Im1,q)


            app.running=1;
            Srange=[app.Smin.Value app.Smax.Value];
            Grange=[app.Gmin.Value app.Gmax.Value];
            phsiz=[round(app.phasores*diff(Srange)/diff(Grange)) app.phasores];
            fov=[Grange;Srange];
            IC=ones(phsiz(1),phsiz(2),3);
            N=size(app.SSa,1);
            PPa=zeros(phsiz(1),phsiz(2),N);% accumulate phasors
            if(nargin>1)% called while exporting data on single exp
                N=1;
            end
            if(N>0)&&(nargin==1),d = barraprogress(app,0,[],'Thresholding|Filtering|Cropping|Masking|Projecting|Plotting');end

            filterfactor=1;%
            % achtung, exported images use true filter size
            app.EbLabel.Text='Phasor filtering';
            if(app.exportHR==0)% if lr change filter to match user view
                if(nargin>1)
                    if(app.dwnsmp>0)
                        filterfactor=round(size(S1,1)/app.dwnsmp);
                        app.EbLabel.Text=['Filter x' niceNums(size(S1,1)/app.dwnsmp,1)];
                    end
                end
            end

            % see what nodes are checked
            if(nargin==1)
                rang=checkedFiles(app);
            else
                rang=q;
            end
            app.curp=rang;
            qv=[app.Imin.Value app.Imax.Value];
            for ii=rang
                if(nargin==1)
                    S1=app.SSa{ii,app.Harm.Value};
                    G1=app.GGa{ii,app.Harm.Value};
                    Im1=app.IIa{ii};
                else
                    if(app.DoCalib.Value==1)
                        if(app.exportHR==1)% high res images is on originals, need to be calibrated
                            if(app.calibflag(ii)>0)
                                pha=atan2(S1,G1)+app.PD(app.calibflag(ii),app.Harm.Value);
                                modu=sqrt((S1.*S1)+(G1.*G1))*app.MF(app.calibflag(ii),app.Harm.Value);
                                S1=modu.*sin(pha);G1=modu.*cos(pha);
                            end
                        end
                    end
                end

                % Im1=round(1000*Im1)/1000;% achtung
                if(app.relative.Value==1)
                    Im1=Im1/max(Im1(:));
                end
                Im1=(Im1-qv(1))/diff(qv);
                Im1(Im1<0)=0;
                if(app.Excess.Value==0)
                    Im1(Im1>1)=1;
                else
                    Im1(Im1>1)=0;
                end
                if(app.logint.Value==1)
                    Im1=log(1+9999*Im1);Im1=NormArray(Im1);
                end
                if(app.hdr.Value==1)
                    if(~isempty(app.hdrkeep{ii}))
                        Im1=app.hdrkeep{ii};
                    else
                        Im1=pseudoHDR(Im1);
                        app.hdrkeep{ii}=Im1;
                    end
                end
                % figure,imagesc(isnan(Im1));
                if(nargin==1),d = barraprogress(app,(ii-.1)/N,d,'Thresholding|Filtering|Cropping|Masking|Projecting|Plotting');end
                if(app.Filterbefore.Value==0)  % filter being smooth before int thresh
                    S1(Im1==0)=NaN;
                    G1(Im1==0)=NaN;
                end
                if(nargin==1),d = barraprogress(app,(ii-.6)/N,d,'Thresholding|Filtering|Cropping|Masking|Projecting|Plotting');end
                % smooth
                [S1,G1] = phasorSmooth(S1,G1,app.Eb.Value*filterfactor,app.Ti.Value,0,[],1);

                if(nargin==1),d = barraprogress(app,(ii-.5)/N,d,'Thresholding|Filtering|Cropping|Masking|Projecting|Plotting');end
                [S1,G1] = phasorSmoothCrop(S1,G1,app.Eb.Value*filterfactor,app.Ti.Value);
                [Im1,Im1] = phasorSmoothCrop(Im1,Im1,app.Eb.Value*filterfactor,app.Ti.Value);
                if(app.Filterbefore.Value==1)
                    S1(Im1==0)=NaN;
                    G1(Im1==0)=NaN;
                end
                if(app.GaussianFilter.Value~=0)
Im1=imgaussfilt(Im1,app.GaussianFilter.Value);
                end

                if(nargin==1),d = barraprogress(app,(ii-.4)/N,d,'Thresholding|Filtering|Cropping|Masking|Projecting|Plotting');end

                if(app.ApplyImageMasks==1)
                    % incase we loadded a single image
                    ind=mod(ii,numel(app.MKL));if(ind==0),ind=numel(app.MKL);end
                    aux=app.MKL{ind};
                    out=(size(Im1,1)-size(aux,1))/2;
                    if(out>0)% image bigger than mask, we gotta upsample mask
                        aux=imresize(aux,size(Im1),'nearest');
                        % Im1=Im1(1+out:end-out,1+out:end-out);
                        % S1=S1(1+out:end-out,1+out:end-out);
                        % G1=G1(1+out:end-out,1+out:end-out);
                    end
                    if(out<0)
                        if(0)% this would be used if mask was same size as original image but due to filtering we gotta crop some
                        out=-out;
                        aux=aux(1+out:end-out,1+out:end-out);
                        else% mask is highres and image is downsampled, we gotta downsample mask
                            aux=imresize(aux,size(Im1),'nearest');
                        end
                    end
                    try
                        Im1=Im1.*aux;
                        S1(aux==0)=NaN;
                        G1(aux==0)=NaN;
                    end

                end

                % update plotting versions
                if(nargin==1)
                    app.SSi{ii}=S1;
                    app.GGi{ii}=G1;
                    app.IIi{ii}=Im1;
                    app.MK{ii}=ones(size(S1,1),size(S1,2),3);
                end

                if(nargin==1),d = barraprogress(app,(ii-.2)/N,d,'Thresholding|Filtering|Cropping|Masking|Projecting|Plotting');end
                % map on phasor space
                % [pp]=phasorPlot2(S1,G1,phsiz,0,fov);
                [pp]=phasorPlotFast(S1,G1,phsiz,fov);

                % pp=NormArray2(pp);
                % figure,imagesc(pp)
                PPa(:,:,ii)=pp;


                if(nargin==1),d = barraprogress(app,(ii)/N,d,'Thresholding|Filtering|Cropping|Masking|Projecting|Plotting');end

                Crange=[app.Cmin.Value app.Cmax.Value];
                [type,centre] = definetype(app.MeasuredmagnitudeButtonGroup.SelectedObject.Text);
                if(isempty(centre)),centre=[app.Gcenter.Value app.Scenter.Value];end
                if(type==3)% centers are shifted by 1000 to use radial as linear
                    Crange=Crange+1000;
                    type=2;
                end
                if(app.gmmexport.Value==0)
                    [~,~,I1,I2,IC] = phasorRainbowIm(S1,G1,Im1,phsiz,fov,centre,1,Crange,app.RCM,1,type);
                else
                    I1=app.MK{ii};
                    if(app.exportHR==1)&&(nargin>1)
                        ensenya('GMM not implemented for HR','r');
                        I1=imresize(I1,size(Im1));
                    end

                    I2=I1.*Im1;
                    I2=uint8(255*I2);
                end

                if(nargin==1)
                    if(app.PhBg.Value==1)
                        app.MK{ii}=I1;
                    end
                end
                I1=uint8(255*I1);
                if(nargin==1),d = barraprogress(app,(ii)/N,d,'Thresholding|Filtering|Cropping|Masking|Projecting|Plotting');end

            end
            if(N==0)% create rainbow
                if(app.PhBg.Value==1)
                    [type,centre] = definetype(app.MeasuredmagnitudeButtonGroup.SelectedObject.Text);
                    if(isempty(centre)),centre=[app.Gcenter.Value app.Scenter.Value];end
                    Crange=[app.Cmin.Value app.Cmax.Value];
                    if(type==3)% centers are shifted by 1000 to use radial as linear
                        Crange=Crange+1000;
                        type=2;
                    end
                    [~,~,~,~,IC] = phasorRainbowIm(0,0,0,phsiz,fov,centre,1,Crange,app.RCM,1,type);
                end
            end
            app.currPP=PPa;
            app.currIC=IC;
            replotPP(app);
            if(N>0)&&(nargin==1),replotII(app);end
            try,close(d);end;


            app.running=0;


        end

        function [] = LoadCalibrationFile(app)
            d=barraprogress(app,0,[],'Loading calibration');
            N=1;
            ruta{1}=app.folderC;
            I=fishTiffs(ruta{1});notloaded=0;

            if(isempty(I))% attempt run as folder pack
                notloaded=1;
                N=0;
                S=dir(app.folderC);
                for ii=length(S):-1:1
                    if(S(ii).isdir==0)
                        S(ii)=[];
                    end
                end
                S(2)=[];S(1)=[];%remove . and ..
                N=length(S);
                ruta=cell(0);
                for ii=1:N
                    ruta{ii}=[S(ii).folder filesep S(ii).name];
                end
            end

            if(N==0)
                msgbox('Empty calibration path');
            else
                for jj=1:N
                    if(notloaded==1)
                        I=fishTiffs(ruta{jj},0);
                    end
                    d=barraprogress(app,.1,d,'Loading calibration');
                    I=sum(sum(I,1),2);
                    nom=ruta{jj}(find(ruta{jj}=='_',1,'last')+1:end);

                    for ii=1:app.HarmLim
                        Ng=0;
                        switch nom
                            case '32Sp'
                                ch1=1:15;
                                ch2=17:31;
                                [S2,G2]=fpt(I(:,:,ch2),ii);
                                Ng=1;
                            case '32A1'
                                ch1=1:31;
                                Ng=4;
                            case '32A0'
                                ch1=1:31;
                                Ng=3;
                        end
                        if(Ng>0)
                            [S1,G1]=fpt(I(:,:,ch1),ii);
                            [s,g]=expectedPhasorPosition(app.LT.Value,app.freq,ii);
                            aux=atan2(S1,G1);aux=aux(:);aux(isnan(aux))=[];
                            app.PD(Ng,ii)=atan2(s,g)-mean(mean(aux));
                            aux=sqrt((S1.*S1)+(G1.*G1));aux=aux(:);aux(isnan(aux))=[];
                            app.MF(Ng,ii)=sqrt((s*s)+(g*g))/mean(mean(aux));

                            if(Ng==1)
                                aux=atan2(S2,G2);aux=aux(:);aux(isnan(aux))=[];
                                app.PD(2,ii)=atan2(s,g)-mean(mean(aux));
                                aux=sqrt((S2.*S2)+(G2.*G2));aux=aux(:);aux(isnan(aux))=[];
                                app.MF(2,ii)=sqrt((s*s)+(g*g))/mean(mean(aux));
                            end
                        end
                        d=barraprogress(app,((jj-1)+ii)/N/app.HarmLim,d,'Loading calibration');
                    end
                    if(Ng>0)
                        app.multical{Ng}=ruta{jj}(find(ruta{jj}==filesep,1,'last')+1:end);
                        app.multilif(Ng)=app.LT.Value;
                    end
                end
                calibrationDisplayUpdate(app);
            end
            calibrationApply(app);
            FilterAndPlotPhasor(app);
            try(close(d));end
        end
        function [] = LoadFlameRawFun(app)
            ensenya(['Starting'],'b');
            app.ApplyImageMasks=0;
            % delete(app.Tree.Children);

            logUse('PhasorFlame');

            app.running=1;

            N=0;cc=0;S=[];

            % unlimit harmonics
            app.Harm.Limits(2)=app.HarmLim;

            try
                S=dir(app.folderD);
                % keep only folders matching known names
                for ii=numel(S):-1:1
                    if(S(ii).isdir==0)
                        S(ii)=[];
                    else
                        try
                            aux=S(ii).name(find(S(ii).name=='_',1,'last')+1:end);

                            switch aux
                                case {'32Sp','32A1','32A0'}

                                otherwise
                                    S(ii)=[];
                            end
                        catch
                            S(ii)=[];
                        end
                    end
                end
                N=numel(S);
                if(N==0)% if no folders are left
                    % its possible user is selecting daughter not mother folder
                    % check to see if there are tiffs inside
                    if(numel(dir([app.folderD filesep '*.tif']))>0)
                        aux=find(app.folderD==filesep,1,'last');
                        S(1).folder=app.folderD(1:aux-1);
                        S(1).name=app.folderD(aux+1:end);
                        S(1).isdir=1;
                        N=1;
                    end
                end

            end


            d=barraprogress(app,0,[],'Please wait...');


            Ng=-1;

            if(isempty(S))
                % error('Empty data path');
                msgbox('Empty data path');
            end

            for ii=1:numel(S)

                d=barraprogress(app,(cc+.01)/N,d,[S(ii).name ]);
                %%%%%%%%%%%%%%%%

                if(S(ii).isdir)
                    nom=S(ii).name(find(S(ii).name=='_',1,'last')+1:end);

                    switch nom% these are assigned to calibflag
                        case '32Sp'
                            ch1=1:15;
                            ch2=17:31;
                            Ng2=1;% 1 and 2 (assigned later) for flimsplits
                        case '32A1'
                            ch1=1:31;
                            Ng2=4;% 4 is 32A1
                        case '32A0'
                            ch1=1:31;
                            Ng2=3;% 3 is 32A0
                        otherwise
                            Ng2=0;
                    end

                    if(Ng2~=0)
                        rep=1;% usually no need to repeat, only flimsplit
                        pav=2+app.HarmLim;% progress addition value (3 points load/compute(x)harm/export)
                        if(floor(Ng2)==1),rep=2;end% for split files we gotta run phasor twice
                        ensenya(['Starting ' S(ii).name],'b');
                        I=fishTiffs([S(ii).folder filesep S(ii).name]);
                        Nslices=1;chgap=31;
                        if(isType(S(ii).name,'sta')||isType(S(ii).name,'zmo'))
                            aux=dir([S(ii).folder filesep S(ii).name filesep '*.txt']);

                            try
                                [dad] = readScanImageMeta([aux(1).folder filesep aux(1).name]);
                                Nslices=numel(dad.tileZs);
                                % Nslices=1;
                                %size(I,3)/Nslices
                            end
                        end
                        
                        sliceOrder=[1:Nslices];
                        if(isType(S(ii).name,'mos')||isType(S(ii).name,'zmo'))
                            if(1)% treat tiles separately
                                % zeeortile='t';
                                Ntiles=size(I,4);
                                I=reshape(I,[size(I,1) size(I,2) size(I,3)*size(I,4)]);
                                Nslices=size(I,3);
                                Nslices=Nslices/chgap;
                                Nzees=Nslices/Ntiles;    
                            else
                                [m,n] = getTileDims(S(ii).name);
                                I=stitchMosaic(I,m,n);
                            end
                        end
                       

                        cc2=0;

                        % size(I)
                        for kk=1:Nslices
                            ext2=[];
                            if(Nslices>1)
                                if(Nzees>1)
                                    laz=mod(kk,Nzees);if(laz==0),laz=Nzees;end
                                ext2=['_z'  niceDigits(laz,2) '_t'   niceDigits(ceil(kk/Nzees),2)];
                                else
                                ext2=['_t'   niceDigits(ceil(kk/Nzees),2)];
                                end
                            end
                            for jj=1:rep
                                ext=[];% separate A1 from A0 in flimsplits
                                if(jj==1)
                                    cht=ch1;
                                    if(rep>1)
                                        ext='_A1';
                                    end
                                else
                                    cht=ch2;
                                    ext='_A0';
                                end
                                if(kk>1),cht=cht+chgap*(kk-1);end
                                % foln=S(ii).folder;
                                % foln=foln(find(foln==filesep,1,'last')+1:end);
                                if(sum(size(I,3)<cht)>0)
                                    ensenya('Error: Missing frames in tiff!','r');
                                    break;
                                end

                                app.LoadedIms=app.LoadedIms+1;

                                app.calibflag(app.LoadedIms)=Ng2;
                                if(jj==2),app.calibflag(app.LoadedIms)=2;end

                                app.IIo{app.LoadedIms}=I(:,:,cht);
                                app.hdrkeep{app.LoadedIms}=[];
                                Im1=sum(I(:,:,cht),3);
                                app.Imaxrange=max([app.Imaxrange max(Im1(:))]);

                                % downsample images
                                if(app.dwnsmp>0)
                                    nwsz=[app.dwnsmp size(Im1,2)*app.dwnsmp/size(Im1,1)];
                                else
                                    nwsz=size(Im1);
                                end
                                app.IIa{app.LoadedIms}=imresize(Im1,nwsz);% accumulated
                                % if no cursors in place set mask to new
                                aux=0;
                                for ll=1:size(app.roidad,1)
                                    if(app.roidad(ll,3)>0)
                                        aux=aux+1;break
                                    end
                                end
                                if(aux==0)
                                    app.MK{app.LoadedIms}=ones(nwsz(1),nwsz(2),3);
                                else% since the accumulated SSi and GGi are not going to be cropped resize MK
                                    try% cursors could be in place but no mask yet (possibly user put cursors before loading data)
                                        app.MK{app.LoadedIms}=imresize(app.MK{app.LoadedIms},nwsz);
                                    catch % assume it is such case so we do have to initialise masks
                                        app.MK{app.LoadedIms}=ones(nwsz(1),nwsz(2),3);
                                    end
                                end

                                cc2=cc2+1/Nslices/pav/rep;
                                d=barraprogress(app,(cc+cc2)/N,d,[S(ii).name ext2]);

                                for hh=1:app.HarmLim
                                    % fast phasor transform
                                    [S1,G1]=fpt(I(:,:,cht),hh);

                                    % keep raw calibrated for accumulated
                                    app.SSo{app.LoadedIms,hh}=S1;
                                    app.GGo{app.LoadedIms,hh}=G1;

                                    % fill nans before resizing (otherwise only nearest algorithm works)!
                                    S1=phasorFillNaN2(S1);
                                    G1=phasorFillNaN2(G1);

                                    %resize
                                    app.SSa{app.LoadedIms,hh}=imresize(S1,nwsz);% accumulated
                                    app.GGa{app.LoadedIms,hh}=imresize(G1,nwsz);% accumulated

                                    cc2=cc2+1/Nslices/pav/rep;
                                    d=barraprogress(app,(cc+cc2)/N,d,[S(ii).name ext2 '_h' num2str(hh)]);
                                end


                                % decay
                                fluo=permute(sum(sum(I,1),2),[3 1 2]);
                                app.dda{app.LoadedIms}=fluo;

                                % names
                                app.pat{app.LoadedIms}=S(ii).folder;
                                app.nms{app.LoadedIms}=S(ii).name;
                                app.nmsx{app.LoadedIms}=[ext2 ext];

                                % add to tree
                                aux=uitreenode(app.Tree,'Text',[app.nms{app.LoadedIms} app.nmsx{app.LoadedIms}],'NodeData',app.LoadedIms);
                                app.Tree.CheckedNodes=[app.Tree.CheckedNodes;aux];

                                cc2=cc2+1/Nslices/pav/rep;
                                d=barraprogress(app,(cc+cc2)/N,d,[S(ii).name ext2 '_h' num2str(hh)]);

                            end
                        end
                    end
                end
                cc=cc+1;
                %%%%%%%%%%%%%%%%
            end

            try
                d=barraprogress(app,1,d,'Finishing');
                close(d)
            end
            try
                close(app.IWh);
            end
            if(app.DoCalib.Value==1)
                calibrationApply(app);
            end


            delete(app.ComponentTree.Children);

            ensenya('Finished','e');

            app.running=0;
        end

        function [] = LoadTifSeqFun(app,filepaths)
            app.running=1;
            if(ischar(filepaths))
                aux=cell(1);
                aux{1}=filepaths;
                filepaths=aux;
            end
            d=barraprogress(app,0,[],'Please wait...');
            ensenya(['Starting'],'b');
            app.ApplyImageMasks=0;
            % delete(app.Tree.Children);
            logUse('PhasorFlame');


            N=0;cc=0;S=[];

            % unlimit harmonics
            app.Harm.Limits(2)=app.HarmLim;


            N=numel(filepaths);
            for ii=1:N
                nom=filepaths{ii}(find(filepaths{ii}==filesep,1,'last')+1:end);

                d=barraprogress(app,(ii-.9)/N,d,[nom ' __']);

                I=fishTiffs(filepaths{ii});
                [~,~,chgap]=size(I);
                if(chgap>1)
                    cht=1:chgap;
                    ensenya(['Phasor transform dimension has ' num2str(chgap) ' bins']);
                    ext=[];% maybe use this for calibration tag

                    app.LoadedIms=app.LoadedIms+1;

                    app.calibflag(app.LoadedIms)=0;% use calib flag for predefined tags

                    app.IIo{app.LoadedIms}=I(:,:,cht);
                    app.hdrkeep{app.LoadedIms}=[];
                    Im1=sum(I(:,:,cht),3);
                    app.Imaxrange=max([app.Imaxrange max(Im1(:))]);

                    % downsample images
                    if(app.dwnsmp>0)
                        nwsz=[app.dwnsmp size(Im1,2)*app.dwnsmp/size(Im1,1)];
                    else
                        nwsz=size(Im1);
                    end
                    app.IIa{app.LoadedIms}=imresize(Im1,nwsz);% accumulated
                    % if no cursors in place set mask to new
                    aux=0;
                    for ll=1:size(app.roidad,1)
                        if(app.roidad(ll,3)>0)
                            aux=aux+1;break
                        end
                    end
                    if(aux==0)
                        app.MK{app.LoadedIms}=ones(nwsz(1),nwsz(2),3);
                    else% since the accumulated SSi and GGi are not going to be cropped resize MK
                        try% cursors could be in place but no mask yet (possibly user put cursors before loading data)
                            app.MK{app.LoadedIms}=imresize(app.MK{app.LoadedIms},nwsz);
                        catch % assume it is such case so we do have to initialise masks
                            app.MK{app.LoadedIms}=ones(nwsz(1),nwsz(2),3);
                        end
                    end
                    d=barraprogress(app,(ii-.6)/N,d,[nom ' __']);

                    for hh=1:app.HarmLim
                        % fast phasor transform
                        [S1,G1]=fpt(I(:,:,cht),hh);

                        % keep raw calibrated for accumulated
                        app.SSo{app.LoadedIms,hh}=S1;
                        app.GGo{app.LoadedIms,hh}=G1;

                        % fill nans before resizing (otherwise only nearest algorithm works)!
                        S1=phasorFillNaN2(S1);
                        G1=phasorFillNaN2(G1);

                        %resize
                        app.SSa{app.LoadedIms,hh}=imresize(S1,nwsz);% accumulated
                        app.GGa{app.LoadedIms,hh}=imresize(G1,nwsz);% accumulated


                        d=barraprogress(app,(ii-.6+(.5*hh/app.HarmLim))/N,d,[nom ' h' num2str(hh)]);
                    end

                    % decay
                    fluo=permute(sum(sum(I,1),2),[3 1 2]);
                    app.dda{app.LoadedIms}=fluo;

                    % names
                    app.pat{app.LoadedIms}=filepaths{ii}(1:find(filepaths{ii}==filesep,1,'last')-1);
                    app.nms{app.LoadedIms}=nom;
                    app.nmsx{app.LoadedIms}=[];

                    % add to tree
                    aux=uitreenode(app.Tree,'Text',[app.nms{app.LoadedIms} app.nmsx{app.LoadedIms}],'NodeData',app.LoadedIms);
                    app.Tree.CheckedNodes=[app.Tree.CheckedNodes;aux];


                    d=barraprogress(app,ii/N,d,[nom ' h' num2str(hh)]);
                end
            end
            try
                d=barraprogress(app,1,d,'Finishing');
                close(d);
            end
            try
                close(app.IWh);
            end
            if(app.DoCalib.Value==1)
                calibrationApply(app);
            end


            delete(app.ComponentTree.Children);

            ensenya('Finished','e');

            app.running=0;
        end
        function [] = LoadFalconSnGFun(app,filepaths)
            ensenya('Loading     ');
            d=barraprogress(app,0,[],'Loading...');

            if(ischar(filepaths)),filepaths={filepaths};end

            [ITT,SST,GGT] = readFALCONsng(filepaths{1});
            aux=filepaths{1};aux=aux(find(aux==filesep,1,'last')+1:end);
            names=cell(0);
            for jj=1:size(ITT,3)
                names{jj}=aux;
            end


            if(isempty(ITT))% maybe user selected mother folder?
                cont=dir(filepaths{1});
                ITT=[];SST=[];GGT=[];names=cell(0);
                contanou=0;
                for jj=3:numel(cont)
                    if(cont(jj).isdir==1)
                        [IT,ST,GT] = readFALCONsng([filepaths{1} filesep cont(jj).name]);
                        if(~isempty(IT))
                            ITT=cat(3,ITT,IT);
                            SST=cat(3,SST,ST);
                            GGT=cat(3,GGT,GT);
                            for kk=1:size(IT,3)
                                contanou=contanou+1;
                                names{contanou}=cont(jj).name;
                            end
                        end
                    end
                end
            end

            if(~isempty(ITT))
                for jj=1:size(ITT,3)

                    app.LoadedIms=app.LoadedIms+1;

                    II=ITT(:,:,jj);
                    S0=SST(:,:,jj);
                    G0=GGT(:,:,jj);

                    %int
                    app.IIo{app.LoadedIms}=II;
                    app.hdrkeep{app.LoadedIms}=[];
                    app.Imaxrange=max([app.Imaxrange max(II(:))]);

                    % downsample images
                    if(app.dwnsmp>0)
                        nwsz=[app.dwnsmp size(II,2)*app.dwnsmp/size(II,1)];
                    else
                        nwsz=size(II);
                    end
                    app.IIa{app.LoadedIms}=imresize(II,nwsz);% accumulated

                    % raw phasor coords
                    app.SSo{app.LoadedIms,1}=S0;
                    app.GGo{app.LoadedIms,1}=G0;

                    % fill nans before resizing!
                    S0=phasorFillNaN2(S0);
                    G0=phasorFillNaN2(G0);

                    % downsample images
                    app.SSa{app.LoadedIms,1}=imresize(S0,nwsz);% accumulated
                    app.GGa{app.LoadedIms,1}=imresize(G0,nwsz);% accumulated

                    % keep decay
                    app.dda{app.LoadedIms}=[];

                    % names
                    app.nms{app.LoadedIms}=[names{jj} '_' niceDigits(jj,2) '_(FALCON)'];
                    app.nmsx{app.LoadedIms}=[ ];
                    app.pat{app.LoadedIms}=filepaths{1};

                    % add to tree
                    aux=uitreenode(app.Tree,'Text',app.nms{app.LoadedIms},'NodeData',app.LoadedIms);
                    app.Tree.CheckedNodes=[app.Tree.CheckedNodes;aux];

                    % nocalib
                    app.calibflag(app.LoadedIms)=0;

                    percentatge(jj,size(ITT,3));
                    d=barraprogress(app,jj/size(ITT,3),d,names{jj});
                end
            else
                msgbox("No data loaded!","");
            end

            try,close(d);end
            % limit harmonics
            app.Harm.Value=1;
            app.Harm.Limits(2)=1;
            app.HarmLim=1;


        end
        function [] = LoadZeissLSMFun(app,filepaths)
            ensenya('Loading');
            if(ischar(filepaths)),filepaths={filepaths};end
            NN=numel(filepaths);
            d=barraprogress(app,0,[],'Loading');
            for ii=1:NN
                % limit harmonics

                [Data,LSMinfo] = lsmread(filepaths{ii});
                DX=LSMinfo.voxSizeX;
                Ims0=permute(Data,[5,4,2,3,1]);%Y,X,C,Z,T
                % t series and Z not implemented, only one or the other
                ZZ=size(Ims0,4);
                dm='_Z';
                if((ZZ==1)&&(size(Ims0,5)>1)),Ims0=permute(Ims0,[1,2,3,5,4]);dm='_T';end
                for zz=1:ZZ
                    if(ZZ==1),ext='';else ext=[dm niceDigits(ii)];end
                    Ims=Ims0(:,:,:,zz);  % zstacks and time series have to be implemented

                    app.LoadedIms=app.LoadedIms+1;

                    for hh=1:app.HarmLim
                        [S0,G0,im] = fpt(Ims,hh);
                        if(hh==1)
                            app.IIo{app.LoadedIms}=im;
                            app.hdrkeep{app.LoadedIms}=[];
                            app.Imaxrange=max([app.Imaxrange max(im(:))]);
                            % downsample images
                            if(app.dwnsmp>0)
                                nwsz=[app.dwnsmp size(im,2)*app.dwnsmp/size(im,1)];
                            else
                                nwsz=size(im);
                            end
                            app.IIa{app.LoadedIms}=imresize(im,nwsz);% accumulated
                        end
                        app.SSo{app.LoadedIms,hh}=S0;
                        app.GGo{app.LoadedIms,hh}=G0;

                        % fill nans before resizing!
                        S0=phasorFillNaN2(S0);
                        G0=phasorFillNaN2(G0);

                        % downsample images
                        app.SSa{app.LoadedIms,hh}=imresize(S0,nwsz);% accumulated
                        app.GGa{app.LoadedIms,hh}=imresize(G0,nwsz);% accumulated
                    end

                    % keep decay
                    app.dda{app.LoadedIms}=[];

                    % names
                    app.nms{app.LoadedIms}=filepaths{ii}(find(filepaths{ii}==filesep,1,'last')+1:end);
                    app.nmsx{app.LoadedIms}=ext;
                    app.pat{app.LoadedIms}=filepaths{ii}(1:find(filepaths{ii}==filesep,1,'last')-1);

                    % add to tree
                    aux=uitreenode(app.Tree,'Text',[app.nms{app.LoadedIms} app.nmsx{app.LoadedIms}],'NodeData',app.LoadedIms);
                    app.Tree.CheckedNodes=[app.Tree.CheckedNodes;aux];

                    % nocalib
                    app.calibflag(app.LoadedIms)=0;
                    d=barraprogress(app,((ii-1)+(zz/ZZ))/NN,d,['Loading']);
                end
            end

            app.Style.SelectedObject=app.SpectralStyle;


            d=barraprogress(app,1,d,'Finishing');
            try, close(d);end
            ensenya('Finished!','e');
        end
        function [] = LoadSimFCSrefFun(app,filepaths)

            ensenya('Loading 000%');
            d=barraprogress(app,0,[],'Loading');
            if(ischar(filepaths)),filepaths={filepaths};end
            NN=numel(filepaths);
            for ii=1:NN
                % limit harmonics
                switch filepaths{ii}(end-2:end)
                    case 're1',  app.Harm.Limits(2)=1;
                    case {'ref','R64'}, app.Harm.Limits(2)=2;
                    case 're3',  app.Harm.Limits(2)=3;
                    case 're4',  app.Harm.Limits(2)=4;
                end
                [FLIMdata,filename,pathname]=refread(filepaths{ii});
                im=FLIMdata{1}(:,:,1);

                app.LoadedIms=app.LoadedIms+1;

                app.IIo{app.LoadedIms}=im;
                app.hdrkeep{app.LoadedIms}=[];
                app.Imaxrange=max([app.Imaxrange max(im(:))]);

                % downsample images
                if(app.dwnsmp>0)
                    nwsz=[app.dwnsmp size(im,2)*app.dwnsmp/size(im,1)];
                else
                    nwsz=size(im);
                end
                app.IIa{app.LoadedIms}=imresize(im,nwsz);% accumulated

                for jj=2:2:size(FLIMdata{1},3)
                    Ph=FLIMdata{1}(:,:,jj);
                    Mo=FLIMdata{1}(:,:,jj+1);

                    S0=Mo.*sin(Ph*3.1416/180);
                    G0=Mo.*cos(Ph*3.1416/180);

                    app.SSo{app.LoadedIms,jj/2}=S0;
                    app.GGo{app.LoadedIms,jj/2}=G0;

                    % fill nans before resizing!
                    S0=phasorFillNaN2(S0);
                    G0=phasorFillNaN2(G0);

                    % downsample images
                    app.SSa{app.LoadedIms,jj/2}=imresize(S0,nwsz);% accumulated
                    app.GGa{app.LoadedIms,jj/2}=imresize(G0,nwsz);% accumulated
                end

                % keep decay
                app.dda{app.LoadedIms}=[];

                % names
                app.nms{app.LoadedIms}=filename{1};
                app.nmsx{app.LoadedIms}=[ ];
                app.pat{app.LoadedIms}=pathname(1:end-1);

                % add to tree
                aux=uitreenode(app.Tree,'Text',app.nms{app.LoadedIms},'NodeData',app.LoadedIms);
                app.Tree.CheckedNodes=[app.Tree.CheckedNodes;aux];

                % nocalib
                app.calibflag(app.LoadedIms)=0;

                percentatge(ii,NN);
                d=barraprogress(app,ii/NN,d,['Loading']);
            end

            % limit harmonics
            if(app.Harm.Value>app.Harm.Limits(2)),app.Harm.Value=app.Harm.Limits(2);end


            ensenya('Finished!','e');
            d=barraprogress(app,1,d,'Finishing');
            try, close(d);end
        end

        function [] = mostramask(app)
            cc=0;sz=ceil(256/sqrt(app.LoadedIms));
            mg=ceil(.01*max(sz*[app.imagerows.Value,app.imagecols.Value]));
            for ii=1:app.imagerows.Value
                for jj=1:app.imagecols.Value
                    cc=cc+1;mk=[];
                    if(cc>app.LoadedIms),mk=.5*ones(sz,sz);end
                    if(cc>numel(app.MKL))
                        mk=.5*ones(sz,sz);
                    else
                        if(isempty(app.MKL{cc}))
                            mk=.5*ones(sz,sz);
                        else
                            mk=[];
                        end
                    end
                    if(isempty(mk))
                        mk=app.MKL{cc};
                        mk=imresize(mk,[sz sz]);
                    end
                    if(jj==1)
                        IR=mk;
                    else
                        IR=cat(2,IR,.5*ones(sz,mg,size(mk,3)),mk);
                    end
                end
                if(ii==1)
                    IT=IR;
                else
                    IT=cat(1,IT,.5*ones(mg,size(IR,2),size(mk,3)),IR);
                end
            end
            imagesc(app.MKax,IT);
            xlim(app.MKax,[1,size(IT,2)]);
            ylim(app.MKax,[1,size(IT,1)]);
            % colormap(app.MKax,[.24 .15 .66;.98 .98 .08]);
            colormap(app.MKax,[0 0 0;.94 .94 .94;1 1 1]);
            % colormap(app.MKax,jet())


        end


        function [] = MotionIntWin(varargin)
            app=varargin{1};
            ff=varargin{2};
            N=numel(ff.Children);
            % disp('##');
            inone=0;
            for ii=1:N
                cp=ff.Children(ii).CurrentPoint(1,1:2);
                pp=ff.Children(ii).XLim;
                if(cp(1)>pp(1))&&(cp(1)<pp(2))
                    inone=1;
                    pp=ff.Children(ii).YLim;
                    if(cp(2)>pp(1))&&(cp(2)<pp(2))
                        cp=round(cp);
                        %                                 ff.Pointer='custom';
                        %                            ff.PointerShapeCData=app.curs2(:,:,app.radi);
                        % ff.PointerShapeHotSpot=[16 16];
                        ff.Pointer='crosshair';
                        qq=ff.Children(ii).UserData;
                        app.Tree.SelectedNodes=app.Tree.Children(qq);
                        % disp(ff.Children(ii).UserData);
                        if(isnan(cp(1)))
                            app.imcur.XData=-1000;
                        else
                            app.imcur.XData=app.GGi{qq}(cp(2),cp(1));
                            app.imcur.YData=app.SSi{qq}(cp(2),cp(1));
                            % if we want to display pix coords
                            % app.coolab.Text=['(' niceNums(cp(1),0) ',' niceNums(cp(2),0) ')'];
                            % display phasor coords
                            lag=app.GGi{qq}(cp(2),cp(1));
                            las=app.SSi{qq}(cp(2),cp(2));

                            if(app.LifetimeStyle.Value==1)
                                tp=magnitude(las,lag,5,app.freq,app.Harm.Value);
                                tn=magnitude(las,lag,7,app.freq,app.Harm.Value);
                                tm=magnitude(las,lag,6,app.freq,app.Harm.Value);
                                app.coolab.Text=['$$\rm \textsf{G=' niceNums(lag,2) ' S=' niceNums(las,2) ' }\tau_{p}\textsf{=' niceNums(tp,2) ' } \tau_{n}\textsf{=' niceNums(tn,2) ' }\tau_{m}\textsf{=' niceNums(tm,2) '}$$'];
                            else
                                app.coolab.Text=['$$\rm \textsf{G=' niceNums(lag,2) ' S=' niceNums(las,2) ' }$$'];

                            end
                        end
                        % figure(88);clf;
                        % imagesc(app.IIi{qq});hold on;
                        % plot(cp(1),cp(2),'or');
                    end

                end
            end
            if(inone==0)
                app.imcur.XData=-1000;
                app.coolab.Text='';
            end
        end

        function [] = createImageFigure(app)
            if(numel(app.IIa)>0)
                try,close(app.IWh);end
                sz=get(0,'screensize');
                sw=sz;% total win size
                n=app.imagerows.Value;
                m=app.imagecols.Value;
                if(app.imagewindowtile.Value==0)||(m*n<app.LoadedIms)
                    if(app.LoadedIms<17)
                        [n,m]=squareDistrib2(app.LoadedIms);
                    else
                        [n,m]=squareDistrib2(app.LoadedIms,sw(4)/sw(3),1);
                    end
                    % if(app.imagerows.Value*app.imagecols.Value<app.LoadedIms)
                        app.imagerows.Value=n;
                        app.imagecols.Value=m;
                    % end
                end
                sz(2)=50;sz(4)=sz(4)-68;% actial available space removing taskbar

                if(app.dwnsmp>0)
                    fits=round(sz(4)/app.dwnsmp/n);% how many images could fit in vertical respecting pix num
                else
                    fits=round(sz(4)/size(app.IIa{1},1)/n);
                end
                if(fits==0),fits=1;end

                figratio=sz(4)/sz(3);
                imratio=n/m;
                if(figratio/imratio<1)
                    sz(3)=sz(3)*figratio/imratio;
                    sz(3:4)=sz(3:4)/fits; % reduce in order to match win size to actual image size
                else
                    sz(4)=sz(4)*imratio/figratio;
                end
                % sz(3:4)=sz(3:4)/fits; % reduce in order to match win size to actual image size
                sz(2)=(sw(4)-sz(4))/2;
                sz(1)=sw(3)-sz(3);
                if(~isempty(app.figsz))&&(app.figsz(1)==0)&&(app.figsz(2)==0)% user never saved position
                    app.figsz=sz;
                else% take position from last use
                    app.figsz=[app.figsz(1:2) sz(3:4)];
                end
                % round(app.figsz);
                if(app.figsz(1)+app.figsz(3)>sw(3))
                    app.figsz(1)=sw(3)-app.figsz(3);% shift to left until fit
                    % app.figsz(1)=(sw(3)-app.figsz(3))/2;% center on screen
                end
                if(app.figsz(2)+app.figsz(4)>sw(4))
                    app.figsz(2)=sw(4)-app.figsz(4);% shift bot until fit
                    % app.figsz(2)=(sw(4)-app.figsz(4))/2;% center on screen
                end
                app.IWh=uifigure('WindowStyle','normal');%clf;

                if(app.margns.Value==1)
                    mg=[.01 .01 .01 .01]./[m n m n];
                else
                    mg=0;
                end


                for ii=1:app.LoadedIms
                    h=subNM(n,m,ii,mg,app.IWh);
                    hh=imagesc(h,app.blankIm);
                    axis(h,'image');
                    set(h,'xtick',[],'ytick',[],'toolbar',[]);
                    set(h,'Box','off','XColor',[0 0 0 0],'YColor',[0 0 0 0]);
                    app.AXh(ii)=hh;
                end

                set(app.IWh,'position',app.figsz,'name','Image Window','NumberTitle','off','menubar','none','Icon','scripts/logo1.png','WindowButtonMotionFcn',{@app.MotionIntWin});
            end
        end

        function [] = moveTreeElement(app,quin,newp,befaf)

            node1=app.Tree.Children(quin);
            node2=app.Tree.Children(newp);
            move(node1,node2,befaf);

            aux=app.MK;
            for ii=1:numel(app.Tree.Children)
                aux{ii}=app.MK{app.Tree.Children(ii).NodeData};
            end
            app.MK=aux;
            %
            aux=app.IIo;
            for ii=1:numel(app.Tree.Children)
                aux{ii}=app.IIo{app.Tree.Children(ii).NodeData};
            end
            app.IIo=aux;
            aux=app.hdrkeep;
            for ii=1:numel(app.Tree.Children)
                aux{ii}=app.hdrkeep{app.Tree.Children(ii).NodeData};
            end
            app.hdrkeep=aux;
            aux=app.SSo;
            for ii=1:numel(app.Tree.Children)
                for jj=1:app.HarmLim
                    aux{ii,jj}=app.SSo{app.Tree.Children(ii).NodeData,jj};
                end
            end
            app.SSo=aux;
            aux=app.GGo;
            for ii=1:numel(app.Tree.Children)
                for jj=1:app.HarmLim
                    aux{ii,jj}=app.GGo{app.Tree.Children(ii).NodeData,jj};
                end
            end
            app.GGo=aux;
            %
            aux=app.IIa;
            for ii=1:numel(app.Tree.Children)
                aux{ii}=app.IIa{app.Tree.Children(ii).NodeData};
            end
            app.IIa=aux;
            aux=app.SSa;
            for ii=1:numel(app.Tree.Children)
                for jj=1:app.HarmLim
                    aux{ii,jj}=app.SSa{app.Tree.Children(ii).NodeData,jj};
                end
            end
            app.SSa=aux;
            aux=app.GGa;
            for ii=1:numel(app.Tree.Children)
                for jj=1:app.HarmLim
                    aux{ii,jj}=app.GGa{app.Tree.Children(ii).NodeData,jj};
                end
            end
            app.GGa=aux;
            %
            aux=app.IIi;
            for ii=1:numel(app.Tree.Children)
                aux{ii}=app.IIi{app.Tree.Children(ii).NodeData};
            end
            app.IIi=aux;
            aux=app.SSi;
            for ii=1:numel(app.Tree.Children)
                aux{ii}=app.SSi{app.Tree.Children(ii).NodeData};
            end
            app.SSi=aux;
            aux=app.GGi;
            for ii=1:numel(app.Tree.Children)
                aux{ii}=app.GGi{app.Tree.Children(ii).NodeData};
            end
            app.GGi=aux;
            %
            aux=app.currPP;
            for ii=1:numel(app.Tree.Children)
                aux(:,:,ii)=app.currPP(:,:,app.Tree.Children(ii).NodeData);
            end
            app.currPP=aux;
            %
            aux=app.dda;
            for ii=1:numel(app.Tree.Children)
                aux{ii}=app.dda{app.Tree.Children(ii).NodeData};
            end
            app.dda=aux;
            aux=app.nms;
            for ii=1:numel(app.Tree.Children)
                aux{ii}=app.nms{app.Tree.Children(ii).NodeData};
            end
            app.nms=aux;
            aux=app.nmsx;
            for ii=1:numel(app.Tree.Children)
                aux{ii}=app.nmsx{app.Tree.Children(ii).NodeData};
            end
            app.nmsx=aux;
            aux=app.pat;
            for ii=1:numel(app.Tree.Children)
                aux{ii}=app.pat{app.Tree.Children(ii).NodeData};
            end
            app.pat=aux;
            aux=app.calibflag;
            for ii=1:numel(app.Tree.Children)
                aux(ii)=app.calibflag(app.Tree.Children(ii).NodeData);
            end
            app.calibflag=aux;
            % %
            for ii=1:numel(app.Tree.Children)
                app.Tree.Children(ii).NodeData=ii;
            end

            aux=zeros(numel(app.Tree.CheckedNodes),1);
            if(~isempty(aux))
                for ii=1:numel(app.Tree.CheckedNodes)
                    aux(ii)=app.Tree.CheckedNodes(ii).NodeData;
                end
                aux=sort(aux);

                app.Tree.CheckedNodes=[];
                for ii=aux
                    app.Tree.CheckedNodes=[app.Tree.CheckedNodes;app.Tree.Children(ii)];
                end
            end


            replotII(app);

        end

        function [] = paintRois(app)
            for ii=1:size(app.roidad,1)
                if(app.roidad(ii,3)>0)
                    roi = images.roi.Circle(app.PP,'center',app.roidad(ii,1:2),'color',app.colMks(ii,:),'radius',app.roidad(ii,3));
                    roi.InteractionsAllowed='none';
                    app.curhan=[app.curhan roi];
                end
            end
        end


        function [] = plotHists(app)
            cla(app.histax);
            rang=checkedFiles(app);
            qv=[app.Imin.Value app.Imax.Value];
            hold(app.histax,'on');
            if(app.relative.Value==1)
                top=1;
            else
                top=app.Imaxrange;
            end
            for ii=rang
                im=app.IIa{ii};
                if(app.relative.Value==1)
                    im=im/max(im(:));
                end

                h = histcounts(im(:),linspace(0,top,101));
                if(app.Logp.Value==1)
                    h=log(1+h);
                end
                h=.86*h/max(h);
                plot(app.histax,h,'k');

            end
            xlim(app.histax,[0 100]);ylim(app.histax,[0 1]);
            patch(app.histax,[0 0 qv(1) qv(1)]*100/top,[0 1 1 0],'k','EdgeColor','none','FaceAlpha',.2);
            patch(app.histax,[qv(2) qv(2) top top]*100/top,[0 1 1 0],'k','EdgeColor','none','FaceAlpha',.2);
            hold(app.histax,'off');
        end

        function [] = phasorUnmixing(app)
            app.running=1;
            % see what nodes are checked
            rang=checkedFiles(app);
            N=numel(rang);
            filterfactor=1;
            top=ceil((app.compNum-1)/2);
            if(top>app.HarmLim)
                ensenya('Warning: Number of computed harmonics is lower than required for system of equations!','r');
                top=app.HarmLim;
            end
            if(top==0),top=1;end
            % top=app.HarmLim;%% this top can be set to end in app.HarmLim if we want to include more equations
            if(app.compNum>0)
                if(isempty(app.folderR))
                    BrowseResultsButtonPushed(app);
                end
                d = barraprogress(app,0,[],'Unmxing');
                coefs=[];
                for hh=1:top
                    for ii=1:app.compNum
                        coefs=[coefs; app.compdad(ii,2,hh) app.compdad(ii,1,hh)];
                    end
                end
                cc=0;
                holdims=cell(0);% keep fraction ims in memory incase group by file
                name='';
                qv=[app.Imin.Value app.Imax.Value];
                for ii=rang
                    cc=cc+1;
                    if(app.exportHR==1)%
                        im=sum(app.IIo{ii},3);
                    else
                        im=app.IIa{ii};
                    end


                    im=round(1000*im)/1000;% achtung
                    if(app.relative.Value==1)
                        im=im/max(im(:));
                    end
                    im=(im-qv(1))/diff(qv);
                    im(im<0)=0;
                    if(app.Excess.Value==0)
                        im(im>1)=1;
                    else
                        im(im>1)=0;
                    end

                    for hh=1:top
                        if(app.exportHR==1)%
                            S=app.SSo{ii,hh};
                            G=app.GGo{ii,hh};
                            if(app.DoCalib.Value==1)% high res images is on originals, need to be calibrated
                                pha=atan2(S,G)+app.PD(app.calibflag(ii),hh);
                                modu=sqrt((S.*S)+(G.*G))*app.MF(app.calibflag(ii),hh);
                                S=modu.*sin(pha);G=modu.*cos(pha);
                            end
                        else
                            S=app.SSa{ii,hh};
                            G=app.GGa{ii,hh};
                        end                %%

                        %%
                        if(app.Filterbefore.Value==0)  % filter being smooth before int thresh
                            S(im==0)=NaN;
                            G(im==0)=NaN;
                        end

                        % smooth
                        [S,G] = phasorSmooth(S,G,app.Eb.Value*filterfactor,app.Ti.Value,0,[],1);
                        %%
                        if(app.Filterbefore.Value==1)  % filter being smooth before int thresh
                            S(im==0)=NaN;
                            G(im==0)=NaN;
                        end
                        % crop
                        [S,G] = phasorSmoothCrop(S,G,app.Eb.Value*filterfactor,app.Ti.Value);


                        if(hh==1)
                            SS=S;GG=G;
                        else
                            SS=cat(3,SS,S);GG=cat(3,GG,G);
                        end
                    end
                    [im,im] = phasorSmoothCrop(im,im,app.Eb.Value*filterfactor,app.Ti.Value);

                    if(app.ApplyImageMasks==1)
                        % incase we loadded a single image
                        ind=mod(ii,numel(app.MKL));if(ind==0),ind=numel(app.MKL);end
                        aux=app.MKL{ind};
                        out=(size(im,1)-size(aux,1))/2;
                        if(out>0)
                            aux=imresize(aux,size(im),'nearest');
                            % im=im(1+out:end-out,1+out:end-out);
                            % SS=SS(1+out:end-out,1+out:end-out,:);
                            % GG=GG(1+out:end-out,1+out:end-out,:);
                        end
                        if(out<0)
                            out=-out;
                            aux=aux(1+out:end-out,1+out:end-out);
                        end
                        try
                            im=im.*aux;
                            inds=find(aux==0);
                            off=numel(im);
                            for hh=1:top
                                SS(inds+off*(hh-1))=NaN;
                                GG(inds+off*(hh-1))=NaN;
                            end
                        catch me
                            ensenya(['Error: Failed while applying mask: ' me.message],'r');
                        end

                    end

                    aux=size(SS,1);
                    SS=permute(SS,[3 1 2]);
                    GG=permute(GG,[3 1 2]);
                    WW=zeros(app.compNum,aux,size(SS,3));
                    for jj=1:aux
                        for kk=1:size(SS,3)

                            [weights] = solveNunknowns(SS(:,jj,kk),GG(:,jj,kk),coefs);
                            WW(:,jj,kk)=weights;
                        end

                        d = barraprogress(app,(((cc-1)*aux)+jj)/aux/N,d,'Unmxing');
                    end
                    WW=permute(WW,[2 3 1]);
                    if(app.exportratiometric.Value==1)
                        try
                            q1=app.comp1.Value;
                            q2=app.comp2.Value;
                            R=WW(:,:,q1)./(WW(:,:,q1)+WW(:,:,q2));
                            if(app.ratiocsv.Value==1)
                                aux=R(:);
                                out=isnan(aux);
                                aux(out)=[];
                                aux=round(aux*10000)/10000;
                                if(app.includeint.Value==1)
                                    aux2=im(:);
                                    aux2(out)=[];
                                    aux=[aux2,aux];
                                end
                                muntaCSV([app.folderR filesep app.nms{ii} '_Unmix' app.nmsx{ii} '_C' num2str(q1) '_vs_C' num2str(q2) '.csv'],',',aux);
                            end
                            R(R>1)=1;
                            R(R<0)=0;
                            R=uint8(R*255);
                            imwrite(R,[app.folderR filesep app.nms{ii} '_Unmix' app.nmsx{ii} '_C' num2str(q1) '_vs_C' num2str(q2) '.jpg']);
                        end
                    end
                    if(app.CompImsCSVExp.Value==1)
                        aux=reshape(WW,[size(WW,1)*size(WW,2) size(WW,3)]);
                        if(app.includeint.Value==1)
                            aux2=im(:);
                            aux2(isnan(aux(:,1)))=[];
                        end
                        aux(isnan(aux(:,1)),:)=[];
                        head=cell(0);for jj=1:app.compNum;head{jj}=['C' num2str(jj)];end
                        if(app.includeint.Value==1)
                            aux=[aux2,aux];
                            head(2:app.compNum+1)=head(1:app.compNum);
                            head{1}='Int';
                        end
                        aux=round(aux*10000)/10000;
                        muntaCSV([app.folderR filesep app.nms{ii} '_Unmix' app.nmsx{ii} '.csv'],',',head,aux);
                    end
                    if(app.CompImsExp.Value==1)
                        % rgb=zeros(size(WW,1),size(WW,2),3);
                        if(app.CompImsIntExp.Value==1)%
                            for jj=1:size(WW,3)
                                WW(:,:,jj)=WW(:,:,jj).*im;
                            end
                        end
                        if(app.CompImsCol.Value==1)
                            WW2=zeros(size(WW,1),size(WW,2),3,size(WW,3));
                            rgb=zeros(size(WW,1),size(WW,2),3);
                            for jj=1:size(WW,3)
                                aux=gray3rgb(WW(:,:,jj),superjet(1,app.colcomps(jj)));
                                rgb=rgb+aux;
                                WW2(:,:,:,jj)=aux;
                            end
                        else
                            WW2=permute(WW,[1 2 4 3]);
                        end

                        marginsize=ceil(size(WW2,1)*.01);
                        WWm=WW2;
                        if(app.addmargins.Value==1)
                            WWm=cat(1,ones(marginsize,size(WWm,2),size(WWm,3),size(WWm,4)),WWm,ones(marginsize,size(WWm,2),size(WWm,3),size(WWm,4)));
                            WWm=cat(2,ones(size(WWm,1),marginsize,size(WWm,3),size(WWm,4)),WWm,ones(size(WWm,1),marginsize,size(WWm,3),size(WWm,4)));
                        end
                        if(app.singleimage.Value==0)
                            for jj=1:app.compNum
                                imwrite(WWm(:,:,:,jj),[app.folderR filesep app.nms{ii} '_Unmix' app.nmsx{ii} '_C' num2str(jj) '.jpg']);
                            end
                        else
                            if(app.groupfile.Value==0)
                                holdims{cc}=WWm;% keep comp ims for tiling
                            else
                                if(app.imagecols.Value*app.imagerows.Value<app.compNum)
                                    app.imagecols.Value=app.compNum;
                                    app.imagerows.Value=1;
                                end
                                cc2=0;
                                for jj=1:app.imagerows.Value
                                    for kk=1:app.imagecols.Value
                                        cc2=cc2+1;
                                        if(cc2>app.compNum)
                                            if(app.addmargins.Value==1)
                                                thim=ones(size(thim));
                                            else
                                                thim=zeros(size(thim));
                                            end
                                        else
                                            thim=WWm(:,:,:,cc2);
                                        end
                                        if(kk==1)
                                            Row=thim;
                                        else
                                            Row=cat(2,Row,thim);
                                        end
                                    end
                                    % if(cc2>numel(holdims)),if(jj==1),WWm2=Row;end;break;end
                                    if(jj==1)
                                        WWm2=Row;
                                    else
                                        if(size(WWm2,2)~=size(Row,2))
                                            Row=cat(2,Row,ones(size(Row,1),size(WWm2,2)-size(Row,2),size(Row,3)));
                                        end
                                        WWm2=cat(1,WWm2,Row);
                                    end
                                end
                                WWm2(WWm2>1)=1;
                                WWm2(WWm2<0)=0;
                                imwrite(WWm2,[app.folderR filesep app.nms{ii} '_Unmix' app.nmsx{ii} '.jpg']);

                            end
                        end
                    end
                end
                d = barraprogress(app,1,d,'Finishing');
                if(app.CompImsExp.Value==1)&&(app.groupfile.Value==0)
                    if(app.imagecols.Value*app.imagerows.Value~=numel(holdims))
                        app.imagecols.Value=numel(holdims);
                        app.imagerows.Value=1;
                    end
                    accumula=[];contacc=0;
                    for ii=1:app.compNum

                        for jj=1:numel(holdims)
                            aux=holdims{jj}(:,:,:,ii);
                            if(jj==1)
                                WWm=aux;
                            else
                                WWm=cat(4,WWm,aux);
                            end
                        end
                        cc2=0;Row=[];

                        for jj=1:app.imagerows.Value
                            for kk=1:app.imagecols.Value
                                cc2=cc2+1;
                                if(cc2>numel(holdims))
                                    aqu=zeros(size(WWm(:,:,:,numel(holdims))));
                                else
                                    aqu=WWm(:,:,:,cc2);
                                end
                                if(kk==1)
                                    Row=aqu;
                                else
                                    Row=cat(2,Row,aqu);
                                end
                            end
                            % if(cc2>numel(holdims)),if(jj==1),WWm2=Row;end;break;end
                            if(jj==1)
                                WWm2=Row;
                            else
                                if(size(WWm2,2)~=size(Row,2))
                                    Row=cat(2,Row,ones(size(Row,1),size(WWm2,2)-size(Row,2),size(Row,3)));
                                end
                                WWm2=cat(1,WWm2,Row);
                            end
                        end
                        if(cc2>0)
                            if(~isempty(WWm2))
                                WWm2(WWm2>1)=1;
                                WWm2(WWm2<0)=0;
                                imwrite(WWm2,[app.folderR filesep 'Component' num2str(ii) '.jpg']);
                                if(app.mergecomps.Value==1)
                                    contacc=contacc+1;
                                    if(contacc==1)
                                        accumula=WWm2;
                                    else
                                        accumula=accumula+WWm2;
                                    end
                                    if(ii==app.compNum)
                                        if(app.addmargins.Value==1)
                                            qqq=accumula;
                                            out=find(sum(qqq,3)==9);
                                            outt=[out;out+size(qqq,1)*size(qqq,2);out+2*size(qqq,1)*size(qqq,2)];
                                            qqq(outt)=[];
                                            qqq=quantile(qqq(:),.99);
                                        else
                                            qqq=quantile(accumula(:),.99);
                                        end
                                        accumula=accumula/qqq;
                                        accumula(accumula>1)=1;
                                        imwrite(accumula,[app.folderR filesep 'CompMerge.jpg']);
                                    end
                                end
                            end
                        end
                    end
                end
                clear holdims;
                try,close(d);end
            end
            app.running=0;
        end


        function [] = replotPP(app)
            app.running=1;
            PPa=app.currPP;
            IC=app.currIC;
            cla(app.PP);
            % under some wierd circumstance axes dont update ticks
            % set(app.PP,'XTickMode', 'auto', 'XTickLabelMode', 'auto','YTickMode', 'auto', 'YTickLabelMode', 'auto');
            app.PP.Toolbar.Visible='off';
            Sr = [app.Smin.Value app.Smax.Value];
            Gr = [app.Gmin.Value app.Gmax.Value];


            % see what nodes are checked
            rang=checkedFiles(app);

            if(app.logDens.Value==1)
                PPa=log(1+PPa);
            end
            if(app.normFile.Value==1)
                for ii=rang
                    PPa(:,:,ii)=NormArray2(PPa(:,:,ii));
                end
            end
            daphasor=NormArray2(sum(PPa(:,:,rang),3));


            im0=NormArray2(daphasor);% for contour

            % figure;imagesc(im0);

            im=gray2rgb(im0,superjet(255,app.PCM));

            hold(app.PP,'on');
            fov=[Gr;Sr];
            % sz=[round(256*diff(Sr)/diff(Gr)) 256];


            xI=[fov(1,1:2);fov(1,1:2)];
            yI=[fov(2,2) fov(2,2);fov(2,1) fov(2,1)];
            zI=[0 0; 0 0];
            % facc=.3;
            % fons=facc+(1-facc)*IC;
            % fons(1,:,:)=0;
            % fons(end,:,:)=0;
            % fons(:,1,:)=0;
            % fons(:,end,:)=0;
            % if(nargin>1)
            if(app.PhBg.Value==1)
                [~,alp]=white2alpha(im,.95,0);
                fons=IC;
                facc=.4;% attenuation factor
                fons=(1-facc)+facc*fons;
                alp=repmat(alp,[1 1 3]);
                fons(alp==1)=im(alp==1);
                % figure(6),imagesc(fons);axis image;
            else
                fons=im;
            end

            h=surf(app.PP,xI,yI,zI,'CData',fons,'FaceColor','texturemap','EdgeColor','none');
            set(h,'hittest','off');

            levs=[.001,.005,.01,.05,.1,.3,.5,.9];

            if(app.contours.Value==1)
                [x,y]=meshgrid(linspace(fov(1,1),fov(1,2),size(im,2)),linspace(fov(2,1),fov(2,2),size(im,1)));
                I=im0;I=imgaussfilt(I,size(I,1)*.03);
                I=I(end:-1:1,:);
                I=NormArray2(I);

                [~,h]=contour(app.PP,x,y,I,levs,'k');
                set(h,'hittest','off');
            end
            %% %plot gm model
            if(~isempty(app.GMfit))
                if(app.elipse.Value==1)

                    fac=find(round(2*cumsum(normpdf([0:.01:5])))==app.conf.Value)*.01;
                    fac=fac(1);
                    ang=linspace(0,2*3.1416,100);
                    for ii=1:size(app.GMfit.mu,1)% for each cluster

                        co=fac*app.GMfit.Sigma(:,:,ii);
                        a=co(2,2);b=co(1,2);c=co(1,1);
                        l1=(a+c)/2+sqrt((b^2)+((a-c)/2)^2);
                        l2=(a+c)/2-sqrt((b^2)+((a-c)/2)^2);
                        if(b==0)
                            if(a>=c)
                                th=0;
                            else
                                th=3.1416;
                            end
                        else
                            th=atan2(l1-a,b);
                        end

                        x=sqrt(l1)*cos(th)*cos(ang)-sqrt(l2)*sin(th)*sin(ang)+app.GMfit.mu(ii,2);
                        y=sqrt(l1)*sin(th)*cos(ang)+sqrt(l2)*cos(th)*sin(ang)+app.GMfit.mu(ii,1);


                        h=plot(app.PP,x,y);
                        set(h,'color',app.colMks(app.GMfitcolinds(ii),:),'linewidth',3,'hittest','off');

                    end
                else
                    grid0=zeros(size(PPa,1),size(PPa,2));
                    % dospidos=(2*pi)^2;
                    [x,y]=meshgrid(linspace(fov(1,1),fov(1,2),size(PPa,2)),linspace(fov(2,1),fov(2,2),size(PPa,1)));
                    coo=[y(:),x(:)];
                    mu=app.GMfit.mu;
                    sigmas=app.GMfit.Sigma;
                    for ii=1:size(mu,1)% for each cluster
                        %                     preexp=1/sqrt(dospidos*det(sigmas(:,:,jj)));% gaussian preexponential factor
                        preexp=1;
                        if(ii==1),gridd=grid0;end
                        questo=reshape(preexp*exp(-0.5*sum(((coo-mu(ii,1:2))').*(sigmas(:,:,ii)\(coo-mu(ii,1:2))'),1))',size(PPa,1),size(PPa,2));
                        if(app.contseparate.Value==1)
                            [~,h]=contour(app.PP,x,y,questo,4,'b');
                            set(h,'EdgeColor',app.colMks(app.GMfitcolinds(ii),:),'hittest','off');
                        end
                        gridd=gridd+questo;
                    end
                    if(app.conttogether.Value==1)
                        [~,h]=contour(app.PP,x,y,gridd,levs,'k');
                        set(h,'hittest','off');
                    end
                end
            end
            %% color for text and stuff
            fons=superjet(1,app.PCM(1));
            coloret=inverseColor(fons);
            %% universal circle
            if(app.UCcb.Value==1)
                steps=6;% angular steps for universal circle
                if(app.LifetimeStyle.Value==1)
                    for ii=1:steps:180
                        h=plot(app.PP,.5*[1+cos((ii)*3.1416/180) 1+cos((ii-steps)*3.1416/180)],.5*[sin((ii)*3.1416/180) sin((ii-steps)*3.1416/180)]);
                        set(h,'color',coloret,'hittest','off');
                    end
                    if(ii<180)% if steps isnt a divisor the last segment is lost!
                        h=plot(app.PP,.5*[1+cos((ii)*3.1416/180) 1+cos((180)*3.1416/180)],.5*[sin((ii)*3.1416/180) sin((180)*3.1416/180)]);
                        set(h,'color',coloret,'hittest','off');
                    end
                end
                if(app.SpectralStyle.Value==1)
                    for ii=1:steps:360
                        h=plot(app.PP,[cos((ii)*3.1416/180) cos((ii-steps)*3.1416/180)],[sin((ii)*3.1416/180) sin((ii-steps)*3.1416/180)]);
                        set(h,'color',coloret,'hittest','off');
                    end
                end
            end
            %% ticks
            if(app.ticks.Value==1)
                if(app.LifetimeStyle.Value==1)
                    omega=2*3.1416*app.Harm.Value*app.freq;
                    if(app.tickNumber.Value==10)
                        taus=[.1 .5 1 2 3 5 8 13 21 34]*1e-9;
                    else
                        ph=linspace(0,3.1416,app.tickNumber.Value+2);
                        gg=sort(.5+.5*cos(ph));
                        gg(gg<1e-4)=[];
                        seq=1e9*sqrt((1./gg)-1)/omega;
                        seq=round(10*seq)/10;
                        seq=unique(seq);
                        seq(seq==Inf)=[];
                        seq(seq==0)=[];
                        taus=seq*1e-9;
                    end

                    gg=1./(1+(omega*taus).^2);
                    ss=(omega*taus)./(1+(omega*taus).^2);
                    in=find(((gg>Gr(1))&(gg<Gr(2)))&((ss>Sr(1))&(ss<Sr(2))));
                    gg=gg(in);ss=ss(in);taus=taus(in)*1e9;
                    pseudo=atan2(ss,(gg-.5));
                    desf=.5;ra=.01;ra=[.5-ra .5+ra];ra0=.5;
                    unitats='ns  ';rightleft=.5;upper=.3;lower=0;top=.461;bot=0;
                end
                if(app.SpectralStyle.Value==1)
                    desf=0;ra=.02;ra=[1-ra 1+ra];ra0=1;%ra=[0 1];
                    pseudo=linspace(0,6.2832,app.tickNumber.Value+1);
                    taus=round(app.SpecMin.Value+pseudo*(app.SpecMax.Value-app.SpecMin.Value)/6.2832);
                    unitats='nm  ';rightleft=0;upper=.6;lower=-.6;top=.96;bot=-top;
                end
                if(app.UmapStyle.Value==0)


                    hp={'left','center','right'};vp={'top','mid','bot'};
                    for ii=1:length(taus)
                        h=plot(app.PP,[desf+ra(1)*cos(pseudo(ii)) desf+ra(2)*cos(pseudo(ii))],[ra(1)*sin(pseudo(ii)) ra(2)*sin(pseudo(ii))]);
                        set(h,'color',coloret,'hittest','off');
                        coords=[desf+ra0*cos(pseudo(ii)) ra0*sin(pseudo(ii))];
                        hpp=1;vpp=2;exv=0;
                        % fancy positioning of text %
                        if(coords(1,1)>rightleft),hpp=3;end
                        if(coords(1,2)>upper),hpp=4-hpp;end
                        if(coords(1,2)<lower),hpp=4-hpp;end
                        if(coords(1,2)>top),hpp=2;vpp=1;end
                        if(coords(1,2)<bot),hpp=2;vpp=3;end
                        if(taus(ii)==app.SpecMin.Value),vpp=3;end
                        if(taus(ii)==app.SpecMax.Value),vpp=1;end
                        %     if(coords(1,2)>.46),vpp=1;end
                        h=text(app.PP,coords(1,1),coords(1,2),{['  ' num2str(taus(ii)) unitats ]});
                        %        h=text(coords(1,1),coords(1,2),{[' \tau=' num2str(taus(ii)*1e9) 'ns ' ]});
                        set(h,'color',coloret,'fontname','calibri','fontsize',app.tickfontsize.Value,'horizontalalign',hp{hpp},'verticalalign',vp{vpp},'margin',.1,'hittest','off');
                        %      set(h,'BackgroundColor',[colordefons .5]);
                    end
                end
            end
            %%
            paintRois(app);
            %%
            app.imcur=plot(app.PP,-1000,-1000,'o');
            app.imcur.MarkerSize=6;
            app.imcur.LineWidth=2;
            app.imcur.MarkerEdgeColor=coloret;
            app.imcur.MarkerFaceColor=[fons];
            % app.imcur.MarkerFaceColor=[1 1 1];
            %%
            set(app.PP,'fontsize',app.AFSef.Value);

            axis(app.PP,'equal');
            axis(app.PP,'tight');
            app.PP.YLim=Sr;
            app.PP.XLim=Gr;
            app.curs1=images.roi.Circle(app.PP,'center',[.5 .5]-1000,'color',app.colMks(app.currCol,:),'radius',app.radi,'InteractionsAllowed','none','FaceAlpha',0);

            %%
            if(app.Harm.Value>1)
                app.coolab.Text=['harmonic #' num2str(app.Harm.Value)];
                app.coolab.FontColor='r';
            end
            %%
            if(app.ShowComponents.Value==1)
                if(app.AddLines.Value==1)
                    % sort components so lines dont intersect
                    aux=app.compdad(1:app.compNum,1:2)-mean(app.compdad(1:app.compNum,1:2));
                    phases=atan2(aux(:,2),aux(:,1));
                    [~,ord]=sort(phases);
                    ord=[ord ord];
                    for ii=1:app.compNum
                        if(ii==1),anterior=ord(app.compNum);else anterior=ord(ii-1);end
                        co1=app.compdad(ord(ii),1:2,app.Harm.Value);
                        co2=app.compdad(anterior,1:2,app.Harm.Value);
                        h=plot(app.PP,[co1(1) co2(1)],[co1(2) co2(2)],'k--','linewidth',.5);
                    end
                end
                for ii=1:app.compNum
                    roi = images.roi.Circle(app.PP,'center',app.compdad(ii,1:2,app.Harm.Value),'edgecolor','k','color',superjet(1,app.colcomps(ii)),'radius',.01,'FaceAlpha',1,'linewidth',0.1);
                    roi.InteractionsAllowed='none';
                end
            end

            %%
            hold(app.PP,'off');
            app.running=0;
        end


        function [] = replotII(app)
            app.running=1;
            if(app.LoadedIms>0)
                app.createMasksFromRois();
                if((ishandle(app.IWh)==0))%||isempty(app.IWh))
                    createImageFigure(app);
                end
                % clf(app.IWh);
                n=app.imagerows.Value;
                m=app.imagecols.Value;
                % if(app.imagewindowtile.Value==0)%)||(m*n<app.LoadedIms)
                %     if(app.LoadedIms<17)
                %         [n,m]=squareDistrib2(app.LoadedIms);
                %     else
                %         sw=get(0,'screensize');
                %         [n,m]=squareDistrib2(app.LoadedIms,sw(4)/sw(3),1);
                %     end
                %     % end
                %     % if(app.imagerows.Value*app.imagecols.Value<app.LoadedIms)
                %     app.imagerows.Value=n;
                %     app.imagecols.Value=m;
                % end
                % % app.tilerows2.Value=n;
                %
                %
                % if(app.margns.Value==1)
                %     mg=[.01 .01 .01 .01]./[m n m n];
                % else
                %     mg=0;
                % end

                % see what nodes are checked
                rang=checkedFiles(app);
                ord=rang;
                if(app.fliprows.Value==1)
                    ord2=rang;
                    for ii=rang
                        if(mod(ceil(ii/m),2)==0)
                            ord2(ord==ii)=((ceil(ii/m)-1)*m)+(ceil(ii/m)*m)-ii+1;
                        end
                    end
                    ord=ord2;
                end
                cc=0;
                for ii=1:app.LoadedIms
                    set(app.AXh(ii),'CData',app.blankIm);
                end
                for ii=rang
                    cc=cc+1;
                    % h=subNM(n,m,ord(cc),mg,app.IWh);


                    im=app.IIi{ii};
                    im=repmat(im,[1 1 3]);

                    mk=app.MK{ii};

                    if(app.SoftCursor.Value==0)% simFCS style only masks
                        out=find(((mk(:,:,1)==1)&(mk(:,:,2)==1))&(mk(:,:,3)==1));
                        mk(out)=im(out);
                        df=numel(mk)/3;
                        mk(out+df)=im(out+df);
                        mk(out+df+df)=im(out+df+df);
                        im=mk;
                    else% intensity in bw with mask overlay
                        try
                            for jj=1:3
                                im(:,:,jj)=im(:,:,jj).*mk(:,:,jj);
                            end
                        catch
                            gg=0;
                        end
                    end
                    if(app.ApplyImageMasks==1)
                        % incase we loaded a single image
                        ind=mod(ii,numel(app.MKL));if(ind==0),ind=numel(app.MKL);end
                        aux=app.MKL{ind};
                        out=(size(im,1)-size(aux,1))/2;
                        if(out>0)                            
                            aux=imresize(aux,[size(im,1) size(im,2)],'nearest');
                        end
                        if(out<0)
                            if(0)% this would be used if mask was same size as original image but due to filtering we gotta crop some
                                out=-out;
                                aux=aux(1+out:end-out,1+out:end-out);
                            else% mask is highres and image is downsampled, we gotta downsample mask
                                aux=imresize(aux,[size(im,1) size(im,2)],'nearest');
                            end
                        end

                        try
                            % stripes=imresize(app.MKoutim,size(aux));
                            % stripes=.5+.5*repmat(stripes,[1 1 3]);
                            aux=repmat(aux,[1 1 3]);
                            % im(aux==0)=stripes(aux==0);
                            im(aux==0)=0;
                        end

                    end

                    % tic
                    % imshow(im,'parent',app.AXh(ii));
                    % axes(app.AXh(ord(cc)));
                    set(app.AXh(ord(cc)),'CData',im);
                    % imagesc(im);
                    %
                    %   axis(h,'image');%colormap(parula);
                    % toc
                    set(get(app.AXh(ord(cc)),'parent'),'UserData',ii);
                    % set(gca,'xtick',[],'ytick',[],'UserData',ii);
                    % h.Toolbar.Visible='off';%
                    % if(app.margns.Value==0)
                    %     h.Box='off';
                    %     h.XColor=[0 0 0 0];
                    %     h.YColor=[0 0 0 0];
                    % end
                end
                % gg=0;
                %             % Create invbut
                % app.Inv = uicontrol(6868,'style','push');
                % % app.Inv.ButtonPushedFcn = createCallbackFcn(app, @XButtonPushed, true);
                % app.Inv.Tooltip = {'Invert mask'};
                % app.Inv.Position = [2 2 24 24];
                % app.Inv.String = ' ';
                % app.Inv.CData = imread('inverse.png');

            end
app.running=0;
        end


        function [] = setcolors(app)
            % app.currcol

            fac=.9-2*app.DM/10;

            % default colors
            fg=[0.06,0.06,0.06];
            bg=[0.94,0.94,0.94];
            % set current
            app.FGcol=fg.*(fac+(1-fac)*app.ALLcols(app.currcol,:));
            app.BGcol=bg.*(fac+(1-fac)*app.ALLcols(app.currcol,:));
            if(app.DM==1)
                aux=app.BGcol;
                app.BGcol=app.FGcol;
                app.FGcol=aux;
                app.BGcol=3*app.BGcol;
                app.BGcol2=.8*app.BGcol;
                app.BGcol3=app.BGcol+(app.FGcol-app.BGcol)*.2;
            else
                app.BGcol2=.8*app.BGcol;
                app.BGcol3=[1 1 1];
            end
            app.blankIm=permute(app.BGcol,[1 3 2]);


            app.FGcol(app.FGcol>1)=1;app.FGcol(app.FGcol<0)=0;
            app.BGcol(app.BGcol>1)=1;app.BGcol(app.BGcol<0)=0;
            app.BGcol2(app.BGcol2>1)=1;app.BGcol2(app.BGcol2<0)=0;

            aux=fieldnames(app.findobj);
            for ii=1:numel(aux)
                eval(['h=app.' aux{ii} ';']);
                try
                    set(h,'Color',app.BGcol);
                end
                if(strcmp(class(h),'matlab.ui.control.NumericEditField'))
                    set(h,'BackgroundColor',app.BGcol3);
                else
                    try
                        set(h,'BackgroundColor',app.BGcol);
                    end
                end
                try
                    set(h,'ForegroundColor',app.FGcol);
                end
                try
                    set(h,'FontColor',app.FGcol);
                end
            end
            set(app.PP,'XColor',app.FGcol,'YColor',app.FGcol);
            app.Tree.BackgroundColor=app.BGcol3;
            app.histax.Color=app.BGcol3;
            app.MKax.Color=app.BGcol3;
            % app.ContextMenu.Children(1).Label='<html><b><i>test</b></i></html>';

            app.coolab.BackgroundColor='none';
            if(1)%keep cols
                app.X.FontColor=[1.00,0.00,0.00];
                app.PlotTrueSize.FontColor=[0.00,0.00,1.00];
                app.ComputeLR.FontColor=[0.00,0.00,1.00];
                app.ComputeHR.FontColor=[0.00,0.00,1.00];
                app.BrowseResults.FontColor=[0.47,0.67,0.19];
                app.ExportHRButton.FontColor=[0.00,0.00,1.00];
                app.ExportLRButton.FontColor=[0.00,0.00,1.00];
                app.ExpPhWinButton.FontColor=[0.31,0.57,0.97];
                app.ExpImWinButton.FontColor=[0.31,0.57,0.97];
            end
            for ii=1:numel(app.TabEixos.Children)
                if(strcmp(class(app.TabEixos.Children(ii)),'matlab.graphics.primitive.Rectangle'))
                    if(app.TabEixos.Children(ii).UserData==1)
                        app.TabEixos.Children(ii).FaceColor=app.BGcol;
                    else
                        app.TabEixos.Children(ii).FaceColor=app.BGcol2;
                    end
                end
                if(strcmp(class(app.TabEixos.Children(ii)),'matlab.graphics.primitive.Text'))
                    app.TabEixos.Children(ii).Color=app.FGcol;
                end
                if(strcmp(class(app.TabEixos.Children(ii)),'matlab.graphics.chart.primitive.Line'))
                    app.TabEixos.Children(ii).Color=[.5 .5 .5];
                end
            end
        end


        function [] = singleColPush(app,~,~,q)
            h=app.singhans(q);
            pos=app.GSLabUIFigure.Position;
            tam=[200 200];
            % pos=pos(1:2)+(pos(3:4)-tam)/2;
            pos(2)=pos(2)+sum(app.ComponentsPanel.Position([2,4]));
            pos(3:4)=tam;
            try
                nou=colorPicker2(1,pos);
                app.single(q)=nou;
                nou=superjet(1,nou);
                set(h,'BackgroundColor',nou);
            end
            if(q==2)
                CmapMagDDValueChanged(app);
            else
                CmapPhdensDDValueChanged(app);
            end

        end

        function [] = triggercallbacks(app)
            CmapPhdensDDValueChanged(app);
            CmapMagDDValueChanged(app);
            ExportratiometricValueChanged(app);
            CompImsExpValueChanged(app);
            singleimageValueChanged(app);
            groupfileValueChanged(app);
            imagewindowtileValueChanged(app);
            CompImsCSVExpValueChanged(app);
            StyleSelectionChanged(app);
            CombineValueChanged(app);
            CalibrationtypeSelectionChanged(app);
            MagnitudeButtonGroupSelectionChanged(app);
            GMMexportValueChanged(app);
            DoCalibValueChanged(app);
            FilterAndPlotPhasor(app);
            plotHists(app);
            setcolors(app);
            SourceButtonGroupSelectionChanged(app);
        end

        function [] = tabselector(app,~,~,qq)
            if(app.CalibHolder.Visible==1)
                hidecalibButtonPushed(app);
            end
            app.TabGroup.SelectedTab=app.TabGroup.Children(qq);
            cc=0;

            for ii=numel(app.TabEixos.Children):-1:1
                if(strcmp(class(app.TabEixos.Children(ii)),'matlab.graphics.primitive.Rectangle'))
                    app.TabEixos.Children(ii).UserData=0;
                    cc=cc+1;
                    if(cc==qq)
                        app.TabEixos.Children(ii).FaceColor=app.BGcol;
                        app.TabEixos.Children(ii).UserData=1;
                    else
                        app.TabEixos.Children(ii).FaceColor=app.BGcol2;
                    end
                end
            end
        end

        function [] = saveParams(app,ruta)
            if(nargin<2),ruta='params3.mat';end
            po=app.GSLabUIFigure.Position;
            p01=po(1:2);
            if(ishandle(app.IWh))
                app.figsz=get(app.IWh,'Position');
                p02=app.figsz(1:2);
            else
                p02=[0 0];
            end
            p03=app.Eb.Value;
            p04=app.Ti.Value;
            p05=app.Imin.Value;
            p06=app.Imax.Value;
            p07=app.Smin.Value;
            p08=app.Smax.Value;
            p09=app.Gmin.Value;
            p10=app.Gmax.Value;
            p11=app.Cmin.Value;
            p12=app.Cmax.Value;
            p13=app.folderC;
            p14=app.folderD;
            p15=app.LT.Value;
            p16=app.DoCalib.Value;
            p17=app.Cbar.Value;
            p18=app.Harm.Value;
            p19=app.HarmLim;
            p20=app.Filterbefore.Value;
            p21=app.phaS.Value;
            p22=app.modS.Value;

            r01=app.folderR;
            r02=app.ExDecay.Value;
            r03=app.DeacayFileDropDown.Value;
            r04=app.Combine.Value;
            r05=app.ExPhasor.Value;
            r07=app.ExInt.Value;
            r06=app.ExMagH.Value;
            r08=app.PhBg.Value;
            r09=app.ExMagS.Value;
            r10=app.PhRb.Value;
            r11=app.blend.Value;
            r12=app.PhRb.Value;
            r13=app.ExCurH.Value;
            r14=app.ExCurS.Value;
            r15=app.Expptrans.Value;
            r16=app.Expvec.Value;
            r17=app.Expwithax.Value;
            r18=app.ExportPhasorFileDropDown.Value;
            r19=app.ExportImageFileDropDown.Value;
            r20=app.groupfiles.Value;
            r21=app.ExMagV.Value;
            r22=app.MagValFmtDropDown.Value;

            m01=app.Srad.Value;
            m02=app.Grad.Value;
            m03=app.Phaserad.Value;
            m04=app.TauPhaserad.Value;
            m05=app.Modrad.Value;
            m06=app.TauModrad.Value;
            m07=app.NormPhaserad.Value;
            m08=app.TauNormrad.Value;
            m09=app.CustomRadial.Value;
            m10=app.CustomAngular.Value;

            v01=app.AFSef.Value;
            v02=app.UCcb.Value;
            v03=app.ticks.Value;
            v04=app.tickfontsize.Value;
            if(app.LifetimeStyle.Value==1),v05=0;end
            if(app.SpectralStyle.Value==1),v05=1;end
            if(app.UmapStyle.Value==1),v05=2;end
            v06=app.CCM;
            v07=app.freqEF.Value*1e6;
            v08=app.PhasorcolormapDD.ValueIndex;
            v09=app.RainbowcolormapDD.ValueIndex;
            v10=app.SoftCursor.Value;
            v11=app.logDens.Value;
            v12=app.normFile.Value;
            v13=app.contours.Value;
            v14=app.margns.Value;
            v15=app.imagewindowtile.Value;
            v16=app.imagecols.Value;
            v17=app.imagerows.Value;
            v18=app.SpecMin.Value;
            v19=app.SpecMax.Value;

            c01=app.complif;
            c02=app.colcomps;
            c03=app.ShowComponents.Value;
            c04=app.AddLines.Value;
            c05=app.CompImsExp.Value;
            c06=app.CompImsIntExp.Value;
            c07=app.CompImsCSVExp.Value;
            c08=app.CompImsCol.Value;
            c09=app.exportratiometric.Value;
            c10=app.comp1.Value;
            c11=app.comp2.Value;
            c12=app.ratiocsv.Value;
            c13=app.singleimage.Value;
            c14=app.currcol;
            c15=app.DM;
            c16=app.addmargins.Value;
            c17=app.includeint.Value;
            c18=app.groupfile.Value;
            c19=app.mergecomps.Value;

            if(app.conttogether.Value==1),u01=0;end
            if(app.contseparate.Value==1),u01=1;end
            if(app.elipse.Value==1),u01=2;end
            if(app.hard.Value==1),u02=0;end
            if(app.prob.Value==1),u02=1;end
            u03=app.conf.Value;
            u04=app.blend.Value;
            u05=app.blendamount.Value;
            u06=app.gmmexport.Value;

            s00=app.Suman.Value;

            files=app.nms;
            paths=app.pat;

            save(ruta,'files','paths','p01','p02','p03','p04','p05','p06','p07','p08','p09','p10','p11','p12','p13','p14','p15','p16','p17','p18','p19','p20','p21','p22','r01','r02','r03','r04','r05','r06','r07','r08','r09','r10','r11','r12','r13','r14','r15','r16','r17','r18','r19','r20','r21','r22','m01','m02','m03','m04','m05','m06','m07','m08','m09','m10','v01','v02','v03','v04','v05','v06','v07','v08','v09','v10','v11','v12','v13','v14','v15','v16','v17','v18','v19','c01','c02','c03','c04','c05','c06','c07','c08','c09','c10','c11','c12','c13','c14','c15','c16','c17','c18','c19','s00','u01','u02','u03','u04','u05','u06');

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % addpath('scripts');
            app.ALLcols=superjet(32,'wvAbZceglyoarFmpwvAbZceglyoarFmp');
            app.CalibHolder.Position=[app.TabGroup.Position(1:2)+app.Tree.Position(1:2) app.Tree.Position(3) app.CalibHolder.Position(4)];% achtung fixed pos, not changing with design mode!
            app.CalibrationPanel.Position(3:4)=app.CalibHolder.Position(3:4)-[6 29];
            app.CalibrationPanel.Position(1:2)=[3 3];
            % app.FiLab.Position=[1 1 236 app.CalibrationPanel.Position(4)-2];
            app.calTable.Position=[1 1 app.CalibrationPanel.Position(3)-2 app.CalibrationPanel.Position(4)-2];
            app.manualcalibpanel.Position=app.calTable.Position;
            app.manualcalibpanel.Title='';
            app.coolab.Interpreter='latex';

            % load user settings file
            applyUserSettings(app);
            % if(~strcmp(whosPC,'Belen'))
            % aux=['6666226662667223';'CF1487414ED1479B'];
            % eval(char(hex2dec(aux')'));
            % try, eval(fra);end
            % end

            try
                app.ReLab.Text=app.folderR(find(app.folderR==filesep,1,'last')+1:end);
            end
            %% make component Tree window
            aux=app.ComponentsPanel.Position;
            app.ComponentTree.Position=[aux(1:2) aux(3) aux(4)-19];
            app.ComponentTree.Visible=0;

            %% prepare tab overlay to have better control
            ref=app.TabGroup.Position;
            aux=25;% tab height
            app.AxisContainer.Position=[ref(1) ref(2)+ref(4)-aux-1 ref(3) aux+1];
            app.TabEixos.Position=[0 0 app.AxisContainer.Position(3) app.AxisContainer.Position(4)];
            app.TabEixos.OuterPosition=app.TabEixos.Position;
            app.TabEixos.InnerPosition=app.TabEixos.Position;
            app.TabEixos.XAxisLocation='top';
            nn=numel(app.TabGroup.Children);
            chars=zeros(nn,1);
            for ii=1:nn
                chars(ii)=numel(app.TabGroup.Children(ii).Title);
            end
            for ii=1:nn
                pp=[sum(chars(1:ii-1))*ref(3)/sum(chars) 0 chars(ii)*ref(3)/sum(chars) aux];
                if(ii==1)
                    col= [0.94,0.94,0.94];
                else
                    col=.8*[0.94,0.94,0.94];
                end
                ud=0;if(ii==1),ud=1;end
                h = rectangle(app.TabEixos,'Position',pp,'EdgeColorMode','manual','UserData',ud, ...
                    'FaceColor',col, 'EdgeColor', 'none','LineWidth',.001,'ButtonDownFcn',{@app.tabselector,ii});
                t = text(app.TabEixos,pp(1)+pp(3)/2,pp(2)+pp(4)/2,app.TabGroup.Children(ii).Title,'verticalalignment','middle','HorizontalAlignment','center','FontSize',12,'ButtonDownFcn',{@app.tabselector,ii});
            end
            hold(app.TabEixos,'on');
            h=plot(app.TabEixos,[1 ref(3)],[aux aux]);set(h,'color','k','linewidth',1,'UserData',0);
            h=plot(app.TabEixos,[1 1],[0 aux]);set(h,'color','k','linewidth',1,'UserData',0);
            h=plot(app.TabEixos,[ref(3) ref(3)],[0 aux]);set(h,'color','k','linewidth',1,'UserData',1);
            app.TabEixos.XLim=[0 ref(3)];
            app.TabEixos.YLim=[0 aux];


            %% set axes sizes (histogram, masks)
            app.histax.InnerPosition([1,3])=app.Islide.Position([1,3]);
            app.MKax.InnerPosition(3)=app.MKax.InnerPosition(4);
            axis(app.MKax,'image');
            set(app.MKax,'Xcolor',[.94 .94 .94],'Ycolor',[.94 .94 .94]);
            app.CalibHolder.Visible=0;

            app.Harm.Limits(2)=app.HarmLim;
            % prepare mask skin for display
            % [x,y]=meshgrid([1:app.dwnsmp],[1:app.dwnsmp]);
            % app.MKoutim=sin(6.28*20*(x+y*.7)/app.dwnsmp)>0;
            % figure(44);imagesc(app.MKoutim>0);

            app.colMks=superjet(numel(app.CCM),app.CCM);% cursor colors
            app.colMks=[app.colMks;0 0 0];% add black to assign gmm nans
            app.roidad=zeros(numel(app.CCM),3);% cursor data
            %% generate cursor color buttons
            ref=app.CursorcolorsPanel.Position;
            sep=3;
            tam=round([(ref(3)-sep*(1+numel(app.CCM)))/numel(app.CCM) (ref(4)-2*sep)/2]);
            tam=tam(1);
            tam=12;
            % if(tam(2)>tam(1)),tam(2)=tam(1);end
            for ii=1:numel(app.CCM)
                h = uibutton(app.CursorcolorsPanel);
                h.Position = [(sep+tam)*(ii-1)+sep sep tam tam];
                h.Text = '';
                h.BackgroundColor= app.colMks(ii,:);
                h.Tooltip=['Cursor ' num2str(ii)];
                h.ButtonPushedFcn = {@app.CursorColPush, ii};
                app.curcolbuthan(ii)=h;
            end
            %% generate component color buttons
            ref=app.plusB.Position;

            for ii=1:8% % component color button
                h = uibutton(app.UnmixTab);
                h.Position = [ref(1) round(ref(2)+6.9-(ii-1)*(20.5714)) tam tam];
                h.Text = '';
                h.BackgroundColor= superjet(1,app.colcomps(ii));
                h.Tooltip=['Component' num2str(ii) ' color'];
                h.ButtonPushedFcn = {@app.ComponentColPush, ii};
                h.Visible=0;
                app.comphans(ii,2)=h;
            end
            %% prepare colormap drop down density
            [llista] = cmaps();
            llista{end+1,1}='Single Color';
            llista{end,2}='dummy';
            for ii=1:size(llista,1)% phasor density
                nom=llista{ii,1};
                if(numel(unique(llista{ii,2}))==1),nom=[nom ' (single color)'];end
                app.PhasorcolormapDD.Items{ii}=nom;
                aux=llista{ii,2};if(aux(1)~='w'),aux=['w' aux];end% make first color white to add transparency in alpha
                app.PhasorcolormapDD.ItemsData{ii}=aux;
            end
            ref=app.PhasorcolormapDD.Position;
            h = uibutton(app.DisplayTab);
            h.Position = [ref(1)+ref(3)+3 ref(2)+6 tam tam];
            h.Text = '';
            h.BackgroundColor= superjet(1,app.single(1));
            h.Tooltip=['Single Color'];
            h.ButtonPushedFcn = {@app.singleColPush, 1};
            app.singhans(1)=h;
            %% prepare colormap drop down magnitude
            for ii=1:size(llista,1)% background color
                nom=llista{ii,1};
                if(numel(unique(llista{ii,2}))==1),nom=[nom ' (single color)'];end
                app.RainbowcolormapDD.Items{ii}=nom;
                aux=llista{ii,2};
                aux(aux=='k')=[];% remove black
                if(aux(1)~='w'),aux=['w' aux];end% make smooth transitions to white
                if(aux(end)~='w'),aux=[aux 'w'];end% make smooth transitions to white
                app.RainbowcolormapDD.ItemsData{ii}=aux;
            end
            ref=app.RainbowcolormapDD.Position;
            h = uibutton(app.DisplayTab);
            h.Position = [ref(1)+ref(3)+3 ref(2)+6 tam tam];
            h.Text = '';
            h.BackgroundColor= superjet(1,app.single(2));
            h.Tooltip=['Single Color'];
            h.ButtonPushedFcn = {@app.singleColPush, 2};
            app.singhans(2)=h;
            try
                load('params3.mat','v08','v09');
                app.PhasorcolormapDD.ValueIndex=v08;
                app.RainbowcolormapDD.ValueIndex=v09;

            end
            %% table for calibration
            % app.calTable.Visible=0;
            % if(strcmp(whosPC,'Alex'))
            app.calTable.Visible=1;
            app.calTable.ColumnName=[];
            aux=cell(0);aux{3,3}=' ';aux{1,3}='No Calibration selected';%aux{1,4}='';
            app.calTable.Data=aux;
            app.calTable.ColumnWidth={33,33,'auto'};%,22};
            s1 = uistyle;
            s1.HorizontalAlignment='center';
            addStyle(app.calTable,s1,'column',2);
            s1.HorizontalAlignment='right';
            addStyle(app.calTable,s1,'column',1);
            s1.HorizontalAlignment='left';
            addStyle(app.calTable,s1,'column',3);
            % end
            %% call functions to set stuff
            triggercallbacks(app);
            % if(strcmp(whosPC,'Suman'))
            app.Suman.Value=1;
            % end

            % overlay spectral band on laser freq
            aux1=app.SpecMin.Position(1)-app.sBandLab.Position(1);
            aux2=app.SpecMax.Position(1)-app.sBandLab.Position(1);
            app.sBandLab.Position(1:2)=app.freqLab.Position(1:2);
            app.SpecMin.Position(1)=app.sBandLab.Position(1)+aux1;
            app.SpecMax.Position(1)=app.sBandLab.Position(1)+aux2;
            app.SpecMin.Position(2)=app.freqEF.Position(2);
            app.SpecMax.Position(2)=app.freqEF.Position(2);
        end

        % Value changed function: Applymask
        function ApplymaskValueChanged(app, event)
            app.ApplyImageMasks=app.Applymask.Value;

            FilterAndPlotPhasor(app);

        end

        % Button pushed function: BrowseResults
        function BrowseResultsButtonPushed(app, event)
            aux = uigetDirs('Select folder to save results',1);
            if(~isempty(aux))
                app.folderR=aux;
                app.ReLab.Text=app.folderR(find(app.folderR==filesep,1,'last')+1:end);
            end
        end

        % Value changed function: Smin
        function CoordSminChanged(app, event)
            FilterAndPlotPhasor(app);
            % replotPP(app);
        end

        % Value changed function: Smax
        function CoordSmaxChanged(app, event)
            FilterAndPlotPhasor(app);
            % replotPP(app);
        end

        % Value changed function: Gmin
        function CoordGminChanged(app, event)
            FilterAndPlotPhasor(app);
            % replotPP(app);
        end

        % Value changed function: Gmax
        function CoordGmaxChanged(app, event)
            FilterAndPlotPhasor(app);
            % replotPP(app);
        end

        % Value changed function: Cmin
        function CoordCminChanged(app, event)
            FilterAndPlotPhasor(app);
        end

        % Value changed function: Cmax
        function CoordCmaxChanged(app, event)
            FilterAndPlotPhasor(app);
        end

        % Value changed function: Gcenter
        function CoordGcenterChanged(app, event)
            FilterAndPlotPhasor(app);
        end

        % Value changed function: Scenter
        function CoordScenterChanged(app, event)
            FilterAndPlotPhasor(app);
        end

        % Button pushed function: ComputeHR
        function ComputeHRButtonPushed(app, event)

            phasorUnmixing(app);
        end

        % Value changed function: CmapFlipDens
        function CmapFlipDensValueChanged(app, event)
            % value = app.CmapFlipDens.Value;
            ind=app.PhasorcolormapDD.ValueIndex;
            app.PhasorcolormapDD.ItemsData{ind}=['w' app.PhasorcolormapDD.ItemsData{ind}(end:-1:2)];
            app.PhasorcolormapDD.ValueIndex=ind;
             CmapPhdensDDValueChanged(app);
        end

        % Value changed function: CmapFlipMag
        function CmapFlipMagValueChanged(app, event)
            %value = app.CmapFlipMag.Value;
            ind=app.RainbowcolormapDD.ValueIndex;
            app.RainbowcolormapDD.ItemsData{ind}=app.RainbowcolormapDD.ItemsData{ind}(end:-1:1);
            app.RainbowcolormapDD.ValueIndex=ind;
             CmapMagDDValueChanged(app);
        end

        % Value changed function: PhasorcolormapDD
        function CmapPhdensDDValueChanged(app, event)
            value = app.PhasorcolormapDD.Value;
            if(app.PhasorcolormapDD.ValueIndex==numel(app.PhasorcolormapDD.Items))
                set(app.singhans(1),'Enable',1);
                value=['w' repmat(app.single(1),[1 4])];% create single color map
            else
                set(app.singhans(1),'Enable',0);
            end
            app.PCM=value;% phasor colormap
            replotPP(app);
        end

        % Value changed function: RainbowcolormapDD
        function CmapMagDDValueChanged(app, event)
            value = app.RainbowcolormapDD.Value;
            if(app.RainbowcolormapDD.ValueIndex==numel(app.RainbowcolormapDD.Items))
                set(app.singhans(2),'Enable',1);
                value=['w' repmat(app.single(2),[1 4]) 'w'];% create single color map
            else
                set(app.singhans(2),'Enable',0);
            end
            app.RCM=value;% rainbow colormap
            FilterAndPlotPhasor(app);
        end

        % Button pushed function: ChanButton3
        function ChanButton3Pushed(app, event)
            % see what nodes are checked
            if(isempty(app.folderR))
                BrowseResultsButtonPushed(app);
            end
            app.running=1;
            d=barraprogress(app,0,[],'Please wait...');
            rang=checkedFiles(app);
            encode=cell(numel(rang),5);cc=0;
            for ii=rang
                cc=cc+1;
                encode{cc,2}=dec2hex(round(rand()*1e4));% give random in case fail
                try
                    aux=app.nms{ii};
                    encode{cc,1}=aux;
                    tros=[find(aux=='_',1,'first') find(aux=='_',1,'last')];
                    encode{cc,2}=aux(tros(1)+1:tros(2)-1);
                    encode{cc,3}=ii;
                    encode{cc,4}=aux(tros(2)+1:end);
                    encode{cc,5}='';
                    try
                    encode{cc,5}=app.nmsx{ii}(1:end-3);
                    end
                end
            end
            qv=[app.Imin.Value app.Imax.Value];
            for ii=1:cc
                for jj=ii+1:cc
                    if(strcmp(encode{ii,2},encode{jj,2})==1)% common part matches
                        if(strcmp(encode{ii,4}(1:3),encode{ii,4}(1:3))==1)% theyre both same number of bins (to include later 64)
                            check11=strcmp(encode{ii,4}(end),encode{jj,4}(end))==0;% theyre different analog imput
                            check12=strcmp(app.nmsx{encode{ii,3}},app.nmsx{encode{jj,3}});% same extra tag
                            check13=isempty(app.nmsx{encode{ii,3}})&&isempty(app.nmsx{encode{jj,3}});% extra tags are empty
                            check1=check11&&(check12||check13);
                            check21=strcmp(encode{ii,1}(end-1:end),'Sp')&&strcmp(encode{jj,1}(end-1:end),'Sp');% both split files
                            check22=(ii+1==jj);% theyre consecutive
                            check2=check21&&check22;
                            if(check2)% if split still gotta check the start of extra tag coincides
                                check2=strcmp(app.nmsx{encode{ii,3}}(1:end-1),app.nmsx{encode{jj,3}}(1:end-1));
                            end
                            if(check1||check2)
                                % disp([num2str(ii) '-' num2str(jj)]);
                                % if(0)
                                if(strcmp(encode{ii,4}(end),'1')||strcmp(encode{ii,4}(end),'p'))
                                    Isplit=cat(3,app.IIo{encode{ii,3}},app.IIo{encode{jj,3}});    % disp('y');
                                else
                                    Isplit=cat(3,app.IIo{encode{jj,3}},app.IIo{encode{ii,3}});
                                end
                                % Isplit=imgaussfilt(Isplit,1);
                                qvi=qv;
                                if(app.relative.Value==0)
                                    qvi=qv/max(max(sum(Isplit,3)));
                                end
                                qvi=[qvi(1)*.5 1-.5*(1-qvi(2))];
                                if(qvi(2)>1)
                                    qvi(2)=1;
                                end
                                if(nargin==2)% 3Ch
                                    [I3] = split23ch(Isplit,qvi);
                                    nom=[app.folderR filesep encode{ii,2} encode{ii,5} '_3Ch_Rec'];
                                else
                                    [I3] = split24ch(Isplit,qvi);
                                    nom=[app.folderR filesep encode{ii,2} encode{ii,5} '_4Ch_Rec'];
                                end
                                % I3=imgaussfilt(I3,1);

                                ex=niceDigits(1+numel(dir([nom '*'])));
                                nom=[nom ex '.png'];
                                imwrite(I3,nom);
                                % end
                            end
                        end
                    end
                end

                d=barraprogress(app,ii/cc,d,'Please wait...');
            end
            d=barraprogress(app,1,d,'Please wait...');
            pause(.3);
            try(close(d));end
            app.running=0;
            % aux=memory;aux=niceDigits(100*aux.MemUsedMATLAB/aux.MaxPossibleArrayBytes,2);
            % disp([aux '%']);
        end

        % Button pushed function: ChanButton4
        function ChanButton4Pushed(app, event)
            ChanButton3Pushed(app);
        end

        % Cell edit callback: calTable
        function CalibTableCellEdit(app, event)
            indices = event.Indices;
            newTag = event.NewData;
            aux=0;
            for ii=1:numel(app.multical)
                if(~isempty(app.multical{ii}))
                    aux=aux+1;
                    if(aux==indices(1))
                        break;
                    end
                end
            end
            edita=ii;
            if(indices(2)==1)% changed tag

                N=numel(app.calibtags);
                N=N+1;
                app.PD(N,:)=app.PD(edita,:);app.PD(edita,:)=0;
                app.MF(N,:)=app.MF(edita,:);app.MF(edita,:)=1;
                app.multical{N}=app.multical{edita};app.multical{edita}=[];
                app.multilif(N)=app.multilif(edita);app.multilif(edita)=0;
                app.calibtags{N}=newTag;

                for ii=1:app.LoadedIms
                    if(contains(app.nms{ii},newTag))
                        app.calibflag(ii)=N;
                    end
                end
            end
            if(indices(2)==2)% changed lifetime
                newval=str2num(newTag);
                % disp([num2str(app.multilif(edita)) ' to ' num2str(newval)]);
                oldval=app.multilif(edita);
                app.multilif(edita)=newval;
                %%
                for hh=1:app.HarmLim
                    [so,go]=expectedPhasorPosition(oldval,app.freq,hh);
                    [sn,gn]=expectedPhasorPosition(newval,app.freq,hh);
                    phad=atan2(sn,gn)-atan2(so,go);
                    modf=sqrt((sn.*sn)+(gn.*gn))/sqrt((so.*so)+(go.*go));
                    app.PD(edita,hh)=app.PD(edita,hh)+phad;
                    app.MF(edita,hh)=app.MF(edita,hh)*modf;
                end
                %%
            end
            DoCalibValueChanged(app);
        end

        % Selection changed function: Calibrationtype
        function CalibrationtypeSelectionChanged(app, event)
            if(strcmp(app.Calibrationtype.SelectedObject.Text,'Files'))
                % app.modS.Enable=0;
                % app.phaS.Enable=0;
                % app.modL.Enable=0;
                % app.phaL.Enable=0;
                % app.calTable.Enable='on';
                app.manualcalibpanel.Visible=0;
                app.LT.Enable=1;
                app.CalibrationLifetimensLabel.Enable=1;
            else
                app.manualcalibpanel.Visible=1;
                % app.modS.Enable=1;
                % app.phaS.Enable=1;
                % app.modL.Enable=1;
                % app.phaL.Enable=1;
                % app.calTable.Enable='off';
                app.LT.Enable=0;
                app.CalibrationLifetimensLabel.Enable=0;
            end
            if(app.DoCalib.Value==1)
                DoCalibValueChanged(app);
            end
        end

        % Menu selected function: FLAMEcalibration
        function CalibrationLoadMenuSelected(app, event)
            aux = uigetDirs('Select folder containing reference sample',1);
            if(~isempty(aux))
                app.folderC=aux;
                LoadCalibrationFile(app);
                if(app.CalibHolder.Visible==0)
                    if(app.TabGroup.SelectedTab==app.TabGroup.Children(1))
                        hidecalibButtonPushed(app);
                    end
                end
            end
        end

        % Value changed function: LT
        function CalibLifetimeValueChanged(app, event)
            Ng=9;quin=0;
            for ii=1:app.LoadedIms
                if(strcmp(app.nms{ii},app.multical{Ng}))
                    quin=ii;
                    app.Tree.SelectedNodes=app.Tree.Children(quin);
                    break
                end
            end
            if(quin>0)

                CalibrationFileMenuSelected(app)

            end
        end

        % Button pushed function: ClearButton
        function CalibClearButtonPushed(app, event)
            calibrationRemove(app);
            app.multilif(:)=0;
            % app.calibflag(:)=0;
            for ii=1:numel(app.multical)
                app.multical{ii}=[];
            end
            app.PD(:)=0;
            app.MF(:)=1;
            calibrationDisplayUpdate(app);
            DoCalibValueChanged(app);
        end

        % Menu selected function: CalibrationFileMenu
        function CalibrationFileMenuSelected(app, event)
            if(isempty(app.Tree.SelectedNodes))
                errordlg('Select a file to use for calibration!');
            else
                if(app.CalibHolder.Visible==0)
                    hidecalibButtonPushed(app);
                end

                quin=app.Tree.SelectedNodes.NodeData ;
                d=barraprogress(app,0,[],'Calibration');
                Ng=9;
                for hh=1:app.HarmLim
                    S1=app.SSo{quin,hh};
                    G1=app.GGo{quin,hh};
                    II=sum(app.IIo{quin},3);
                    [s,g]=expectedPhasorPosition(app.LT.Value,app.freq,hh);
                    S1=S1(:);G1=G1(:);II=II(:);
                    out=find(II==0);
                    S1(out)=[];G1(out)=[];II(out)=[];
                    S1=S1'*II/sum(II);G1=G1'*II/sum(II);% photon wieghted phasor coords
                    aux=atan2(S1,G1);
                    app.PD(Ng,hh)=atan2(s,g)-aux;
                    aux=sqrt((S1*S1)+(G1*G1));
                    app.MF(Ng,hh)=sqrt((s*s)+(g*g))/aux;

                    d=barraprogress(app,hh/app.HarmLim,d,'Loading calibration');
                end

                app.multical{Ng}=app.nms{quin};
                app.multilif(Ng)=app.LT.Value;

                for ii=1:app.LoadedIms
                    if(app.calibflag(ii)==0)
                        app.calibflag(ii)=Ng;
                    end
                end


                % calibrationDisplayUpdate(app);% already inside applyCalibration!
                calibrationApply(app);
                FilterAndPlotPhasor(app);
            end

            try(close(d));end


        end

        % Menu selected function: AllNoneMenu
        function CheckedAllNoneMenuSelected(app, event)
            rang=checkedFiles(app);
            if(numel(rang)>numel(app.Tree.Children)/2)% more checked
                app.Tree.CheckedNodes=[];% uncheck all
                rang=[];
            else
                rang=1:numel(app.Tree.Children);
                for ii=rang
                    app.Tree.CheckedNodes=[app.Tree.CheckedNodes;app.Tree.Children(ii)];
                end
                bk=0;
                for ii=rang
                    aux=app.currPP(:,:,ii);
                    if(numel(unique(aux(:)))==1)
                        bk=1;break
                    end
                end
                if(bk==1)
                    FilterAndPlotPhasor(app);
                end
            end

            replotPP(app);
            replotII(app);
            plotHists(app);
        end

        % Menu selected function: InvertMenu
        function CheckedInvertMenuSelected(app, event)
            rang=checkedFiles(app);
            norang=setdiff([1:numel(app.Tree.Children)],rang);
            app.Tree.CheckedNodes=[];
            for ii=norang
                app.Tree.CheckedNodes=[app.Tree.CheckedNodes;app.Tree.Children(ii)];
            end
            rang=norang;
            bk=0;
            for ii=rang
                aux=app.currPP(:,:,ii);
                if(numel(unique(aux(:)))==1)
                    bk=1;break
                end
            end
            if(bk==1)
                FilterAndPlotPhasor(app);
            end
            replotPP(app);
            replotII(app);
            plotHists(app);
        end

        % Menu selected function: AlternateMenu
        function CheckAlternateMenuSelected(app, event)
            if(~isempty(app.Tree.CheckedNodes))
                if(mod(app.Tree.CheckedNodes(1).NodeData,2)==0)
                    start=1;
                else
                    start=2;
                end
            end
            app.Tree.CheckedNodes=[];% uncheck all

            rang=start:2:numel(app.Tree.Children);
            for ii=rang
                app.Tree.CheckedNodes=[app.Tree.CheckedNodes;app.Tree.Children(ii)];
            end
            bk=0;
            for ii=rang
                aux=app.currPP(:,:,ii);
                if(numel(unique(aux(:)))==1)
                    bk=1;break
                end
            end
            if(bk==1)
                FilterAndPlotPhasor(app);
            end
            replotPP(app);
            replotII(app);
            plotHists(app);
        end

        % Menu selected function: AllMenu
        function ClearAllContextSelected(app, event)
            app.IIo=cell(0);
            app.hdrkeep=cell(0);
            app.SSo=cell(0);
            app.GGo=cell(0);
            app.IIa=cell(0);
            app.SSa=cell(0);
            app.GGa=cell(0);
            app.IIi=cell(0);
            app.SSi=cell(0);
            app.GGi=cell(0);
            app.dda=cell(0);
            app.nms=cell(0);
            app.nmsx=cell(0);
            app.pat=cell(0);
            app.calibflag=[];
            app.LoadedIms=0;
            app.Imaxrange=1;
            delete(app.Tree.Children);
            replotPP(app);
            replotII(app);
            cla(app.histax);
            delete(app.ComponentTree.Children);
            CalibClearButtonPushed(app);
            try
                close(app.IWh);
            end
        end

        % Menu selected function: UncheckedMenu
        function ClearUncheckedMenuSelected(app, event)
            rang=checkedFiles(app);

            for ii=1:numel(rang)
                app.IIo{ii}=app.IIo{rang(ii)};
                app.hdrkeep{ii}=app.hdrkeep{rang(ii)};
                app.IIa{ii}=app.IIa{rang(ii)};
                for hh=1:app.HarmLim
                    app.SSo{ii,hh}=app.SSo{rang(ii),hh};
                    app.GGo{ii,hh}=app.GGo{rang(ii),hh};
                    app.SSa{ii,hh}=app.SSa{rang(ii),hh};
                    app.GGa{ii,hh}=app.GGa{rang(ii),hh};
                end
                app.IIi{ii}=app.IIi{rang(ii)};
                app.SSi{ii}=app.SSi{rang(ii)};
                app.GGi{ii}=app.GGi{rang(ii)};
                app.MK{ii}=app.MK{rang(ii)};
                app.dda{ii}=app.dda{rang(ii)};
                app.nms{ii}=app.nms{rang(ii)};
                app.nmsx{ii}=app.nmsx{rang(ii)};
                app.pat{ii}=app.pat{rang(ii)};
                app.calibflag(ii)=app.calibflag(rang(ii));
                app.currPP(:,:,ii)=app.currPP(:,:,rang(ii));
            end
            app.LoadedIms=numel(rang);
            app.IIo(app.LoadedIms+1:end)=[];
            app.hdrkeep(app.LoadedIms+1:end)=[];
            app.SSo(app.LoadedIms+1:end,:)=[];
            app.GGo(app.LoadedIms+1:end,:)=[];
            app.IIa(app.LoadedIms+1:end)=[];
            app.SSa(app.LoadedIms+1:end,:)=[];
            app.GGa(app.LoadedIms+1:end,:)=[];
            app.IIi(app.LoadedIms+1:end)=[];
            app.SSi(app.LoadedIms+1:end)=[];
            app.GGi(app.LoadedIms+1:end)=[];
            app.MK(app.LoadedIms+1:end)=[];
            app.dda(app.LoadedIms+1:end)=[];
            app.nms(app.LoadedIms+1:end)=[];
            app.nmsx(app.LoadedIms+1:end)=[];
            app.pat(app.LoadedIms+1:end)=[];
            app.calibflag(app.LoadedIms+1:end)=[];
            norang=setdiff(1:numel(app.Tree.Children),rang);
            norang=sort(norang,'descend');
            for ii=norang
                delete(app.Tree.Children(ii));
            end
            for ii=1:numel(app.Tree.Children)
                app.Tree.Children(ii).NodeData=ii;
            end
            app.Tree.CheckedNodes=[];% uncheck all
            CheckedInvertMenuSelected(app);%check all

            replotPP(app);
            try
                close(app.IWh);
            end
            if(app.LoadedIms>0)
                replotII(app);
                plotHists(app);
            end
        end

        % Button pushed function: ComputeLR
        function ComputeLRButtonPushed(app, event)
            app.exportHR=0;
            phasorUnmixing(app);
            app.exportHR=1;
        end

        % Value changed function: Combine
        function CombineValueChanged(app, event)
            if(app.Combine.Value==0)
                % app.Expptrans.Enable=1;
                app.groupfiles.Enable=0;
                app.groupfiles.Value=0;
            else
                app.Expvec.Value=0;
                app.groupfiles.Enable=1;
                app.Expptrans.Value=0;
                if(app.ExPhasor.Value==1)
                    app.Expptrans.Value=0;
                    app.ExportPhasorFileDropDown.Value=app.ExportImageFileDropDown.Value;
                end
            end
        end

        % Value changed function: AddLines
        function ComponentLinesValueChanged(app, event)
            replotPP(app);
        end

        % Button pushed function: minusB
        function ComponentMinusButtonPressed(app, event)
            componentRemoveTheo(app);
        end

        % Callback function: ComponentTree
        function ComponentTreeCheckedNodesChanged(app, event)
            componentTreeColors(app);
            componentGetEmpiricCoords(app);
            if(app.FileButton.Value==1)
                app.compNum=app.compNumE;
            else
                app.compNum=numel(find(app.roidad(:,3)>0));
                if(app.compNum>3),app.compNum=3;end
                componentLifeChange(app,[],[],[1:app.compNum]);
            end
            replotPP(app);
        end

        % Button pushed function: plusB
        function ComponentPlusButtonPressed(app, event)
            componentAddTheo(app);
        end

        % Value changed function: CompImsExp
        function CompImsExpValueChanged(app, event)
            if(app.CompImsExp.Value==1)
                app.CompImsIntExp.Enable=1;
                app.CompImsCol.Enable=1;
                app.singleimage.Enable=1;
                app.addmargins.Enable=1;
                app.groupfile.Enable=1;
                app.mergecomps.Enable=1;
            else
                app.CompImsIntExp.Enable=0;
                app.CompImsCol.Enable=0;
                app.singleimage.Enable=0;

                app.addmargins.Enable=0;
                app.groupfile.Enable=0;
                app.mergecomps.Enable=0;
            end
            singleimageValueChanged(app);
        end

        % Value changed function: ratiocsv
        function CompratiocsvValueChanged(app, event)

            if(app.ratiocsv.Value==1)
                app.includeint.Enable=1;
            else
                app.includeint.Enable=0;
            end
        end

        % Value changed function: CompImsCSVExp
        function CompImsCSVExpValueChanged(app, event)

            if(app.CompImsCSVExp.Value==1)
                app.includeint.Enable=1;
            else
                app.includeint.Enable=0;
            end
        end

        % Value changed function: fliprows
        function DisplayfliprowsValueChanged(app, event)
            try,close(app.IWh);end
            replotII(app);
        end

        % Value changed function: margns
        function DisplayMargnsValueChanged(app, event)
            try,close(app.IWh);end
            replotII(app);
        end

        % Value changed function: contours
        function DispContoursValueChanged(app, event)
            replotPP(app);

        end

        % Value changed function: DoCalib
        function DoCalibValueChanged(app, event)

            if(app.DoCalib.Value==0)
                app.CalibrationPanel.Enable=0;
                app.Calibrationtype.Enable=0;
                calibrationRemove(app);
                FilterAndPlotPhasor(app);
            else
                app.CalibrationPanel.Enable=1;
                app.Calibrationtype.Enable=1;
                calibrationApply(app);
                FilterAndPlotPhasor(app);
            end
        end

        % Value changed function: DownsamplingEditField
        function DownsamplingEditFieldValueChanged(app, event)
            app.dwnsmp = app.DownsamplingEditField.Value;
            if(app.dwnsmp>0)&&(app.dwnsmp<64)                
                warndlg('The downsampling field sets the image size after resizing to speed up processing. If the value is below 64, it defaults to 64. If set to 0, no downsampling occurs.');
                app.DownsamplingEditField.Value=64;
                app.dwnsmp=64;
            end
            if(app.LoadedIms>0)
                gg=questdlg({'Data must be reloaded to change sizes. Clear everything?',' '},'','Ok','Cancel','Ok');
                if(strcmp(gg,'Ok'))
                    clearCalib(app);                    
                    ClearAllContextSelected(app);
                else
                    app.dwnsmp =256;
                    app.DownsamplingEditField.Value=256;
                end
            end
        end

        % Value changed function: exportratiometric
        function ExportratiometricValueChanged(app, event)
            if(app.exportratiometric.Value==1)
                app.comp1.Enable=1;
                app.comp2.Enable=1;
                app.ratiocsv.Enable=1;
            else
                app.comp1.Enable=0;
                app.comp2.Enable=0;
                app.ratiocsv.Enable=0;
            end

        end

        % Button pushed function: ExportLRButton
        function ExportLRButtonPushed(app, event)
            app.exportHR=0;
            ExportHRButtonPushed(app);
            app.exportHR=1;
        end

        % Button pushed function: ExportHRButton
        function ExportHRButtonPushed(app, event)
            app.running=1;
            if(isempty(app.folderR))
                BrowseResultsButtonPushed(app);
            end
            ensenya('Exporting    ');
            d=barraprogress(app,0,[],'Please wait...');
            % aux=get(d,'Position');
            % set(d,'Position',aux+(aux(4)+40).*[0 1 0 0]);
            % progress bar bits for each file: plot,preprint,csvs,images
            trossos=ones(4,1);trossos=trossos/sum(trossos);
            saveParams(app);

            if(app.gmmexport.Value)
                GMMApplyButtonPushed(app);
            end


            % check number of exports in case it is only 1 and
            % combo is selected
            npans=app.ExInt.Value+app.ExMagH.Value+app.ExMagS.Value+app.ExCurH.Value+app.ExCurS.Value+app.ExPhasor.Value;


            % see what nodes are checked
            rang=checkedFiles(app);
            if(app.groupfiles.Value==1)
                guarda=cell(0);
            end

            for ii=rang
                % tic;
                extra=app.nmsx{ii};
                cct=0;
                if(app.exportHR==1)
                    [S1,G1,Im1,pp,I1,I2,IC]=FilterAndPlotPhasor(app,app.SSo{ii,app.Harm.Value},app.GGo{ii,app.Harm.Value},sum(app.IIo{ii},3),ii);
                else
                    [S1,G1,Im1,pp,I1,I2,IC]=FilterAndPlotPhasor(app,app.SSa{ii,app.Harm.Value},app.GGa{ii,app.Harm.Value},app.IIa{ii},ii);
                end
                cct=cct+1;d=barraprogress(app,((ii-1)+sum(trossos(1:cct)))/numel(app.nms),d,[app.nms{ii} extra]);

                auxfora=0;
                if(app.Botonera.Visible==1),auxfora=1;end
                if(auxfora==1),app.Botonera.Visible=0;end
                app.coolab.Text='';% just in case
                if(app.Expwithax.Value==0)
                    auX=app.PP.XTick;
                    auY=app.PP.YTick;
                    app.PP.XTick=[];
                    app.PP.YTick=[];
                end
                if(app.Expvec.Value==1)% vector
                    exportgraphics(app.PP,[app.folderR filesep app.nms{ii} '_PP' extra '.emf']);
                    ppprint=[];
                else% bitmap

                    if(app.Suman.Value==0)
                        [ppprint]=screencapture(app.GSLabUIFigure);
                        fpos=app.GSLabUIFigure.Position;
                        ppos=app.PhasorPanel.Position;
                        ppprint=ppprint(fpos(4)-ppos(2)-ppos(4)+4:fpos(4)-ppos(2),ppos(1)+1:ppos(1)+ppos(3)-2,:);

                    else
                        [ppprint,~]=getframe(app.GSLabUIFigure,app.PhasorPanel.Position);
                        ppprint=ppprint(3:end,1:end-2,:);
                    end

                end
                if(app.Expwithax.Value==0)
                    app.PP.XTick=auX;
                    app.PP.YTick=auY;

                    aux=sum(ppprint,3);
                    val=sum(ppprint(1,1,:));
                    auxx=aux(round(size(aux,1)/2),:);
                    auxy=aux(:,round(size(aux,2)/2));
                    rgx=find(auxx~=val,1,'first'):find(auxx~=val,1,'last');
                    rgy=find(auxy~=val,1,'first'):find(auxy~=val,1,'last');
                    ppprint=ppprint(rgy,rgx,:);
                end
                %
                if(auxfora==1),app.Botonera.Visible=1;end
                % achtung, takes bg color into account to turn it white
                % hopefully the color is not present in the plot
                aux=find(sum(ppprint,3)==720);
                ppprint(aux)=255;
                aux1=numel(ppprint)/3;
                ppprint(aux+aux1)=255;
                ppprint(aux+aux1*2)=255;

                cct=cct+1;d=barraprogress(app,((ii-1)+sum(trossos(1:cct)))/numel(app.nms),d,[app.nms{ii} extra]);

                % decay
                if(app.ExDecay.Value==1)
                    if(~isempty(app.dda{ii}))
                        muntaCSV([app.folderR filesep app.nms{ii} '.' app.DeacayFileDropDown.Value],',',app.dda{ii});
                    end
                end
                % magnidude csv
                if(app.ExMagV.Value==1)

                    switch app.MeasuredmagnitudeButtonGroup.SelectedObject.Text
                        case 'S'
                            magn=1;
                        case 'G'
                            magn=0;
                        case 'Phase'
                            magn=2;
                        case 'TauPhase'
                            magn=5;
                        case 'Mod'
                            magn=3;
                        case 'TauMod'
                            magn=6;
                        case 'NormPhase'
                            magn=4;
                        case 'TauNorm'
                            magn=7;
                        otherwise
                            magn=-1;
                    end
                    if(magn~=-1)&&(app.LifetimeStyle.Value==1)
                        val=magnitude(S1(:),G1(:),magn,app.freq,app.Harm.Value);
                        in=find(~isnan(val));
                        aux=Im1(:);aux=aux(in);val=val(in);
                        aux(aux<1e-4)=0;
                        var=[aux val];
                        muntaCSV([app.folderR filesep app.nms{ii} '_' app.MeasuredmagnitudeButtonGroup.SelectedObject.Text '.' app.MagValFmtDropDown.Value],',',var);
                    else
                        ensenya('Magnitude not implemented for non-lifetime','a');
                    end

                end
                cct=cct+1;d=barraprogress(app,((ii-1)+sum(trossos(1:cct)))/numel(app.nms),d,[app.nms{ii} extra]);


                % combo accumulated
                sep=255*ones(round(size(I2,1)*.02),size(I2,2),3);
                ImPrint=255*ones(1,size(I2,2),3);% single line to keep concatenating
                contaconcat=0;


                % 1 intensity image
                if(app.ExInt.Value==1)
                    if(contaconcat==0)
                        ImPrint=repmat(uint8(255*Im1),[1 1 3]);
                    else
                        ImPrint=cat(1,ImPrint,repmat(uint8(255*Im1),[1 1 3]));
                    end
                    contaconcat=contaconcat+1;
                    if(app.Combine.Value==0)||((npans==1)&&(app.groupfiles.Value==0))
                        imwrite(Im1,[app.folderR filesep app.nms{ii} '_Int' extra '.' app.ExportImageFileDropDown.Value]);
                    end
                end

                % 2 magnitude
                if(app.ExMagH.Value==1)
                    if(contaconcat==0)
                        ImPrint=I1;
                    else
                        ImPrint=cat(1,ImPrint,sep,I1);
                    end
                    contaconcat=contaconcat+1;
                    if(app.Combine.Value==0)||((npans==1)&&(app.groupfiles.Value==0))
                        imwrite(I1,[app.folderR filesep app.nms{ii} '_PPI1' extra '.' app.ExportImageFileDropDown.Value]);
                    end
                end

                % 3 magnitude times im
                if(app.ExMagS.Value==1)

                    if(contaconcat==0)
                        ImPrint=I2;
                    else
                        ImPrint=cat(1,ImPrint,sep,I2);
                    end
                    contaconcat=contaconcat+1;
                    if(app.Combine.Value==0)||((npans==1)&&(app.groupfiles.Value==0))
                        imwrite(I2,[app.folderR filesep app.nms{ii} '_PPI2' extra '.' app.ExportImageFileDropDown.Value]);
                    end
                end

                % 4 cursor im
                if(app.ExCurH.Value==1)
                    try
                        % reset mk and recreate (incase
                        % there is rainbow underneath)
                        app.MK{ii}=app.MK{ii}*0+1;
                        createMasksFromRois(app);
                        mk=app.MK{ii};
                        bk=find(sum(mk,3)==3);
                        mk(bk)=0;
                        mk(bk+numel(mk)/3)=0;
                        mk(bk+numel(mk)*2/3)=0;
                        mk=imresize(mk,size(ImPrint,2)/size(mk,2));
                        mk(mk>1)=1;

                        if(contaconcat==0)
                            ImPrint=uint8(255*mk);
                        else
                            ImPrint=cat(1,ImPrint,sep,uint8(255*mk));
                        end
                        contaconcat=contaconcat+1;
                        if(app.Combine.Value==0)||((npans==1)&&(app.groupfiles.Value==0))
                            imwrite(uint8(255*mk),[app.folderR filesep app.nms{ii} '_PCI1' extra '.' app.ExportImageFileDropDown.Value]);
                        end
                    end
                end

                % 5 cursor times im
                if(app.ExCurS.Value==1)
                    try
                        % reset mk and recreate (incase
                        % there is rainbow underneath)
                        app.MK{ii}=app.MK{ii}*0+1;
                        createMasksFromRois(app);
                        mk=imresize(app.MK{ii},size(ImPrint,2)/size(app.MK{ii},2));
                        mk(mk>1)=1;

                        if(contaconcat==0)
                            ImPrint=uint8(255*Im1.*mk);
                        else
                            ImPrint=cat(1,ImPrint,sep,uint8(255*Im1.*mk));
                        end
                        contaconcat=contaconcat+1;
                        if(app.Combine.Value==0)||((npans==1)&&(app.groupfiles.Value==0))
                            imwrite(uint8(255*Im1.*mk),[app.folderR filesep app.nms{ii} '_PCI2' extra '.' app.ExportImageFileDropDown.Value]);
                        end
                    end
                end


                % 6 phasor
                if(app.ExPhasor.Value==1)&&(app.Expvec.Value==0)
                    if(contaconcat==0)
                        ImPrint=ppprint;
                    else
                        ImPrint=cat(1,ImPrint,sep,imresize(ppprint,[round(size(ppprint,1))*size(ImPrint,2)/size(ppprint,2) size(ImPrint,2)]));
                    end
                    contaconcat=contaconcat+1;
                    if(app.Combine.Value==0)||((npans==1)&&(app.groupfiles.Value==0))
                        if(app.Expptrans.Value==1)% transparent png
                            [~,alp]=white2alpha(ppprint,.95,0);
                            imwrite(ppprint,[app.folderR filesep app.nms{ii} '_PP' extra '.' app.ExportPhasorFileDropDown.Value],'alpha',alp);
                        else
                            imwrite(ppprint,[app.folderR filesep app.nms{ii} '_PP' extra '.' app.ExportPhasorFileDropDown.Value]);
                        end
                    end
                end


                % 7 combo
                if(app.Combine.Value==1)&&(npans>0)
                    if(app.groupfiles.Value==1)
                        guarda{ii}=ImPrint;
                    else
                        imwrite(ImPrint,[app.folderR filesep app.nms{ii} '_combo' extra '.' app.ExportImageFileDropDown.Value]);
                    end
                end
                % percentatge(ii,numel(app.nms));
                cct=cct+1;d=barraprogress(app,((ii-1)+sum(trossos(1:cct)))/numel(app.nms),d,[app.nms{ii} extra]);
                gg=1;
            end

            try
                d=barraprogress(app,1,d,'Finishing');
            end
            % colorbar
            if(app.Cbar.Value==1)
                if(app.LifetimeStyle.Value==1)
                    try
                        auxx=size(I2,1);
                    catch
                        auxx=1000;
                    end
                    cb=composeCbar([app.Cmin.Value app.Cmax.Value],app.MeasuredmagnitudeButtonGroup.SelectedObject.Text,auxx,app.RCM,app.freq);
                    if(~isempty(cb))
                        imwrite(cb,[app.folderR filesep 'colorbar.' app.ExportImageFileDropDown.Value]);
                    end
                else
                    ensenya('Magnitude not implemented for non-lifetime','a');
                end
            end
            if(app.PhRb.Value==1)% plain bg rainbow
                if(exist('IC','var')==0)% if no data is loaded
                    Srange=[app.Smin.Value app.Smax.Value];
                    Grange=[app.Gmin.Value app.Gmax.Value];
                    phsiz=[round(app.phasores*diff(Srange)/diff(Grange)) app.phasores];
                    fov=[Grange;Srange];

                    [type,centre] = definetype(app.MeasuredmagnitudeButtonGroup.SelectedObject.Text);
                    if(isempty(centre)),centre=[app.Gcenter.Value app.Scenter.Value];end
                    Crange=[app.Cmin.Value app.Cmax.Value];
                    if(type==3)% centers are shifted by 1000 to use radial as linear
                        Crange=Crange+1000;
                        type=2;
                    end
                    [~,~,~,~,IC] = phasorRainbowIm(0,0,0,phsiz,fov,centre,1,Crange,app.RCM,1,type);
                end
                imwrite(IC,[app.folderR filesep 'colorgradient.' app.ExportImageFileDropDown.Value]);
            end
            if(app.groupfiles.Value==1)
                if(npans>0)
                    sz=size(guarda{rang(1)});
                    ord=rang;
                    if(app.fliprows.Value==1)
                        ord2=rang;
                        m=app.imagecols.Value;
                        for ii=rang
                            if(mod(ceil(ii/m),2)==0)
                                ord2(ord==ii)=((ceil(ii/m)-1)*m)+(ceil(ii/m)*m)-ii+1;
                            end
                        end
                        ord=ord2;
                    end
                    cc=0;
                    for ii=1:app.imagerows.Value
                        for jj=1:app.imagecols.Value
                            cc=cc+1;
                            pp=[];
                            try
                                pp=guarda{ord(cc)};
                            end
                            if(isempty(pp)),pp=255*ones(sz(1),sz(2),3);end
                            if(jj==1),PPr=pp;else,PPr=cat(2,PPr,pp);end
                        end
                        if(ii==1),PPT=PPr;else,PPT=cat(1,PPT,PPr);end
                    end
                    imwrite(PPT,[app.folderR filesep 'ALL_combo.' app.ExportImageFileDropDown.Value]);
                end
            end
            if(numel(rang)>1)
                FilterAndPlotPhasor(app);
            else
                replotPP(app);
            end
            try
                close(d);
            end
            ensenya('Finished','e');
            % if(numel(app.nms)==0)
            %     msgbox('Warning: No data was exported!');
            % end

            app.running=0;
        end

        % Button pushed function: ExpPhWinButton
        function ExpPhWinButtonPushed(app, event)
            if(isempty(app.folderR))
                BrowseResultsButtonPushed(app);
            end
            auxfora=0;
            if(app.Botonera.Visible==1),auxfora=1;end
            if(auxfora==1),app.Botonera.Visible=0;end
            app.coolab.Text='';% just in case
            if(app.Expwithax.Value==0)
                auX=app.PP.XTick;
                auY=app.PP.YTick;
                app.PP.XTick=[];
                app.PP.YTick=[];
            end
            if(app.Expvec.Value==1)% vector
                exportgraphics(app.PP,[app.folderR filesep 'PhasorPlot.' app.ExportPhasorFileDropDown.Value]);
            else% bitmap

                if(app.Suman.Value==0)
                    [ppprint]=screencapture(app.GSLabUIFigure);
                    fpos=app.GSLabUIFigure.Position;
                    ppos=app.PhasorPanel.Position;
                    ppprint=ppprint(fpos(4)-ppos(2)-ppos(4)+4:fpos(4)-ppos(2),ppos(1)+1:ppos(1)+ppos(3)-2,:);
                else
                    [ppprint,~]=getframe(app.GSLabUIFigure,app.PhasorPanel.Position);
                    ppprint=ppprint(3:end,1:end-2,:);
                end

                %
                if(auxfora==1),app.Botonera.Visible=1;end

                if(app.Expwithax.Value==0)
                    aux=sum(ppprint,3);
                    val=sum(ppprint(1,1,:));
                    auxx=aux(round(size(aux,1)/2),:);
                    auxy=aux(:,round(size(aux,2)/2));
                    rgx=find(auxx~=val,1,'first'):find(auxx~=val,1,'last');
                    rgy=find(auxy~=val,1,'first'):find(auxy~=val,1,'last');
                    ppprint=ppprint(rgy,rgx,:);
                end

                % achtung, takes bg color into account to turn it white
                % hopefully the color is not present in the plot
                aux=find(sum(ppprint,3)==720);
                ppprint(aux)=255;
                aux1=numel(ppprint)/3;
                ppprint(aux+aux1)=255;
                ppprint(aux+aux1*2)=255;


                aux=numel(dir([app.folderR filesep 'PhasorPlot*']));
                for ii=1:1+aux
                    ex=niceDigits(ii);
                    nom=[app.folderR filesep 'PhasorPlot' ex '.' app.ExportPhasorFileDropDown.Value];
                    if(exist(nom,'file')==0)
                        break;
                    end
                end


                if(app.Expptrans.Value==1)% transparent png
                    [~,alp]=white2alpha(ppprint,.95,0);
                    imwrite(ppprint,nom,'alpha',alp);
                else
                    imwrite(ppprint,nom);
                end
            end
            if(app.Expwithax.Value==0)
                app.PP.XTick=auX;
                app.PP.YTick=auY;
            end
            ensenya('Exported Phasor Plot','b');
        end

        % Button pushed function: ExpImWinButton
        function ExpImWinButtonPushed(app, event)

            if(isempty(app.folderR))
                BrowseResultsButtonPushed(app);
            end
            aux=numel(dir([app.folderR filesep 'ImageWindow*']));
            for ii=1:1+aux
                ex=niceDigits(ii);
                nom=[app.folderR filesep 'ImageWindow' ex '.jpg'];
                if(exist(nom,'file')==0)
                    break;
                end
            end

            [im,~]=getframe(app.IWh);
            imwrite(im,nom);
            ensenya('Exported Image Window','b');

        end

        % Value changed function: Expvec
        function ExpvecValueChanged(app, event)
            if(app.Expvec.Value==1)
                app.Expptrans.Value=0;
                if(app.ExportPhasorFileDropDown.ValueIndex<4)
                    app.ExportPhasorFileDropDown.ValueIndex=4;
                end
            else
                if(app.ExportPhasorFileDropDown.ValueIndex>3)
                    app.ExportPhasorFileDropDown.ValueIndex=1;
                end
            end

        end

        % Value changed function: Expptrans
        function ExpptransValueChanged(app, event)
            if(app.Expptrans.Value==1)
                app.Expvec.Value=0;

            end
            if(app.ExportPhasorFileDropDown.ValueIndex>1)
                app.ExportPhasorFileDropDown.ValueIndex=1;
            end
        end

        % Value changed function: ExportPhasorFileDropDown
        function ExportPhasorFileDropDownValueChanged(app, event)
            if(app.ExportPhasorFileDropDown.ValueIndex>1)
                app.Expptrans.Value=0;
            end
            if(app.ExportPhasorFileDropDown.ValueIndex<4)
                app.Expvec.Value=0;
            else
                app.Expvec.Value=1;
            end

        end

        % Value changed function: Excess
        function ExcessValueChanged(app, event)
            if(app.LoadedIms>0)
                FilterAndPlotPhasor(app);
            end
        end

        % Value changed function: freqEF
        function freqEFValueChanged(app, event)
            % prev=app.freq;
            if(numel(unique(app.PD))>1)
                gg=questdlg({'Calibration must be reloaded. Clear?',' '},'','Ok','Cancel','Ok');
                if(strcmp(gg,'Ok'))
                    clearCalib(app);
                    LoadCalibrationFile(app);
                    FilterAndPlotPhasor(app);
                    app.freq=app.freqEF.Value*1e6;
                else
                    app.freqEF.Value=app.freq*1e-6;
                end
            else
                app.freq=app.freqEF.Value*1e6;
                replotPP(app);
            end


        end

        % Value changed function: Eb
        function FilterSizeValueChanged(app, event)
            app.Filtermatch.Text=[' x ' num2str(app.Eb.Value)];
            FilterAndPlotPhasor(app);

        end

        % Value changed function: Ti
        function FilterTimesValueChanged(app, event)
            FilterAndPlotPhasor(app);
        end

        % Value changed function: AFSef
        function FontSizeAxesValueChanged(app, event)
            replotPP(app);
        end

        % Value changed function: tickfontsize
        function FontSizeLineValueChanged(app, event)
            replotPP(app);
        end

        % Value changed function: groupfile
        function groupfileValueChanged(app, event)
            if(app.groupfile.Value==1)
                app.mergecomps.Enable=0;
            else
                app.mergecomps.Enable=1;
            end
        end

        % Button pushed function: ApplyGMMButton
        function GMMApplyButtonPushed(app, event)

            if(~isempty(app.GMfit))

                rang=checkedFiles(app);
                alldata=[];
                for ii=rang
                    alldata=[alldata;[app.SSi{ii}(:) app.GGi{ii}(:)]];
                end


                for ii=rang
                    dad=[app.SSi{ii}(:) app.GGi{ii}(:)];
                    if(app.hard.Value==1)
                        K=size(app.GMfit.mu,1);
                        IDX = cluster(app.GMfit,dad);
                        out=find(isnan(IDX));
                        IDX(out)=K+1;
                        aux=app.GMfitcolinds;
                        aux(K+1)=numel(app.CCM);
                        IDX=app.colMks(aux(IDX),:);
                        IDX=reshape(IDX,[size(app.SSi{ii}) 3]);
                    else
                        probs=posterior(app.GMfit,dad);
                        cols=repmat(permute(app.colMks(app.GMfitcolinds,:),[3 1 2]),[size(probs,1) 1 1]);
                        probs=probs.*cols;
                        probs=sum(probs,2);
                        IDX=reshape(probs,[size(app.SSi{ii}) 3]);
                    end
                    if(app.blend.Value==1)
                        IDX=imgaussfilt(IDX,app.blendamount.Value*size(IDX,1)/2000);
                    end
                    app.MK{ii}=IDX;
                end

                % replotPP(app);
                replotII(app);
            end
        end

        % Selection changed function: dispStyle2
        function GMMdispStyleChanged(app, event)
            GMMApplyButtonPushed(app);
        end

        % Selection changed function: dispStyle
        function GMMdisp2StyleChanged(app, event)
            replotPP(app);
        end

        % Value changed function: blendamount
        function GMMblendamountValueChanged(app, event)
            GMMApplyButtonPushed(app);
        end

        % Value changed function: gmmexport
        function GMMexportValueChanged(app, event)
            if(app.gmmexport.Value==1)
                app.ExMagH.Text='GMM-colored image';
                app.ExMagS.Text='GMM-colored x intensity';
            else
                app.ExMagH.Text='Magnitude-colored image';
                app.ExMagS.Text='Magnitude-colored x intensity';
            end
        end

        % Value changed function: blend
        function GMMblendeValueChanged(app, event)
            GMMApplyButtonPushed(app);
        end

        % Button pushed function: TrainGMMButton
        function GMMTrainButtonPushed(app, event)
            app.PhBg.Value=0;
            rang=checkedFiles(app);
            if(numel(rang)>0)
                if(numel(unique(app.roidad))==1)&&(isempty(app.GMfit))
                    msgbox("Add cursors to set starting points for GMM","");
                else
                    app.running=1;
                    d=barraprogress(app,0,[],'Please wait...');
                    alldata=[];
                    for ii=rang
                        alldata=[alldata;[app.SSi{ii}(:) app.GGi{ii}(:)]];
                    end
                    options = statset('MaxIter',1500);

                    if(numel(unique(app.roidad))==1)
                        S.mu=app.GMfit.mu;% keep old mu to rematch
                        curind=app.GMfitcolinds;
                        K=size(app.GMfit.mu,1);
                        gmfit = fitgmdist(alldata,K,'CovarianceType','full','SharedCovariance',false,'Options',options);

                        startpoint=0;
                    else
                        curind=find(app.roidad(:,3)>0);
                        K=numel(curind);
                        S.mu=app.roidad(curind,1:2);
                        sig=zeros(2,2,K);
                        for ii=1:K
                            sig(:,:,ii)=[app.roidad(curind(ii),3) 0;0 app.roidad(curind(ii),3)];
                        end
                        S.Sigma=sig;
                        gmfit = fitgmdist(alldata,K,'Start',S,'CovarianceType','full','SharedCovariance',false,'Options',options);
                        startpoint=1;
                    end

                    % app.clust=0;

                    app.GMfit=gmfit;
                    % if(startpoint==0)
                    %     app.GMfitcolinds=curind;
                    % end% check distance to starting points to match user set colors
                    dd=zeros(size(S.mu,1)*size(S.mu,1),3);% save ii,jj and dist
                    cc=0;
                    for ii=1:size(app.GMfit.mu,1)
                        for jj=1:size(S.mu,1)
                            cc=cc+1;
                            dd(cc,1:2)=[ii jj];
                            dd(cc,3)=norm(app.GMfit.mu(ii,:)-S.mu(jj,:));
                        end
                    end
                    [~,ord]=sort(dd(:,3));
                    dd=dd(ord,:);
                    newcurind=curind;
                    for ii=1:numel(curind)
                        i2=dd(1,2);i1=dd(1,1);
                        newcurind(i1)=curind(i2);
                        dd(1,:)=[];
                        dd(dd(:,2)==i2,:)=[];
                        dd(dd(:,1)==i1,:)=[];
                    end
                    app.GMfitcolinds=newcurind;

                    XButtonPushed(app);
                    replotPP(app);
                    GMMApplyButtonPushed(app);
                    try,close(d);end
                    app.running=0;
                end
            end
        end

        % Value changed function: conf
        function GMMconfValueChanged(app, event)
            replotPP(app);
        end

        % Button pushed function: ClearGMMButton
        function GMMclearButtonPushed(app, event)
            app.GMfit=[];
            app.GMfitcolinds=[];
            replotPP(app);
        end

        % Value changed function: Harm
        function HarmValueChanged(app, event)
            % FilterAndPlotPhasor(app);
            if(app.Harm.Value>1)
                app.Harm.FontColor=[1 0 0];
                app.Harm.FontWeight='bold';
            else
                app.Harm.FontColor=[0 0 0];
                app.Harm.FontWeight='normal';
            end
            FilterAndPlotPhasor(app);
        end

        % Button pushed function: hidecalib
        function hidecalibButtonPushed(app, event)
            if(app.CalibHolder.Visible==1)
                app.CalibHolder.Visible=0;
                app.hidecalib.Text='▴';
                app.hidecalib.Position(2)=app.Tree.Position(2)+2;
            else
                app.CalibHolder.Visible=1;
                app.hidecalib.Text='▾';
                app.hidecalib.Position(2)=app.CalibHolder.Position(2)+app.CalibHolder.Position(4)-app.Tree.Position(2)-1;
            end
        end

        % Value changed function: hdr
        function HDRValueChanged(app, event)
            if(app.hdr.Value==1)
                app.logint.Value=0;
            end
            if(app.LoadedIms>0)
                FilterAndPlotPhasor(app);
            end
        end

        % Value changed function: Imin
        function IminValueChanged(app, event)
            app.Islide.Value=[app.Imin.Value app.Imax.Value];
            for ii=1:app.LoadedIms
                app.hdrkeep{ii}=[];
            end
            if(app.LoadedIms>0)
                FilterAndPlotPhasor(app);
            end
            plotHists(app);

        end

        % Value changed function: Imax
        function ImaxValueChanged(app, event)
            app.Islide.Value=[app.Imin.Value app.Imax.Value];
            for ii=1:app.LoadedIms
                app.hdrkeep{ii}=[];
            end
            if(app.LoadedIms>0)
                FilterAndPlotPhasor(app);
            end
            plotHists(app);

        end

        % Value changed function: invertmk
        function invertmkValueChanged(app, event)
            for ii=1:numel(app.MKL)
                app.MKL{ii}=1-app.MKL{ii};
            end
            app.invertmk.Value=1-app.invertmk.Value;
            mostramask(app);
            app.ApplyImageMasks=0;
            ApplymaskValueChanged(app);
        end

        % Value changed function: Islide
        function IslideValueChanged(app, event)
            value = app.Islide.Value;
            app.Imin.Value=round(100*value(1))/100;
            app.Imax.Value=round(100*value(2))/100;
            for ii=1:app.LoadedIms
                app.hdrkeep{ii}=[];
            end
            if(app.LoadedIms>0)
                FilterAndPlotPhasor(app);
            end
            plotHists(app);

        end

        % Value changed function: imagecols
        function imagecolsValueChanged(app, event)
            imagewindowtileValueChanged(app);
        end

        % Value changed function: imagerows
        function imagerowsValueChanged(app, event)
            imagewindowtileValueChanged(app);
        end

        % Value changed function: imagewindowtile
        function imagewindowtileValueChanged(app, event)
            if(app.imagewindowtile.Value==1)
                app.imagecols.Enable=1;
                app.imagerows.Enable=1;
                app.tilinglabel.Enable=1;
            else
                app.imagecols.Enable=0;
                app.imagerows.Enable=0;
                app.tilinglabel.Enable=0;
            end

            createImageFigure(app);
            replotII(app);
        end

        % Button pushed function: LoadCursorsButton
        function LoadCursorsButtonPushed(app, event)
            [filepath] = uigetFiles('Select cursor file',1);
            figure(app.GSLabUIFigure);
            if(~isempty(filepath))
                try

                    aux=whos('-file',filepath);
                    if(strcmp(aux(1).name,'roidad'))
                        for ii=1:numel(app.curhan)
                            delete(app.curhan(ii));
                        end
                        clearMasks(app);
                        load(filepath,'roidad');
                        app.roidad=roidad;
                        app.currCol=find(roidad(:,3)>0,1,'last')+1;
                        if(app.currCol>numel(app.CCM)),app.currCol=1;end
                        app.curs1.Color=app.colMks(app.currCol,:);
                        % paintRois(app);
                        FilterAndPlotPhasor(app);
                        app.Botonera.Visible=1;
                    else
                        error();
                    end
                catch
                    msgbox("No cursors in file!","");
                end
            end
        end

        % Menu selected function: LoadTIFSequenceContextMenu
        function LoadTIFSequenceContextMenuSelected(app, event)
            [filepaths] = uigetFiles('Select tif file(s)',1,1,{'*.tif;*.tiff'});

            if(~isempty(filepaths))
                imsbefore=app.LoadedIms;
                LoadTifSeqFun(app,filepaths);
                if(app.LoadedIms>imsbefore)% we have accumulated some data
                    createImageFigure(app);
                    FilterAndPlotPhasor(app);
                    plotHists(app);
                else
                    msgbox('Warning: No data was loaded!');
                end
            end
        end

        % Menu selected function: LoadFLAMErawContextMenu
        function LoadFLAMErawContextMenuSelected(app, event)
            % BrowseDataButtonPushed(app);
            aux = uigetDirs('Select folder containing image folders',1);
            if(~isempty(aux))
                app.folderD=aux;
                app.RGBLabel.Enable=1;
                app.ChanButton3.Enable=1;
                app.ChanButton4.Enable=1;
                imsbefore=app.LoadedIms;
                LoadFlameRawFun(app);

                if(app.LoadedIms>imsbefore)% we have accumulated some data
                    saveParams(app);
                    createImageFigure(app);
                    FilterAndPlotPhasor(app);
                    plotHists(app);
                    SourceButtonGroupSelectionChanged(app);
                else
                    msgbox('Warning: No data was loaded!');
                end
            end
        end

        % Menu selected function: LoadSimFCSrefContextMenu
        function LoadSimFCSrefContextMenuSelected(app, event)
            logUse('PhasorFlame');
            % delete(app.Tree.Children);

            [filepaths] = uigetFiles('Select ref file(s)',1,1,{'*.R64;*.ref;*.re1;*.re3;*.re4'});
            figure(app.GSLabUIFigure);
            if(~isempty(filepaths))
                imsbefore=app.LoadedIms;
                LoadSimFCSrefFun(app,filepaths);
                if(app.LoadedIms>imsbefore)% we have accumulated some data
                    saveParams(app);
                    createImageFigure(app);
                    FilterAndPlotPhasor(app);
                    plotHists(app);
                    SourceButtonGroupSelectionChanged(app);
                else
                    msgbox('Warning: No data was loaded!');
                end
            end
        end

        % Menu selected function: LoadFALCONSnGContextMenu
        function LoadFALCONSnGContextMenuSelected(app, event)
            logUse('PhasorFlame');
            %
            filepaths = uigetDirs('Select FALCON folder',1);
            if(~isempty(filepaths))
                imsbefore=app.LoadedIms;
                LoadFalconSnGFun(app,filepaths);
                if(app.LoadedIms>imsbefore)% we have accumulated some data
                    saveParams(app);
                    createImageFigure(app);
                    FilterAndPlotPhasor(app);
                    plotHists(app);
                    SourceButtonGroupSelectionChanged(app);
                else
                    msgbox('Warning: No data was loaded!');
                end

            end
        end

        % Menu selected function: LoadZeisslsmContextMenu
        function LoadZeisslsmContextMenuSelected(app, event)
            [filepaths] = uigetFiles('Select lsm file(s)',1,1,{'*.lsm'});
            figure(app.GSLabUIFigure);
            if(~isempty(filepaths))
                imsbefore=app.LoadedIms;
                LoadZeissLSMFun(app,filepaths);
                if(app.LoadedIms>imsbefore)% we have accumulated some data
                    saveParams(app);
                    createImageFigure(app);
                    FilterAndPlotPhasor(app);
                    plotHists(app);
                    SourceButtonGroupSelectionChanged(app);
                else
                    msgbox('Warning: No data was loaded!');
                end
            end
        end

        % Menu selected function: SavedSessionMenu
        function LoadSavedSessionMenuSelected(app, event)
            [filepaths] = uigetFiles('Select file session',1,0,{'*.mat'});
            imsbefore=app.LoadedIms;
            if(~isempty(filepaths))
                applyUserSettings(app,filepaths);

                if(app.LoadedIms>imsbefore)% we have accumulated some data
                    saveParams(app);
                    createImageFigure(app);
                    % FilterAndPlotPhasor(app);
                    plotHists(app);
                    SourceButtonGroupSelectionChanged(app);
                    StyleSelectionChanged(app);
                else
                    ensenya('Finished','e');
                end
            end
        end

        % Button pushed function: LoadmaskButton
        function LoadmaskButtonPushed(app, event)
            [filepath] = uigetFiles('Select mask file',1,0,{'*.png;*.tif'});
            figure(app.GSLabUIFigure);
            if(~isempty(filepath))
                try
                    if(strcmp(filepath(end-2:end),'png'))
                        I=imread(filepath);
                        %
                        if(size(I,3)>1)
                            I=sum(double(I),3)/3;
                        else% in case indexed images and colors are sorted in a wierd way
                            [I,cm]=imread(filepath);
                            if(~isempty(cm))
                                I=ind2rgb(double(I),cm);
                                I=sum(I,3);
                            end
                        end
                        I=NormArray(I);
                        if(max(I(:))>1),I=I>.5;end
                        % rang=checkedFiles(app);
                        for ii=1:app.LoadedIms
                            app.MKL{ii}=double(I);
                        end
                        % if(isempty(rang))
                        %     app.MKL{1}=I;
                        % end
                        ensenya('Warning: Applying 1 mask to all images','a');
                    else
                        nn=numel(imfinfo(filepath));
                        rang=checkedFiles(app);
                        if(nn>numel(rang))
                            ensenya(['Warning: More masks than selected files! Loading only first ' num2str(numel(rang)) ],'a');
                        end
                        if(nn<numel(rang))
                            ensenya(['Warning: Less masks than selected files! '],'a');
                        end
                        for ii=1:min(nn,numel(rang))
                            I=imread(filepath,ii);
                            if(size(I,3)>1),I=sum(I,3)/3;end
                            if(max(I(:))>1),I=I>128;end% assume uint8
                            app.MKL{rang(ii)}=double(I);
                        end
                    end


                    % FilterAndPlotPhasor(app);
                    mostramask(app);
                    ApplymaskValueChanged(app);

                catch
                    msgbox("Error loading file!","");
                end
            end
        end

        % Value changed function: logint
        function LogIntValueChanged(app, event)
            if(app.logint.Value==1)
                app.hdr.Value=0;
            end
            if(app.LoadedIms>0)
                FilterAndPlotPhasor(app);
            end
        end

        % Value changed function: Logp
        function LogpValueChanged(app, event)

            plotHists(app);

        end

        % Value changed function: UCcb
        function LinesCheckBoxValueChanged(app, event)
            replotPP(app);
        end

        % Value changed function: logDens
        function logDensValueChanged(app, event)
            replotPP(app);
        end

        % Value changed function: MaxharmonicsEditField
        function MaxharmonicsEditFieldValueChanged(app, event)
            preval=app.HarmLim;%disp(preval)
            app.HarmLim = app.MaxharmonicsEditField.Value;
            if(app.LoadedIms>0)&&(preval<app.HarmLim)
                gg=questdlg({'Data must be reloaded to compute higher harmonics. Clear everything?',' '},'','Ok','Cancel','Ok');
                if(strcmp(gg,'Ok'))
                    clearCalib(app);
                    ClearAllContextSelected(app);
                    LoadCalibrationFile(app);
                else
                    app.HarmLim=preval  ;
                    app.MaxharmonicsEditField.Value=preval;
                end
            end
            app.Harm.Limits(2)=app.HarmLim;

        end

        % Selection changed function: MeasuredmagnitudeButtonGroup
        function MagnitudeButtonGroupSelectionChanged(app, event)
            if((app.CustomAngular.Value==1)||(app.CustomRadial.Value==1))
                app.Gcenter.Enable='on';
                app.Scenter.Enable='on';
                app.CenterLabel.Enable='on';
            else
                app.Gcenter.Enable='off';
                app.Scenter.Enable='off';
                app.CenterLabel.Enable='off';
            end

            if(app.LoadedIms>0)
                app.PhBg.Value=1;
                MagnitudeAutoColorButtonPushed(app);
            end
        end

        % Button pushed function: AutoButton
        function MagnitudeAutoColorButtonPushed(app, event)
            rang=checkedFiles(app);
            [type,centre] = definetype(app.MeasuredmagnitudeButtonGroup.SelectedObject.Text);
            if(isempty(centre)),centre=[app.Gcenter.Value app.Scenter.Value];end
            qv=[.0001 .9999];
            limits=[Inf,-Inf];
            if(isempty(rang)),limits=[0 1];end
            for ii=rang
                s=app.SSi{ii};
                g=app.GGi{ii};
                if(type==1)
                    ph=atan2(s-centre(2),g-centre(1));
                    qq=quantile(ph(:),qv);
                    qq=qq/6.28;
                    limits(1)=min(limits(1),qq(1));
                    limits(2)=max(limits(2),qq(2));
                end
                if(type==2)||(type==3)
                    vec=[s(:)-centre(2),g(:)-centre(1)];
                    vec=sqrt(sum(vec.*vec,2));
                    qq=quantile(vec(:),qv);
                    if((type==3)),qq=qq-1e3;end
                    limits(1)=min(limits(1),qq(1));
                    limits(2)=max(limits(2),qq(2));
                end
            end
            app.Cmin.Value=limits(1);
            app.Cmax.Value=limits(2);
            FilterAndPlotPhasor(app);


        end

        % Value changed function: modS
        function modSValueChanged(app, event)
            if(app.DoCalib.Value==1)
                DoCalibValueChanged(app);
            end
        end

        % Menu selected function: TopMenu
        function MoveTopMenuSelected(app, event)
            if(~isempty(event.ContextObject.SelectedNodes))
                quin=event.ContextObject.SelectedNodes.NodeData;
                new=1;
                moveTreeElement(app,quin,new,'before');
            else
                warndlg('Select a file to move it!','Warning') ;
            end
        end

        % Menu selected function: UpMenu
        function MoveUpMenuSelected(app, event)
            if(~isempty(event.ContextObject.SelectedNodes))
                quin=event.ContextObject.SelectedNodes.NodeData;
                new=quin-1;if(new==0),new=1;end
                moveTreeElement(app,quin,new,'before');
            else
                warndlg('Select a file to move it!','Warning') ;
            end
        end

        % Menu selected function: DownMenu
        function MoveDownMenuSelected(app, event)
            if(~isempty(event.ContextObject.SelectedNodes))
                quin=event.ContextObject.SelectedNodes.NodeData;
                new=quin+1;if(new>app.LoadedIms),new=app.LoadedIms;end
                moveTreeElement(app,quin,new,'after');
            else
                warndlg('Select a file to move it!','Warning') ;
            end
        end

        % Menu selected function: BotMenu
        function MoveBotMenuSelected(app, event)
            if(~isempty(event.ContextObject.SelectedNodes))
                quin=event.ContextObject.SelectedNodes.NodeData;
                new=app.LoadedIms;
                moveTreeElement(app,quin,new,'after');
            else
                warndlg('Select a file to move it!','Warning') ;
            end
        end

        % Menu selected function: EvenOddMenu
        function MoveEvenOdd(app, event)
            rang=sort([2:2:app.LoadedIms],'descend');
            d=barraprogress(app,0,[],'Rearranging...');
            NN=numel(rang);
            for ii=1:NN% ii=3
                moveTreeElement(app,rang(ii)+ii-1,1,'before');
                d=barraprogress(app,ii/NN,d,'Rearranging...');
            end
            pause(.5);
            try
                close(d);
            end
        end

        % Value changed function: normFile
        function normFileValueChanged(app, event)
            replotPP(app);
        end

        % Button down function: PP
        function PPButtonDown(app, event)
            if(event.Button==1)% left click
                app.Botonera.Visible=1;
                radiP=app.radi;
                centre=app.PP.CurrentPoint(1,1:2);
                roi = images.roi.Circle(app.PP,'center',centre,'color',app.colMks(app.currCol,:),'radius',radiP);
                roi.InteractionsAllowed='none';
                app.curhan=[app.curhan roi];
                app.roidad(app.currCol,1:3)=[centre radiP];
                app.currCol=app.currCol+1;
                if(app.currCol>numel(app.CCM)),app.currCol=1;end
                app.curs1.Color=app.colMks(app.currCol,:);
                if(app.CursorButton.Value==1)
                    SourceButtonGroupSelectionChanged(app);
                end
            end
            if(event.Button==3)% right click
                FilterAndPlotPhasor(app);
            end
        end

        % Value changed function: PhBg
        function PhBgValueChanged(app, event)
            FilterAndPlotPhasor(app);

        end

        % Button pushed function: PlotTrueSize
        function PlotTrueSizeButtonPushed(app, event)
            aux1=app.IIa;
            aux2=app.SSa;
            aux3=app.GGa;
            for ii=1:app.LoadedIms
                app.IIa{ii}=sum(app.IIo{ii},3);
            end
            app.SSa=app.SSo;
            app.GGa=app.GGo;
            if(app.DoCalib.Value==1)
                for ii=1:app.LoadedIms
                    for hh=1:app.HarmLim
                        S1=app.SSa{ii,hh};
                        G1=app.GGa{ii,hh};
                        % fill nans before resizing (otherwise only nearest algorithm works)!
                        % S1=phasorFillNaN2(S1);
                        % G1=phasorFillNaN2(G1);
                        % calib
                        if(app.calibflag(ii)>0)
                            pha=atan2(S1,G1)+app.PD(app.calibflag(ii),hh);
                            modu=sqrt((S1.*S1)+(G1.*G1))*app.MF(app.calibflag(ii),hh);
                            S1=modu.*sin(pha);G1=modu.*cos(pha);
                        end
                        app.SSa{ii,hh}=S1;
                        app.GGa{ii,hh}=G1;
                    end
                end
            end

            FilterAndPlotPhasor(app);
            app.IIa=aux1;
            app.SSa=aux2;
            app.GGa=aux3;
        end

        % Window scroll wheel function: GSLabUIFigure
        function GSLabUIFigureWindowScrollWheel(app, event)
            aux = event.VerticalScrollCount;
            xl=.01*diff(app.PP.XLim);
            if(aux>0)
                % app.radi=app.radi+1;if(app.radi>16),app.radi=16;end
                app.radi=app.radi+xl;
                if(app.radi>xl*100),app.radi=xl*100;end
                app.curs1.Radius=app.radi;
            else
                % app.radi=app.radi-1;if(app.radi<1),app.radi=1;end
                app.radi=app.radi-xl;
                if(app.radi<xl*.001),app.radi=xl*.001;end
                app.curs1.Radius=app.radi;
            end
            % app.GSLabUIFigure.PointerShapeCData=app.curs(:,:,app.radi);
            app.GSLabUIFigureWindowButtonMotion();
            % disp(rand);

        end

        % Close request function: GSLabUIFigure
        function GSLabUIFigureCloseRequest(app, event)
            saveParams(app);
            try close(app.IWh);end
            delete(app);
        end

        % Window button motion function: GSLabUIFigure
        function GSLabUIFigureWindowButtonMotion(app, event)

            if(app.running==0)
                % change to phasor units
                app.imcur.XData=-1000;
                cp=app.PP.CurrentPoint;
                cp=cp(1,1:2);
                yn=0;
                if(cp(1)<app.Gmax.Value)&&(cp(1)>app.Gmin.Value)
                    if(cp(2)<app.Smax.Value)&&(cp(2)>app.Smin.Value)
                        lag=cp(1);
                        las=cp(2);
                        % app.coolab.Text=['$$\textsf{GS=(' niceNums(lag,2) ',' niceNums(las,2) '), }{\tau_{pnm}}\textsf{=(' niceNums(tp,2) ',' niceNums(tn,2) ',' niceNums(tm,2) ')}$$'];
                        if(app.LifetimeStyle.Value==1)
                            tp=magnitude(las,lag,5,app.freq,app.Harm.Value);
                            tn=magnitude(las,lag,7,app.freq,app.Harm.Value);
                            tm=magnitude(las,lag,6,app.freq,app.Harm.Value);
                            app.coolab.Text=['$$\rm \textsf{G=' niceNums(lag,2) ' S=' niceNums(las,2) ' }\tau_{p}\textsf{=' niceNums(tp,2) ' } \tau_{n}\textsf{=' niceNums(tn,2) ' }\tau_{m}\textsf{=' niceNums(tm,2) '}$$'];
                        else
                            app.coolab.Text=['$$\rm \textsf{G=' niceNums(lag,2) ' S=' niceNums(las,2) ' }$$'];
                        end

                        yn=1;


                        app.GSLabUIFigure.Pointer='custom';
                        app.GSLabUIFigure.PointerShapeCData=app.blk;
                        % radiP=estimateCursorSize(app);
                        app.curs1.Center=cp;
                        radiP=app.curs1.Radius;
                        radiP=radiP*radiP;
                        % disp(radiP);
                        %                         rang=[];
                        % for ii=1:numel(app.Tree.CheckedNodes)
                        %     rang=[rang app.Tree.CheckedNodes(ii).NodeData];
                        % end

                        for ii=1:app.LoadedIms
                            S1=app.SSi{ii}-cp(2);
                            G1=app.GGi{ii}-cp(1);
                            blank=G1;blank(:)=1;
                            colmk=app.colMks(app.currCol,:);
                            aux=(S1.*S1)+(G1.*G1)-radiP<0;
                            % N=numel(aux);
                            % aux=find(aux==1);%indexes in 1chim
                            % app.MK{ii}(aux)=colmk(1);%R
                            % app.MK{ii}(aux+N)=colmk(2);%G
                            % app.MK{ii}(aux+2*N)=colmk(3);%B
                            for jj=1:3
                                aux0=blank;
                                aux0(aux==1)=colmk(jj);
                                app.MK{ii}(:,:,jj)=aux0;
                            end
                        end
                        % figure(55);clf;
                        % hh=axes();
                        % images.roi.Circle(hh,'center',cp,'color',app.colMks(app.currCol,:),'radius',sqrt(radiP));
                        % fov=[app.Gmin.Value app.Gmax.Value;app.Smin.Value app.Smax.Value];
                        % xlim(fov(1,:));ylim(fov(2,:));
                        % universalCircle(fov,'k',8e7);
                        % axis equal;
                        createMasksFromRois(app);
                        if(app.LoadedIms>0)
                            replotII(app);
                        end

                    end
                end
                if(yn==0)
                    app.GSLabUIFigure.Pointer='arrow';
                    app.curs1.Center=[-1000 0];
                    app.coolab.Text='';
                    app.coolab.FontColor=app.FGcol;
                end
            end

        end

        % Window key press function: GSLabUIFigure
        function GSLabUIFigureWindowKeyPress(app, event)
            switch event.Key
                case 'f1', tabselector(app,[],[],1);
                case 'f2', tabselector(app,[],[],2);
                case 'f3', tabselector(app,[],[],3);
                case 'f4', tabselector(app,[],[],4);
                case 'f5', tabselector(app,[],[],5);
                case 'f6', tabselector(app,[],[],6);
                case 'f7', tabselector(app,[],[],7);
                case 'f12'% used for debugging
                    gg=0;
                case 'add', app.currcol=app.currcol+1;if(app.currcol>16),app.currcol=1;end;setcolors(app);
                case 'multiply',app.DM=1-app.DM;setcolors(app);
            end
        end

        % Value changed function: PhasorresolutionEditField
        function PhasorresolutionEditFieldValueChanged(app, event)
            app.phasores = app.PhasorresolutionEditField.Value;
            FilterAndPlotPhasor(app);
        end

        % Value changed function: phaS
        function phaSValueChanged(app, event)
            if(app.DoCalib.Value==1)
                DoCalibValueChanged(app);
            end
        end

        % Value changed function: relative
        function relativeValueChanged(app, event)
            for ii=1:app.LoadedIms
                app.hdrkeep{ii}=[];
            end
            if(app.relative.Value==1)
                app.Imax.Value=app.Imax.Value/app.Imaxrange;
                app.Imin.Value=app.Imin.Value/app.Imaxrange;
                app.Imax.Limits=[0 1];
                app.Imin.Limits=[0 1];
                app.Imax.ValueDisplayFormat='%.2f';
                app.Imin.ValueDisplayFormat='%.2f';
                app.Islide.Limits(2)=1;
                app.Islide.Value=[app.Imin.Value app.Imax.Value];
            else

                app.Imax.Limits=[0 ceil(app.Imaxrange)];
                app.Imin.Limits=[0 app.Imaxrange];
                app.Imax.ValueDisplayFormat='%.0f';
                app.Imin.ValueDisplayFormat='%.0f';
                app.Islide.Limits(2)=ceil(app.Imaxrange);
                app.Imax.Value=(app.Imax.Value*app.Imaxrange);
                app.Imin.Value=app.Imin.Value*app.Imaxrange;
                app.Islide.Value=[app.Imin.Value app.Imax.Value];

            end
            if(app.LoadedIms>0)
                plotHists(app);
                FilterAndPlotPhasor(app);
                % replotII(app);

            end
        end

        % Button pushed function: SaveCursorsButton
        function SaveCursorsButtonPushed(app, event)
            if(numel(unique(app.roidad))==1)
                msgbox("No cursors to save!","");
            else
                [name,folder] = uisaveFile('Save current cursors to file');
                if(ischar(name))
                    roidad=app.roidad;
                    save([folder name],'roidad');
                end
            end
        end

        % Menu selected function: asSimFCSrefMenu
        function SaveSimFCSrefContextSelected(app, event)
            if(numel(app.nms)==0)
                msgbox("No data to save!","");
            else

                [folder] = uigetDirs('Select output folder',1);
                d=barraprogress(app,0,[],'Saving...');
                ensenya('Saving');
                rang=checkedFiles(app);
                for ii=rang
                    ima=sum(app.IIo{ii},3);
                    S0=app.SSo{ii,1};
                    G0=app.GGo{ii,1};
                    if(app.DoCalib.Value==1)
                        pha=atan2(S0,G0)+app.PD(app.calibflag(ii),1);
                        modu=sqrt((S0.*S0)+(G0.*G0))*app.MF(app.calibflag(ii),1);
                        S0=modu.*sin(pha);G0=modu.*cos(pha);
                    end
                    vec=[ima(:);180*atan2(S0(:),G0(:))/3.1416;sqrt(S0(:).*S0(:)+G0(:).*G0(:))];
                    S0=app.SSo{ii,2};
                    G0=app.GGo{ii,2};
                    if(app.DoCalib.Value==1)
                        pha=atan2(S0,G0)+app.PD(app.calibflag(ii),2);
                        modu=sqrt((S0.*S0)+(G0.*G0))*app.MF(app.calibflag(ii),2);
                        S0=modu.*sin(pha);G0=modu.*cos(pha);
                    end
                    vec=[vec;180*atan2(S0(:),G0(:))/3.1416;sqrt(S0(:).*S0(:)+G0(:).*G0(:))];


                    pumpum = fopen([folder filesep app.nms{ii} app.nmsx{ii} '.ref'],'wb');
                    fwrite(pumpum,vec,'single');
                    fclose(pumpum);
                    d=barraprogress(app,ii/numel(app.nms),d,'Saving...');
                end
                d=barraprogress(app,1,d,'Saving...');
                pause(.1);
                try
                    close(d);
                end
            end

            ensenya('Finished!','e');
        end

        % Button pushed function: SavemaskButton
        function SavemaskButtonPushed(app, event)
            try
                rang=checkedFiles(app);
                if(numel(rang)==1)
                    [name,folder] = uisaveFile('Save current mask to file','*.png');
                    imwrite(app.MKL{rang(1)},[folder name]);
                end
                if(numel(rang)>1)
                    [name,folder] = uisaveFile('Save current mask to file','*.tif');
                    for ii=1:numel(rang)
                        if(ii==1)
                            imwrite(app.MKL{rang(ii)},[folder name]);
                        else
                            imwrite(app.MKL{rang(ii)},[folder name],'WriteMode','append');
                        end
                    end
                end
            end
        end

        % Menu selected function: CurrentSessionMenu
        function SaveCurrentSessionMenuSelected(app, event)
            [name,folder] = uisaveFile('Save current session to file');
            if(ischar(name))
                saveParams(app,[folder name]);
            end
        end

        % Value changed function: singleimage
        function singleimageValueChanged(app, event)
            if(app.singleimage.Value==1)&&(app.singleimage.Enable==1)
                app.addmargins.Enable=1;
                app.groupfile.Enable=1;
                app.mergecomps.Enable=1;
            else
                app.addmargins.Enable=0;
                app.groupfile.Enable=0;
                app.mergecomps.Enable=0;
            end
        end

        % Value changed function: SoftCursor
        function SoftCursorValueChanged(app, event)
            replotII(app);
        end

        % Selection changed function: SourceButtonGroup
        function SourceButtonGroupSelectionChanged(app, event)
            if(app.FileButton.Value==1)
                app.ComponentTree.Visible=1;
                app.ComponentTree.Enable=1;
                delete(app.ComponentTree.Children);
                for ii=1:numel(app.Tree.Children)
                    aux=uitreenode(app.ComponentTree,'Text',app.Tree.Children(ii).Text,'NodeData',app.Tree.Children(ii).NodeData);
                end
                app.compNum=app.compNumE;
                for ii=1:8
                    h=app.comphans(ii,2);
                    set(h,'Visible',0);
                end
            end
            if(app.InputButton.Value==1)
                app.ComponentTree.Visible=0;
                app.compNum=app.compNumT;
                app.plusB.Enable=1;
                app.minusB.Enable=1;

                for ii=1:8
                    h=app.comphans(ii,2);
                    set(h,'Visible',0);
                end
                for ii=1:app.compNum
                    h=app.comphans(ii,2);
                    set(h,'Visible',1);
                end
                componentLifeChange(app,[],[],[1:app.compNum]);
            end
            if(app.CursorButton.Value==1)
                for ii=1:8
                    h=app.comphans(ii,2);
                    set(h,'Visible',0);
                end
                app.ComponentTree.Visible=1;
                app.ComponentTree.Enable=0;
                delete(app.ComponentTree.Children);

                inds=find(app.roidad(:,3)>0);
                app.compNum=numel(inds);
                if(app.compNum>3),app.compNum=3;end
                for ii=1:app.compNum
                    aux=uitreenode(app.ComponentTree,'Text',['Cursor #' num2str(ii)]);
                end
                try
                    app.ComponentTree.CheckedNodes=app.ComponentTree.Children;
                end
                componentTreeColors(app);
                componentLifeChange(app,[],[],[1:app.compNum]);
            end


        end

        % Value changed function: ShowComponents
        function ShowComponentsValueChanged(app, event)
            replotPP(app);
        end

        % Selection changed function: Style
        function StyleSelectionChanged(app, event)

            if(strcmp(app.Style.SelectedObject.Text,'Lifetime'))
                app.UCcb.Value=1;
                app.Smin.Value=0;app.Smax.Value=.6;
                app.Gmin.Value=0;app.Gmax.Value=1;
                app.sBandLab.Visible=0;
                app.SpecMin.Visible=0;
                app.SpecMax.Visible=0;
                app.freqEF.Visible=1;
                app.freqLab.Visible=1;
                if(app.LoadedIms>0)
                    app.Cmin.Value=0;app.Cmax.Value=.5;
                end
            end
            if(strcmp(app.Style.SelectedObject.Text,'Spectral'))
                app.UCcb.Value=1;
                app.Smin.Value=-1;app.Smax.Value=1;
                app.Gmin.Value=-1;app.Gmax.Value=1;
                app.sBandLab.Visible=1;
                app.SpecMin.Visible=1;
                app.SpecMax.Visible=1;
                app.freqEF.Visible=0;
                app.freqLab.Visible=0;
                if(app.LoadedIms>0)
                    app.Cmin.Value=0;app.Cmax.Value=1;
                end
            end
            if(strcmp(app.Style.SelectedObject.Text,'Umap'))
                app.UCcb.Value=0;
                app.Smin.Value=-7;app.Smax.Value=8.5;
                app.Gmin.Value=-11.5;app.Gmax.Value=11.5;
                if(app.LoadedIms>0)
                    app.Cmin.Value=-.15;app.Cmax.Value=.55;
                end
            end
            app.radi=max([app.Smax.Value-app.Smin.Value app.Gmax.Value-app.Gmin.Value])/20;
            FilterAndPlotPhasor(app);

        end

        % Value changed function: SpecMin
        function SpecMinValueChanged(app, event)
            replotPP(app);
        end

        % Value changed function: SpecMax
        function SpecMaxValueChanged(app, event)
            replotPP(app);
        end

        % Value changed function: ticks
        function ticksValueChanged(app, event)
            replotPP(app);
            if(app.ticks.Value==0)
                app.tickfontsize.Enable=0;
                app.tickNumber.Enable=0;
            else
                app.tickfontsize.Enable=1;
                app.tickNumber.Enable=1;
            end
        end

        % Value changed function: tickNumber
        function tickNumberValueChanged(app, event)
            replotPP(app);
        end

        % Button pushed function: TomaskButton
        function TomaskButtonPushed(app, event)
            % see what nodes are checked
            rang=checkedFiles(app);
            if(numel(rang)>0)
                % if(numel(rang)>1)
                %     ensenya('Warning: Creating mask for first checked image on File panel!','a');
                % end

                for rr=rang
                    if(strcmp(app.masktype.Value,'Threshold'))


                        MKt=app.IIi{rr}>0;

                        % MK=app.IIa{rr};
                        % if(app.relative.Value==1)
                        %     MK=MK/max(MK(:));
                        % end
                        % if(app.Excess.Value==0)
                        %     MK=MK>app.Imin.Value;
                        % else
                        %     MK=(MK>app.Imin.Value)&(MK<app.Imax.Value);
                        % end
                    else
                        if(app.Applymask.Value==1)
                            % current SnG are already cropped by mask!
                            ApplymaskValueChanged(app);
                        end
                        MKt=zeros(size(app.GGi{rr}));
                        for ii=1:size(app.roidad,1)
                            if(app.roidad(ii,3)>0)
                                S1=app.SSi{rr}-app.roidad(ii,2);
                                G1=app.GGi{rr}-app.roidad(ii,1);
                                aux=(S1.*S1)+(G1.*G1)-app.roidad(ii,3)^2;
                                MKt=MKt+double(aux<0);%figure,imagesc(MK)
                            end
                        end
                        MKt=MKt>0;%figure,imagesc(MK)
                    end
                    app.MKL{rr}=MKt;
                end
                mostramask(app);
                ApplymaskValueChanged(app);
                % app.MK{rang(1)};
            end
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            if(app.CalibHolder.Visible==1)
                hidecalibButtonPushed(app);
            end
        end

        % Callback function: Tree
        function TreeCheckedNodesChanged(app, event)
            rang=checkedFiles(app);
            if(numel(setdiff(rang,app.curp))>0)
                FilterAndPlotPhasor(app);
            end
            replotPP(app);
            replotII(app);
            plotHists(app);
        end

        % Button pushed function: X
        function XButtonPushed(app, event)
            app.currCol=1;
            app.roidad(:,:)=0;
            FilterAndPlotPhasor(app);

            % app.curs1.Color=app.colMks(app.currCol,:);
            %
            % clearMasks(app)
            %  replotII(app);

            app.Botonera.Visible=0;
        end

        % Value changed function: GaussianFilter
        function GaussianFilterValueChanged(app, event)
            FilterAndPlotPhasor(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.GSLabUIFigure = uifigure('Visible', 'off');
            app.GSLabUIFigure.Name = 'GSLab';
            app.GSLabUIFigure.Icon = fullfile(pathToMLAPP, 'scripts', 'logo1.png');
            app.GSLabUIFigure.CloseRequestFcn = createCallbackFcn(app, @GSLabUIFigureCloseRequest, true);
            app.GSLabUIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @GSLabUIFigureWindowButtonMotion, true);
            app.GSLabUIFigure.WindowScrollWheelFcn = createCallbackFcn(app, @GSLabUIFigureWindowScrollWheel, true);
            app.GSLabUIFigure.WindowKeyPressFcn = createCallbackFcn(app, @GSLabUIFigureWindowKeyPress, true);
            app.PhasorPanel = uipanel(app.GSLabUIFigure);
            app.PP = uiaxes(app.PhasorPanel);
            app.PP.YLim = [0 0.6];
            app.PP.ButtonDownFcn = createCallbackFcn(app, @PPButtonDown, true);
            app.coolab = uilabel(app.PhasorPanel);
            app.coolab.Text = '';
            app.Botonera = uipanel(app.GSLabUIFigure);
            app.Botonera.Visible = 'off';
            app.X = uibutton(app.Botonera, 'push');
            app.X.ButtonPushedFcn = createCallbackFcn(app, @XButtonPushed, true);
            app.X.Tooltip = {'Clear cursors'};
            app.X.Text = 'x';
            app.TabGroup = uitabgroup(app.GSLabUIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.FileTab = uitab(app.TabGroup);
            app.FileTab.Title = 'File';
            app.Tree = uitree(app.FileTab, 'checkbox');
            % Assign Checked Nodes
            app.Tree.CheckedNodesChangedFcn = createCallbackFcn(app, @TreeCheckedNodesChanged, true);
            app.hidecalib = uibutton(app.FileTab, 'push');
            app.hidecalib.ButtonPushedFcn = createCallbackFcn(app, @hidecalibButtonPushed, true);
            app.hidecalib.Tooltip = {'Calibration panel'};
            app.hidecalib.Text = '▴';
            app.ImageTab = uitab(app.TabGroup);
            app.ImageTab.Title = 'Image';
            app.histax = uiaxes(app.ImageTab);
            app.histax.XTick = [];
            app.histax.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.histax.YTickLabel = '';
            app.MKax = uiaxes(app.ImageTab);
            app.MKax.XTick = [];
            app.MKax.YTick = [];
            app.Imax = uieditfield(app.ImageTab, 'numeric');
            app.Imax.Limits = [0 1];
            app.Imax.ValueDisplayFormat = '%.2f';
            app.Imax.ValueChangedFcn = createCallbackFcn(app, @ImaxValueChanged, true);
            app.Imax.Tooltip = {'Upper value for intensity normalisation (over unity)'};
            app.Imax.Value = 1;
            app.Imin = uieditfield(app.ImageTab, 'numeric');
            app.Imin.Limits = [0 1];
            app.Imin.ValueDisplayFormat = '%.2f';
            app.Imin.ValueChangedFcn = createCallbackFcn(app, @IminValueChanged, true);
            app.Imin.Tooltip = {'Lower value for intensity thresholding (over unity)'};
            app.IntensityLabel = uilabel(app.ImageTab);
            app.IntensityLabel.Text = 'Intensity threshold';
            app.Islide = uislider(app.ImageTab, 'range');
            app.Islide.Limits = [0 1];
            app.Islide.MajorTicks = [];
            app.Islide.ValueChangedFcn = createCallbackFcn(app, @IslideValueChanged, true);
            app.Islide.Value = [0 1];
            app.Excess = uicheckbox(app.ImageTab);
            app.Excess.ValueChangedFcn = createCallbackFcn(app, @ExcessValueChanged, true);
            app.Excess.Tooltip = {'Exclude pixels above upper range value from analysis'};
            app.Excess.Text = 'Excess to zero';
            app.Logp = uicheckbox(app.ImageTab);
            app.Logp.ValueChangedFcn = createCallbackFcn(app, @LogpValueChanged, true);
            app.Logp.Tooltip = {'Plot logarithmic counts'};
            app.Logp.Text = 'Log plot';
            app.TomaskButton = uibutton(app.ImageTab, 'push');
            app.TomaskButton.ButtonPushedFcn = createCallbackFcn(app, @TomaskButtonPushed, true);
            app.TomaskButton.Tooltip = {'Generate binary image as mask'};
            app.TomaskButton.Text = 'To mask';
            app.SavemaskButton = uibutton(app.ImageTab, 'push');
            app.SavemaskButton.ButtonPushedFcn = createCallbackFcn(app, @SavemaskButtonPushed, true);
            app.SavemaskButton.Tooltip = {'Save current mask as image'};
            app.SavemaskButton.Text = 'Save mask';
            app.ChanButton3 = uibutton(app.ImageTab, 'push');
            app.ChanButton3.ButtonPushedFcn = createCallbackFcn(app, @ChanButton3Pushed, true);
            app.ChanButton3.Enable = 'off';
            app.ChanButton3.Tooltip = {'Generate simulated 3Chan image from FLIM data'};
            app.ChanButton3.Text = '3Chan';
            app.ChanButton4 = uibutton(app.ImageTab, 'push');
            app.ChanButton4.ButtonPushedFcn = createCallbackFcn(app, @ChanButton4Pushed, true);
            app.ChanButton4.Enable = 'off';
            app.ChanButton4.Tooltip = {'Generate simulated 4Chan image from FLIM data'};
            app.ChanButton4.Text = '4Chan';
            app.RGBLabel = uilabel(app.ImageTab);
            app.RGBLabel.Enable = 'off';
            app.RGBLabel.Text = 'RGB ';
            app.relative = uicheckbox(app.ImageTab);
            app.relative.ValueChangedFcn = createCallbackFcn(app, @relativeValueChanged, true);
            app.relative.Tooltip = {'Indicate values relative to each image maximum or absolute intensity'};
            app.relative.Text = 'Relative';
            app.relative.Value = true;
            app.Applymask = uicheckbox(app.ImageTab);
            app.Applymask.ValueChangedFcn = createCallbackFcn(app, @ApplymaskValueChanged, true);
            app.Applymask.Text = 'Apply mk';
            app.invertmk = uicheckbox(app.ImageTab);
            app.invertmk.ValueChangedFcn = createCallbackFcn(app, @invertmkValueChanged, true);
            app.invertmk.Tooltip = {'Invert current mask'};
            app.invertmk.Text = 'Invert';
            app.logint = uicheckbox(app.ImageTab);
            app.logint.ValueChangedFcn = createCallbackFcn(app, @LogIntValueChanged, true);
            app.logint.Tooltip = {'Render in log intensity'};
            app.logint.Text = 'Log intensity';
            app.hdr = uicheckbox(app.ImageTab);
            app.hdr.ValueChangedFcn = createCallbackFcn(app, @HDRValueChanged, true);
            app.hdr.Tooltip = {'Compute windowed dynamic range (experimental)'};
            app.hdr.Text = 'HDR int';
            app.masktype = uidropdown(app.ImageTab);
            app.masktype.Items = {'Threshold', 'Cursors'};
            app.masktype.Tooltip = {'Source for generating mask'};
            app.masktype.Value = 'Threshold';
            app.LoadmaskButton = uibutton(app.ImageTab, 'push');
            app.LoadmaskButton.ButtonPushedFcn = createCallbackFcn(app, @LoadmaskButtonPushed, true);
            app.LoadmaskButton.Tooltip = {'Use binarized version as mask (first checked image)'};
            app.LoadmaskButton.Text = 'Load mask';
            app.GaussianFilter = uieditfield(app.ImageTab, 'numeric');
            app.GaussianFilter.Limits = [0 100];
            app.GaussianFilter.ValueDisplayFormat = '%.1f';
            app.GaussianFilter.ValueChangedFcn = createCallbackFcn(app, @GaussianFilterValueChanged, true);
            app.GaussianFilter.Tooltip = {'Lower value for intensity thresholding (over unity)'};
            app.IntensityLabel_2 = uilabel(app.ImageTab);
            app.IntensityLabel_2.Text = 'Gaussian filter';
            app.PhasorTab = uitab(app.TabGroup);
            app.PhasorTab.Title = 'Phasor';
            app.Eb = uieditfield(app.PhasorTab, 'numeric');
            app.Eb.Limits = [0 Inf];
            app.Eb.RoundFractionalValues = 'on';
            app.Eb.ValueChangedFcn = createCallbackFcn(app, @FilterSizeValueChanged, true);
            app.Eb.Tooltip = {'Pixel size for median filter'};
            app.Eb.Value = 5;
            app.Ti = uieditfield(app.PhasorTab, 'numeric');
            app.Ti.Limits = [0 Inf];
            app.Ti.RoundFractionalValues = 'on';
            app.Ti.ValueChangedFcn = createCallbackFcn(app, @FilterTimesValueChanged, true);
            app.Ti.Tooltip = {'Number of iterations'};
            app.Ti.Value = 1;
            app.Smin = uieditfield(app.PhasorTab, 'numeric');
            app.Smin.ValueDisplayFormat = '%.2f';
            app.Smin.ValueChangedFcn = createCallbackFcn(app, @CoordSminChanged, true);
            app.Smin.Tooltip = {'Lower limit for display'};
            app.Smax = uieditfield(app.PhasorTab, 'numeric');
            app.Smax.ValueDisplayFormat = '%.2f';
            app.Smax.ValueChangedFcn = createCallbackFcn(app, @CoordSmaxChanged, true);
            app.Smax.Tooltip = {'Upper limit for display'};
            app.Smax.Value = 0.5;
            app.Gmin = uieditfield(app.PhasorTab, 'numeric');
            app.Gmin.ValueDisplayFormat = '%.2f';
            app.Gmin.ValueChangedFcn = createCallbackFcn(app, @CoordGminChanged, true);
            app.Gmin.Tooltip = {'Lower limit for display'};
            app.Gmax = uieditfield(app.PhasorTab, 'numeric');
            app.Gmax.ValueDisplayFormat = '%.2f';
            app.Gmax.ValueChangedFcn = createCallbackFcn(app, @CoordGmaxChanged, true);
            app.Gmax.Tooltip = {'Upper limit for display'};
            app.Gmax.Value = 1;
            app.Cmin = uieditfield(app.PhasorTab, 'numeric');
            app.Cmin.ValueDisplayFormat = '%.2f';
            app.Cmin.ValueChangedFcn = createCallbackFcn(app, @CoordCminChanged, true);
            app.Cmin.Tooltip = {'Lower limit in normalized units of a circle'};
            app.Cmax = uieditfield(app.PhasorTab, 'numeric');
            app.Cmax.ValueDisplayFormat = '%.2f';
            app.Cmax.ValueChangedFcn = createCallbackFcn(app, @CoordCmaxChanged, true);
            app.Cmax.Tooltip = {'Upper limit in normalized units of a circle'};
            app.Cmax.Value = 0.5;
            app.Gcenter = uieditfield(app.PhasorTab, 'numeric');
            app.Gcenter.ValueChangedFcn = createCallbackFcn(app, @CoordGcenterChanged, true);
            app.Gcenter.Enable = 'off';
            app.Gcenter.Tooltip = {'G coordinate of color gradient center'};
            app.Gcenter.Value = 0.5;
            app.EbLabel = uilabel(app.PhasorTab);
            app.EbLabel.Text = 'Phasor filtering';
            app.Scenter = uieditfield(app.PhasorTab, 'numeric');
            app.Scenter.ValueChangedFcn = createCallbackFcn(app, @CoordScenterChanged, true);
            app.Scenter.Enable = 'off';
            app.Scenter.Tooltip = {'S coordinate of color gradient center'};
            app.CenterLabel = uilabel(app.PhasorTab);
            app.CenterLabel.Enable = 'off';
            app.CenterLabel.Text = 'Centering';
            app.IterationsLabel = uilabel(app.PhasorTab);
            app.IterationsLabel.Text = 'Times';
            app.SrangeEditFieldLabel = uilabel(app.PhasorTab);
            app.SrangeEditFieldLabel.Text = 'S range';
            app.GrangeEditFieldLabel = uilabel(app.PhasorTab);
            app.GrangeEditFieldLabel.Text = 'G range';
            app.ColorrangeEditFieldLabel = uilabel(app.PhasorTab);
            app.ColorrangeEditFieldLabel.Text = 'Color range';
            app.MeasuredmagnitudeButtonGroup = uibuttongroup(app.PhasorTab);
            app.MeasuredmagnitudeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @MagnitudeButtonGroupSelectionChanged, true);
            app.MeasuredmagnitudeButtonGroup.Title = 'Measured magnitude';
            app.Srad = uiradiobutton(app.MeasuredmagnitudeButtonGroup);
            app.Srad.Tooltip = {'Measure S coordinate'};
            app.Srad.Text = 'S';
            app.Grad = uiradiobutton(app.MeasuredmagnitudeButtonGroup);
            app.Grad.Tooltip = {'Measure G coordinate'};
            app.Grad.Text = 'G';
            app.Phaserad = uiradiobutton(app.MeasuredmagnitudeButtonGroup);
            app.Phaserad.Tooltip = {'Phase projection'};
            app.Phaserad.Text = 'Phase';
            app.TauPhaserad = uiradiobutton(app.MeasuredmagnitudeButtonGroup);
            app.TauPhaserad.Tooltip = {'Tau phase projection'};
            app.TauPhaserad.Text = 'TauPhase';
            app.Modrad = uiradiobutton(app.MeasuredmagnitudeButtonGroup);
            app.Modrad.Tooltip = {'Modulation projection'};
            app.Modrad.Text = 'Mod';
            app.TauModrad = uiradiobutton(app.MeasuredmagnitudeButtonGroup);
            app.TauModrad.Tooltip = {'Tau modulation projection'};
            app.TauModrad.Text = 'TauMod';
            app.NormPhaserad = uiradiobutton(app.MeasuredmagnitudeButtonGroup);
            app.NormPhaserad.Tooltip = {'Pseudo-phase projection centered around [.5 0]'};
            app.NormPhaserad.Text = 'NormPhase';
            app.TauNormrad = uiradiobutton(app.MeasuredmagnitudeButtonGroup);
            app.TauNormrad.Tooltip = {'Tau-pseudo-phase projection centered around [.5 0]'};
            app.TauNormrad.Text = 'TauNorm';
            app.TauNormrad.Value = true;
            app.CustomRadial = uiradiobutton(app.MeasuredmagnitudeButtonGroup);
            app.CustomRadial.Tooltip = {'Input center for modulation-type projection'};
            app.CustomRadial.Text = 'CustomRadial';
            app.CustomAngular = uiradiobutton(app.MeasuredmagnitudeButtonGroup);
            app.CustomAngular.Tooltip = {'Input center for phase-type projection'};
            app.CustomAngular.Text = 'CustomAngular';
            app.Filtermatch = uilabel(app.PhasorTab);
            app.Filtermatch.Text = ' x 5';
            app.MaxharmonicsEditFieldLabel = uilabel(app.PhasorTab);
            app.MaxharmonicsEditFieldLabel.Text = 'Max harmonics';
            app.MaxharmonicsEditField = uieditfield(app.PhasorTab, 'numeric');
            app.MaxharmonicsEditField.Limits = [1 Inf];
            app.MaxharmonicsEditField.ValueDisplayFormat = '%.0f';
            app.MaxharmonicsEditField.ValueChangedFcn = createCallbackFcn(app, @MaxharmonicsEditFieldValueChanged, true);
            app.MaxharmonicsEditField.Tooltip = {'Computed harmonics (requires reloading)'};
            app.MaxharmonicsEditField.Value = 2;
            app.PhasorresolutionEditFieldLabel = uilabel(app.PhasorTab);
            app.PhasorresolutionEditFieldLabel.Text = 'Phasor resolution';
            app.PhasorresolutionEditField = uieditfield(app.PhasorTab, 'numeric');
            app.PhasorresolutionEditField.Limits = [1 1000000];
            app.PhasorresolutionEditField.ValueDisplayFormat = '%.0f';
            app.PhasorresolutionEditField.ValueChangedFcn = createCallbackFcn(app, @PhasorresolutionEditFieldValueChanged, true);
            app.PhasorresolutionEditField.Tooltip = {'Resolution in points per G range'};
            app.PhasorresolutionEditField.Value = 256;
            app.PlotTrueSize = uibutton(app.PhasorTab, 'push');
            app.PlotTrueSize.ButtonPushedFcn = createCallbackFcn(app, @PlotTrueSizeButtonPushed, true);
            app.PlotTrueSize.Tooltip = {'Compute full resolution phasor '};
            app.PlotTrueSize.Text = 'Plot HR';
            app.Filterbefore = uicheckbox(app.PhasorTab);
            app.Filterbefore.Tooltip = {'Filter before or after thresholding'};
            app.Filterbefore.Text = 'Filter before threshold';
            app.DownsamplingEditFieldLabel = uilabel(app.PhasorTab);
            app.DownsamplingEditFieldLabel.Text = 'Downsampling';
            app.DownsamplingEditField = uieditfield(app.PhasorTab, 'numeric');
            app.DownsamplingEditField.Limits = [0 2000];
            app.DownsamplingEditField.ValueDisplayFormat = '%.0f';
            app.DownsamplingEditField.ValueChangedFcn = createCallbackFcn(app, @DownsamplingEditFieldValueChanged, true);
            app.DownsamplingEditField.Tooltip = {'Downsample image size for fast computing (zero for true sizes)'};
            app.DownsamplingEditField.Value = 256;
            app.AutoButton = uibutton(app.PhasorTab, 'push');
            app.AutoButton.ButtonPushedFcn = createCallbackFcn(app, @MagnitudeAutoColorButtonPushed, true);
            app.AutoButton.Tooltip = {'Adjust color range to fit phasor data'};
            app.AutoButton.Text = 'Auto';
            app.SaveCursorsButton = uibutton(app.PhasorTab, 'push');
            app.SaveCursorsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveCursorsButtonPushed, true);
            app.SaveCursorsButton.Tooltip = {'Save current cursors'};
            app.SaveCursorsButton.Text = 'Save Cursors';
            app.LoadCursorsButton = uibutton(app.PhasorTab, 'push');
            app.LoadCursorsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadCursorsButtonPushed, true);
            app.LoadCursorsButton.Tooltip = {'Load set of cursors'};
            app.LoadCursorsButton.Text = 'Load Cursors';
            app.DisplayTab = uitab(app.TabGroup);
            app.DisplayTab.Title = 'Display';
            app.HarmonicLabel = uilabel(app.DisplayTab);
            app.HarmonicLabel.Text = 'Harmonic #';
            app.AxesfontsizeEditFieldLabel_2 = uilabel(app.DisplayTab);
            app.AxesfontsizeEditFieldLabel_2.Text = 'Axes font size';
            app.fontsizeLabel = uilabel(app.DisplayTab);
            app.fontsizeLabel.Text = 'Tick font size';
            app.Style = uibuttongroup(app.DisplayTab);
            app.Style.SelectionChangedFcn = createCallbackFcn(app, @StyleSelectionChanged, true);
            app.LifetimeStyle = uiradiobutton(app.Style);
            app.LifetimeStyle.Tooltip = {'Set phasor limits for lifetime'};
            app.LifetimeStyle.Text = 'Lifetime';
            app.LifetimeStyle.Value = true;
            app.SpectralStyle = uiradiobutton(app.Style);
            app.SpectralStyle.Tooltip = {'Set phasor limits for spectra'};
            app.SpectralStyle.Text = 'Spectral';
            app.UmapStyle = uiradiobutton(app.Style);
            app.UmapStyle.Tooltip = {'Set phasor limits for umap'};
            app.UmapStyle.Text = 'Umap';
            app.PhasordensitycolorLabel = uilabel(app.DisplayTab);
            app.PhasordensitycolorLabel.Text = 'Density colormap';
            app.PhasorcolormapDD = uidropdown(app.DisplayTab);
            app.PhasorcolormapDD.ValueChangedFcn = createCallbackFcn(app, @CmapPhdensDDValueChanged, true);
            app.PhasorcolormapDD.Tooltip = {'Colormap for phasor density heat map'};
            app.PhasordensitycolorLabel_2 = uilabel(app.DisplayTab);
            app.PhasordensitycolorLabel_2.Text = 'Magnitude colormap';
            app.RainbowcolormapDD = uidropdown(app.DisplayTab);
            app.RainbowcolormapDD.ValueChangedFcn = createCallbackFcn(app, @CmapMagDDValueChanged, true);
            app.RainbowcolormapDD.Tooltip = {'Colormap for phasor measurement '};
            app.logDens = uicheckbox(app.DisplayTab);
            app.logDens.ValueChangedFcn = createCallbackFcn(app, @logDensValueChanged, true);
            app.logDens.Tooltip = {'Display phasor data as log of density'};
            app.logDens.Text = 'Log density';
            app.normFile = uicheckbox(app.DisplayTab);
            app.normFile.ValueChangedFcn = createCallbackFcn(app, @normFileValueChanged, true);
            app.normFile.Tooltip = {'Normalize density to each file'};
            app.normFile.Text = 'Normalize by file';
            app.normFile.Value = true;
            app.contours = uicheckbox(app.DisplayTab);
            app.contours.ValueChangedFcn = createCallbackFcn(app, @DispContoursValueChanged, true);
            app.contours.Tooltip = {'Add contours'};
            app.contours.Text = 'Contours';
            app.UCcb = uicheckbox(app.DisplayTab);
            app.UCcb.ValueChangedFcn = createCallbackFcn(app, @LinesCheckBoxValueChanged, true);
            app.UCcb.Tooltip = {'Show lines in phasor plot'};
            app.UCcb.Text = 'Lines';
            app.UCcb.Value = true;
            app.ticks = uicheckbox(app.DisplayTab);
            app.ticks.ValueChangedFcn = createCallbackFcn(app, @ticksValueChanged, true);
            app.ticks.Tooltip = {'Show ticks in phasor plot'};
            app.ticks.Text = 'Ticks';
            app.ticks.Value = true;
            app.tickNumber = uieditfield(app.DisplayTab, 'numeric');
            app.tickNumber.Limits = [0 41];
            app.tickNumber.ValueChangedFcn = createCallbackFcn(app, @tickNumberValueChanged, true);
            app.tickNumber.Tooltip = {'Number of ticks'};
            app.tickNumber.Value = 10;
            app.Harm = uieditfield(app.DisplayTab, 'numeric');
            app.Harm.Limits = [1 Inf];
            app.Harm.ValueDisplayFormat = '%.0f';
            app.Harm.ValueChangedFcn = createCallbackFcn(app, @HarmValueChanged, true);
            app.Harm.Tooltip = {'Phasor transform harmonic number'};
            app.Harm.Value = 1;
            app.tickfontsize = uieditfield(app.DisplayTab, 'numeric');
            app.tickfontsize.ValueChangedFcn = createCallbackFcn(app, @FontSizeLineValueChanged, true);
            app.tickfontsize.Tooltip = {'Tick font size'};
            app.tickfontsize.Value = 10;
            app.AFSef = uieditfield(app.DisplayTab, 'numeric');
            app.AFSef.ValueChangedFcn = createCallbackFcn(app, @FontSizeAxesValueChanged, true);
            app.AFSef.Tooltip = {'Axes font size'};
            app.AFSef.Value = 14;
            app.PhBg = uicheckbox(app.DisplayTab);
            app.PhBg.ValueChangedFcn = createCallbackFcn(app, @PhBgValueChanged, true);
            app.PhBg.Tooltip = {'Display color projection for phasor measurement'};
            app.PhBg.Text = 'Magnitude background color';
            app.PhBg.Value = true;
            app.SoftCursor = uicheckbox(app.DisplayTab);
            app.SoftCursor.ValueChangedFcn = createCallbackFcn(app, @SoftCursorValueChanged, true);
            app.SoftCursor.Tooltip = {'Hard mask is simFCS style, soft is mask times intensity'};
            app.SoftCursor.Text = 'Soft Masks';
            app.imagewindowtile = uicheckbox(app.DisplayTab);
            app.imagewindowtile.ValueChangedFcn = createCallbackFcn(app, @imagewindowtileValueChanged, true);
            app.imagewindowtile.Tooltip = {'Force image window panel distribution'};
            app.imagewindowtile.Text = 'Image Window tiling ';
            app.tilinglabel = uilabel(app.DisplayTab);
            app.tilinglabel.Text = 'x      ';
            app.imagerows = uieditfield(app.DisplayTab, 'numeric');
            app.imagerows.Limits = [1 Inf];
            app.imagerows.ValueDisplayFormat = '%.0f';
            app.imagerows.ValueChangedFcn = createCallbackFcn(app, @imagerowsValueChanged, true);
            app.imagerows.Tooltip = {'Number of rows in image window'};
            app.imagerows.Value = 1;
            app.imagecols = uieditfield(app.DisplayTab, 'numeric');
            app.imagecols.Limits = [1 Inf];
            app.imagecols.ValueDisplayFormat = '%.0f';
            app.imagecols.ValueChangedFcn = createCallbackFcn(app, @imagecolsValueChanged, true);
            app.imagecols.Tooltip = {'Number of columns in image window'};
            app.imagecols.Value = 1;
            app.margns = uicheckbox(app.DisplayTab);
            app.margns.ValueChangedFcn = createCallbackFcn(app, @DisplayMargnsValueChanged, true);
            app.margns.Tooltip = {'Add margin between image panels'};
            app.margns.Text = 'Margins';
            app.margns.Value = true;
            app.fliprows = uicheckbox(app.DisplayTab);
            app.fliprows.ValueChangedFcn = createCallbackFcn(app, @DisplayfliprowsValueChanged, true);
            app.fliprows.Tooltip = {'Render images always left to right or in zig-zag mode'};
            app.fliprows.Text = 'Flip even rows';
            app.fontsizeLabel_2 = uilabel(app.DisplayTab);
            app.fontsizeLabel_2.Text = 'Tick #';
            app.CursorcolorsPanel = uipanel(app.DisplayTab);
            app.CursorcolorsPanel.Title = 'Cursor colors';
            app.sBandLab = uilabel(app.DisplayTab);
            app.sBandLab.Text = 'Spectral band                          nm';
            app.SpecMin = uieditfield(app.DisplayTab, 'numeric');
            app.SpecMin.Limits = [0 Inf];
            app.SpecMin.ValueDisplayFormat = '%.0f';
            app.SpecMin.ValueChangedFcn = createCallbackFcn(app, @SpecMinValueChanged, true);
            app.SpecMin.Tooltip = {'Lower limit for speectral band'};
            app.SpecMin.Value = 400;
            app.SpecMax = uieditfield(app.DisplayTab, 'numeric');
            app.SpecMax.Limits = [0 Inf];
            app.SpecMax.ValueDisplayFormat = '%.0f';
            app.SpecMax.ValueChangedFcn = createCallbackFcn(app, @SpecMaxValueChanged, true);
            app.SpecMax.Tooltip = {'Upper limit for speectral band'};
            app.SpecMax.Value = 700;
            app.freqLab = uilabel(app.DisplayTab);
            app.freqLab.Text = 'Source frequency            MHz';
            app.freqEF = uieditfield(app.DisplayTab, 'numeric');
            app.freqEF.Limits = [1 Inf];
            app.freqEF.ValueDisplayFormat = '%.0f';
            app.freqEF.ValueChangedFcn = createCallbackFcn(app, @freqEFValueChanged, true);
            app.freqEF.Tooltip = {'Frequency of excitation source'};
            app.freqEF.Value = 80;
            app.CmapFlipDens = uicheckbox(app.DisplayTab);
            app.CmapFlipDens.ValueChangedFcn = createCallbackFcn(app, @CmapFlipDensValueChanged, true);
            app.CmapFlipDens.Tooltip = {'Flip color order'};
            app.CmapFlipDens.Text = 'Flip';
            app.CmapFlipMag = uicheckbox(app.DisplayTab);
            app.CmapFlipMag.ValueChangedFcn = createCallbackFcn(app, @CmapFlipMagValueChanged, true);
            app.CmapFlipMag.Tooltip = {'Flip color order'};
            app.CmapFlipMag.Text = 'Flip';
            app.UnmixTab = uitab(app.TabGroup);
            app.UnmixTab.Title = 'Unmix';
            app.ComponentsPanel = uipanel(app.UnmixTab);
            app.ComponentsPanel.Title = 'Components';
            app.plusB = uibutton(app.ComponentsPanel, 'push');
            app.plusB.ButtonPushedFcn = createCallbackFcn(app, @ComponentPlusButtonPressed, true);
            app.plusB.Tooltip = {'Add component'};
            app.plusB.Text = '+';
            app.minusB = uibutton(app.ComponentsPanel, 'push');
            app.minusB.ButtonPushedFcn = createCallbackFcn(app, @ComponentMinusButtonPressed, true);
            app.minusB.Tooltip = {'Remove component'};
            app.minusB.Text = '-';
            app.ShowComponents = uicheckbox(app.UnmixTab);
            app.ShowComponents.ValueChangedFcn = createCallbackFcn(app, @ShowComponentsValueChanged, true);
            app.ShowComponents.Tooltip = {'Display component positions on phasor plot'};
            app.ShowComponents.Text = 'Draw components';
            app.ShowComponents.Value = true;
            app.AddLines = uicheckbox(app.UnmixTab);
            app.AddLines.ValueChangedFcn = createCallbackFcn(app, @ComponentLinesValueChanged, true);
            app.AddLines.Tooltip = {'Display lines between components on phasor plot'};
            app.AddLines.Text = 'Add lines';
            app.AddLines.Value = true;
            app.ComputeHR = uibutton(app.UnmixTab, 'push');
            app.ComputeHR.ButtonPushedFcn = createCallbackFcn(app, @ComputeHRButtonPushed, true);
            app.ComputeHR.Tooltip = {'Launch unmixing with raw full resolution'};
            app.ComputeHR.Text = 'Compute HR';
            app.ComputeLR = uibutton(app.UnmixTab, 'push');
            app.ComputeLR.ButtonPushedFcn = createCallbackFcn(app, @ComputeLRButtonPushed, true);
            app.ComputeLR.Tooltip = {'Launch unmixing with current display resolution'};
            app.ComputeLR.Text = 'Compute LR';
            app.CompImsExp = uicheckbox(app.UnmixTab);
            app.CompImsExp.ValueChangedFcn = createCallbackFcn(app, @CompImsExpValueChanged, true);
            app.CompImsExp.Tooltip = {'Export pixel component fraction as image'};
            app.CompImsExp.Text = 'Export fraction im';
            app.CompImsExp.Value = true;
            app.CompImsIntExp = uicheckbox(app.UnmixTab);
            app.CompImsIntExp.Tooltip = {'Export images as pixel intensity times fraction (true component photon intensity) versus normalized fraction.'};
            app.CompImsIntExp.Text = 'Export fraction x int';
            app.CompImsCSVExp = uicheckbox(app.UnmixTab);
            app.CompImsCSVExp.ValueChangedFcn = createCallbackFcn(app, @CompImsCSVExpValueChanged, true);
            app.CompImsCSVExp.Tooltip = {'Export pixel fraction as spreadsheet'};
            app.CompImsCSVExp.Text = 'Export fraction csv';
            app.CompImsCol = uicheckbox(app.UnmixTab);
            app.CompImsCol.Tooltip = {'Add color to component fractional images'};
            app.CompImsCol.Text = 'Color components';
            app.exportratiometric = uicheckbox(app.UnmixTab);
            app.exportratiometric.ValueChangedFcn = createCallbackFcn(app, @ExportratiometricValueChanged, true);
            app.exportratiometric.Tooltip = {'Export ratiometric image of pair of components'};
            app.exportratiometric.Text = 'Export ratiometric';
            app.ComponentvsLabel = uilabel(app.UnmixTab);
            app.ComponentvsLabel.Text = 'Component        vs';
            app.comp1 = uieditfield(app.UnmixTab, 'numeric');
            app.comp1.ValueDisplayFormat = '%.0f';
            app.comp1.Tooltip = {'Numerator component N/(N+D)'};
            app.comp2 = uieditfield(app.UnmixTab, 'numeric');
            app.comp2.ValueDisplayFormat = '%.0f';
            app.comp2.Tooltip = {'Denominator sumand component N/(N+D)'};
            app.ratiocsv = uicheckbox(app.UnmixTab);
            app.ratiocsv.ValueChangedFcn = createCallbackFcn(app, @CompratiocsvValueChanged, true);
            app.ratiocsv.Tooltip = {'Export pixel ratio as spreadsheet'};
            app.ratiocsv.Text = 'Export ratio csv';
            app.singleimage = uicheckbox(app.UnmixTab);
            app.singleimage.ValueChangedFcn = createCallbackFcn(app, @singleimageValueChanged, true);
            app.singleimage.Tooltip = {'Join all images into single file '};
            app.singleimage.Text = 'Single image';
            app.singleimage.Value = true;
            app.addmargins = uicheckbox(app.UnmixTab);
            app.addmargins.Tooltip = {'Add margins between images in output'};
            app.addmargins.Text = 'Add margins';
            app.includeint = uicheckbox(app.UnmixTab);
            app.includeint.Tooltip = {'Add photon intensity column in spreadsheet'};
            app.includeint.Text = 'Include intensity';
            app.groupfile = uicheckbox(app.UnmixTab);
            app.groupfile.ValueChangedFcn = createCallbackFcn(app, @groupfileValueChanged, true);
            app.groupfile.Tooltip = {'Group images by file or by component'};
            app.groupfile.Text = 'Group by file';
            app.mergecomps = uicheckbox(app.UnmixTab);
            app.mergecomps.Tooltip = {'Export color merge of component images'};
            app.mergecomps.Text = 'Merge comps.';
            app.SourceButtonGroup = uibuttongroup(app.UnmixTab);
            app.SourceButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SourceButtonGroupSelectionChanged, true);
            app.SourceButtonGroup.Tooltip = {'Define pure components for unmixing'};
            app.SourceButtonGroup.Title = 'Source';
            app.InputButton = uiradiobutton(app.SourceButtonGroup);
            app.InputButton.Tooltip = {'Use cursor positions for pure components (max 3)'};
            app.InputButton.Text = 'Input';
            app.InputButton.Value = true;
            app.FileButton = uiradiobutton(app.SourceButtonGroup);
            app.FileButton.Tooltip = {'Use loaded files for pure components'};
            app.FileButton.Text = 'File';
            app.CursorButton = uiradiobutton(app.SourceButtonGroup);
            app.CursorButton.Tooltip = {'Use point-and-click cursors (first 3 max, 1harmonic limitation)'};
            app.CursorButton.Text = 'Cursor';
            app.ComponentTree = uitree(app.UnmixTab, 'checkbox');
            app.ComponentTree.Visible = 'off';
            % Assign Checked Nodes
            app.ComponentTree.CheckedNodesChangedFcn = createCallbackFcn(app, @ComponentTreeCheckedNodesChanged, true);
            app.ClusterTab = uitab(app.TabGroup);
            app.ClusterTab.Title = 'Cluster';
            app.TrainGMMButton = uibutton(app.ClusterTab, 'push');
            app.TrainGMMButton.ButtonPushedFcn = createCallbackFcn(app, @GMMTrainButtonPushed, true);
            app.TrainGMMButton.Tooltip = {'Train GMM with current data'};
            app.TrainGMMButton.Text = 'Train GMM';
            app.ClearGMMButton = uibutton(app.ClusterTab, 'push');
            app.ClearGMMButton.ButtonPushedFcn = createCallbackFcn(app, @GMMclearButtonPushed, true);
            app.ClearGMMButton.Tooltip = {'Clear current GMM'};
            app.ClearGMMButton.Text = 'Clear GMM';
            app.dispStyle = uibuttongroup(app.ClusterTab);
            app.dispStyle.SelectionChangedFcn = createCallbackFcn(app, @GMMdisp2StyleChanged, true);
            app.dispStyle.Title = 'Phasor display style';
            app.conttogether = uiradiobutton(app.dispStyle);
            app.conttogether.Tooltip = {'Draw contours of full GMM'};
            app.conttogether.Text = 'Contour of model';
            app.conttogether.Value = true;
            app.contseparate = uiradiobutton(app.dispStyle);
            app.contseparate.Tooltip = {'Draw contours for each cluster'};
            app.contseparate.Text = 'Contour separate clusters';
            app.elipse = uiradiobutton(app.dispStyle);
            app.elipse.Tooltip = {'Draw elipses around each cluster'};
            app.elipse.Text = 'Elipse with          % of data';
            app.conf = uieditfield(app.dispStyle, 'numeric');
            app.conf.Limits = [1 100];
            app.conf.ValueDisplayFormat = '%.0f';
            app.conf.ValueChangedFcn = createCallbackFcn(app, @GMMconfValueChanged, true);
            app.conf.Tooltip = {'Percentage of points inside elipse'};
            app.conf.Value = 68;
            app.dispStyle2 = uibuttongroup(app.ClusterTab);
            app.dispStyle2.SelectionChangedFcn = createCallbackFcn(app, @GMMdispStyleChanged, true);
            app.dispStyle2.Title = 'Image display style';
            app.hard = uiradiobutton(app.dispStyle2);
            app.hard.Tooltip = {'Color pixels by cluster with max probability'};
            app.hard.Text = 'Hard assign';
            app.prob = uiradiobutton(app.dispStyle2);
            app.prob.Tooltip = {'Color pixels weighted by posterior cluster probability'};
            app.prob.Text = 'Probability assign';
            app.prob.Value = true;
            app.ApplyGMMButton = uibutton(app.ClusterTab, 'push');
            app.ApplyGMMButton.ButtonPushedFcn = createCallbackFcn(app, @GMMApplyButtonPushed, true);
            app.ApplyGMMButton.Tooltip = {'Apply trained GMM'};
            app.ApplyGMMButton.Text = 'Apply GMM';
            app.blend = uicheckbox(app.ClusterTab);
            app.blend.ValueChangedFcn = createCallbackFcn(app, @GMMblendeValueChanged, true);
            app.blend.Tooltip = {'Soften color borders'};
            app.blend.Text = 'Blending          %';
            app.blendamount = uieditfield(app.ClusterTab, 'numeric');
            app.blendamount.Limits = [1 100];
            app.blendamount.ValueDisplayFormat = '%.0f';
            app.blendamount.ValueChangedFcn = createCallbackFcn(app, @GMMblendamountValueChanged, true);
            app.blendamount.Tooltip = {'Blending filter size'};
            app.blendamount.Value = 5;
            app.gmmexport = uicheckbox(app.ClusterTab);
            app.gmmexport.ValueChangedFcn = createCallbackFcn(app, @GMMexportValueChanged, true);
            app.gmmexport.Tooltip = {'In export tab use checkboxes to export cluster-colored images'};
            app.gmmexport.Text = 'Override magnitude for export';
            app.ExportTab = uitab(app.TabGroup);
            app.ExportTab.Title = 'Export';
            app.BrowseResults = uibutton(app.ExportTab, 'push');
            app.BrowseResults.ButtonPushedFcn = createCallbackFcn(app, @BrowseResultsButtonPushed, true);
            app.BrowseResults.Tooltip = {'Set output folder location'};
            app.BrowseResults.Text = 'Output Folder:';
            app.ExportLRButton = uibutton(app.ExportTab, 'push');
            app.ExportLRButton.ButtonPushedFcn = createCallbackFcn(app, @ExportLRButtonPushed, true);
            app.ExportLRButton.Tooltip = {'Export data current image sizes'};
            app.ExportLRButton.Text = 'Export LR';
            app.ExportHRButton = uibutton(app.ExportTab, 'push');
            app.ExportHRButton.ButtonPushedFcn = createCallbackFcn(app, @ExportHRButtonPushed, true);
            app.ExportHRButton.Tooltip = {'Export data using raw image sizes'};
            app.ExportHRButton.Text = 'Export HR';
            app.ExpImWinButton = uibutton(app.ExportTab, 'push');
            app.ExpImWinButton.ButtonPushedFcn = createCallbackFcn(app, @ExpImWinButtonPushed, true);
            app.ExpImWinButton.Tooltip = {'Export current image window as image'};
            app.ExpImWinButton.Text = 'Exp.Im.Win.';
            app.ExpPhWinButton = uibutton(app.ExportTab, 'push');
            app.ExpPhWinButton.ButtonPushedFcn = createCallbackFcn(app, @ExpPhWinButtonPushed, true);
            app.ExpPhWinButton.Tooltip = {'Export current phasor panel as image'};
            app.ExpPhWinButton.Text = 'Exp.Ph.Win.';
            app.ExDecay = uicheckbox(app.ExportTab);
            app.ExDecay.Tooltip = {'Export a file of average frame intensity'};
            app.ExDecay.Text = 'Decay as';
            app.DeacayFileDropDown = uidropdown(app.ExportTab);
            app.DeacayFileDropDown.Items = {'csv', 'txt'};
            app.DeacayFileDropDown.Tooltip = {'File format'};
            app.DeacayFileDropDown.Value = 'csv';
            app.Cbar = uicheckbox(app.ExportTab);
            app.Cbar.Tooltip = {'Generate a colorbar based on the phasor magnitude measured'};
            app.Cbar.Text = 'Print colorbar';
            app.PhRb = uicheckbox(app.ExportTab);
            app.PhRb.Tooltip = {'Generate magnitude background color image'};
            app.PhRb.Text = 'Save background image';
            app.Expwithax = uicheckbox(app.ExportTab);
            app.Expwithax.Tooltip = {'Export each phasor plot without the axes'};
            app.Expwithax.Text = 'Phasor with axes';
            app.Expwithax.Value = true;
            app.Expptrans = uicheckbox(app.ExportTab);
            app.Expptrans.ValueChangedFcn = createCallbackFcn(app, @ExpptransValueChanged, true);
            app.Expptrans.Tooltip = {'Export transparent phasor plot background'};
            app.Expptrans.Text = 'Phasor plot alpha';
            app.Expvec = uicheckbox(app.ExportTab);
            app.Expvec.ValueChangedFcn = createCallbackFcn(app, @ExpvecValueChanged, true);
            app.Expvec.Tooltip = {'Export phasor plot as vector images'};
            app.Expvec.Text = 'Phasor plot vector file';
            app.ExPhasor = uicheckbox(app.ExportTab);
            app.ExPhasor.Tooltip = {'Export each file''s phasor plot'};
            app.ExPhasor.Text = 'Phasor plot as';
            app.ExPhasor.Value = true;
            app.ExportPhasorFileDropDown = uidropdown(app.ExportTab);
            app.ExportPhasorFileDropDown.Items = {'png', 'jpg', 'tif', 'emf', 'pdf'};
            app.ExportPhasorFileDropDown.ValueChangedFcn = createCallbackFcn(app, @ExportPhasorFileDropDownValueChanged, true);
            app.ExportPhasorFileDropDown.Tooltip = {'Image file format'};
            app.ExportPhasorFileDropDown.Value = 'png';
            app.ReLab = uilabel(app.ExportTab);
            app.ReLab.Text = '*no folder selected*';
            app.ExInt = uicheckbox(app.ExportTab);
            app.ExInt.Tooltip = {'Export Intensity Image'};
            app.ExInt.Text = 'Intensity image';
            app.ExInt.Value = true;
            app.ExportImageFileDropDown = uidropdown(app.ExportTab);
            app.ExportImageFileDropDown.Items = {'png', 'jpg', 'tif'};
            app.ExportImageFileDropDown.Tooltip = {'Image file format'};
            app.ExportImageFileDropDown.Value = 'png';
            app.ExMagV = uicheckbox(app.ExportTab);
            app.ExMagV.Tooltip = {'Export a file of average frame intensity'};
            app.ExMagV.Text = 'Magnitude values as';
            app.MagValFmtDropDown = uidropdown(app.ExportTab);
            app.MagValFmtDropDown.Items = {'csv', 'txt'};
            app.MagValFmtDropDown.Tooltip = {'File format'};
            app.MagValFmtDropDown.Value = 'csv';
            app.ExMagH = uicheckbox(app.ExportTab);
            app.ExMagH.Tooltip = {'Export intensity image colored by magnitude'};
            app.ExMagH.Text = 'Magnitude-colored image';
            app.ExMagS = uicheckbox(app.ExportTab);
            app.ExMagS.Tooltip = {'Export intensity image colored by magnitude times number of photons'};
            app.ExMagS.Text = 'Magnitude-colored x intensity';
            app.ExMagS.Value = true;
            app.ExCurH = uicheckbox(app.ExportTab);
            app.ExCurH.Tooltip = {'Export cursor-colored image'};
            app.ExCurH.Text = 'Cursor-colored image';
            app.ExCurS = uicheckbox(app.ExportTab);
            app.ExCurS.Tooltip = {'Export cursor-colored image times number of photons'};
            app.ExCurS.Text = 'Cursor-colored x intensity';
            app.Combine = uicheckbox(app.ExportTab);
            app.Combine.ValueChangedFcn = createCallbackFcn(app, @CombineValueChanged, true);
            app.Combine.Tooltip = {'Combine all exports for each file into single image'};
            app.Combine.Text = 'Combine into single image';
            app.Combine.Value = true;
            app.groupfiles = uicheckbox(app.ExportTab);
            app.groupfiles.Tooltip = {'Generate single image with all loaded files'};
            app.groupfiles.Text = 'Group all combined images';
            app.allasLabel = uilabel(app.ExportTab);
            app.allasLabel.Text = 'all as';
            app.CalibHolder = uipanel(app.GSLabUIFigure);
            app.CalibrationPanel = uipanel(app.CalibHolder);
            app.calTable = uitable(app.CalibrationPanel);
            app.calTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'};
            app.calTable.RowName = {};
            app.calTable.ColumnEditable = [true true false];
            app.calTable.CellEditCallback = createCallbackFcn(app, @CalibTableCellEdit, true);
            app.calTable.Multiselect = 'off';
            app.ClearButton = uibutton(app.CalibrationPanel, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @CalibClearButtonPushed, true);
            app.ClearButton.Tooltip = {'Clear all calibration'};
            app.ClearButton.Text = 'Clear';
            app.manualcalibpanel = uipanel(app.CalibrationPanel);
            app.manualcalibpanel.Title = 'gg';
            app.phaS = uispinner(app.manualcalibpanel);
            app.phaS.Step = 0.01;
            app.phaS.Limits = [-3.1416 3.1416];
            app.phaS.ValueDisplayFormat = '%.3f';
            app.phaS.ValueChangedFcn = createCallbackFcn(app, @phaSValueChanged, true);
            app.modS = uispinner(app.manualcalibpanel);
            app.modS.Step = 0.01;
            app.modS.Limits = [0.001 Inf];
            app.modS.ValueDisplayFormat = '%.3f';
            app.modS.ValueChangedFcn = createCallbackFcn(app, @modSValueChanged, true);
            app.modS.Value = 1;
            app.phaL = uilabel(app.manualcalibpanel);
            app.phaL.Text = 'Phase shift';
            app.modL = uilabel(app.manualcalibpanel);
            app.modL.Text = 'Mod factor';
            app.DoCalib = uicheckbox(app.CalibHolder);
            app.DoCalib.ValueChangedFcn = createCallbackFcn(app, @DoCalibValueChanged, true);
            app.DoCalib.Tooltip = {'Apply Calibration'};
            app.DoCalib.Text = 'Apply';
            app.LT = uieditfield(app.CalibHolder, 'numeric');
            app.LT.ValueChangedFcn = createCallbackFcn(app, @CalibLifetimeValueChanged, true);
            app.LT.Tooltip = {'Measurement lifetime value'};
            app.CalibrationLifetimensLabel = uilabel(app.CalibHolder);
            app.CalibrationLifetimensLabel.Text = 'Calibration Lifetime (ns)';
            app.Calibrationtype = uibuttongroup(app.CalibHolder);
            app.Calibrationtype.SelectionChangedFcn = createCallbackFcn(app, @CalibrationtypeSelectionChanged, true);
            app.Filecalib = uiradiobutton(app.Calibrationtype);
            app.Filecalib.Tooltip = {'Use reference measurement'};
            app.Filecalib.Text = 'Files';
            app.Filecalib.Value = true;
            app.Manualcalib = uiradiobutton(app.Calibrationtype);
            app.Manualcalib.Tooltip = {'Input calibration'};
            app.Manualcalib.Text = 'Manual';
            app.AxisContainer = uipanel(app.GSLabUIFigure);
            app.TabEixos = uiaxes(app.AxisContainer);
            app.TabEixos.XTick = [];
            app.TabEixos.YTick = [];
            app.colpost = uicheckbox(app.GSLabUIFigure);
            app.colpost.Text = 'Color by probability';
            app.colpost.Value = true;
            app.Suman = uicheckbox(app.GSLabUIFigure);
            app.Suman.Text = 'Phasor Export Issue';
            app.ContextMenu = uicontextmenu(app.GSLabUIFigure);
            app.CalibrationFileMenu = uimenu(app.ContextMenu);
            app.CalibrationFileMenu.MenuSelectedFcn = createCallbackFcn(app, @CalibrationFileMenuSelected, true);
            app.CalibrationFileMenu.Text = 'Calibration File';
            app.Load = uimenu(app.ContextMenu);
            app.Load.Text = 'Load';
            app.FLAMEcalibration = uimenu(app.Load);
            app.FLAMEcalibration.MenuSelectedFcn = createCallbackFcn(app, @CalibrationLoadMenuSelected, true);
            app.FLAMEcalibration.Text = 'FLAME Calibration';
            app.LoadTIFSequenceContextMenu = uimenu(app.Load);
            app.LoadTIFSequenceContextMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadTIFSequenceContextMenuSelected, true);
            app.LoadTIFSequenceContextMenu.Text = 'TIF Sequence';
            app.LoadFLAMErawContextMenu = uimenu(app.Load);
            app.LoadFLAMErawContextMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadFLAMErawContextMenuSelected, true);
            app.LoadFLAMErawContextMenu.Text = 'FLAME raw';
            app.LoadFALCONSnGContextMenu = uimenu(app.Load);
            app.LoadFALCONSnGContextMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadFALCONSnGContextMenuSelected, true);
            app.LoadFALCONSnGContextMenu.Text = 'FALCON SnG';
            app.LoadZeisslsmContextMenu = uimenu(app.Load);
            app.LoadZeisslsmContextMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadZeisslsmContextMenuSelected, true);
            app.LoadZeisslsmContextMenu.Text = ' Zeiss lsm';
            app.LoadSimFCSrefContextMenu = uimenu(app.Load);
            app.LoadSimFCSrefContextMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadSimFCSrefContextMenuSelected, true);
            app.LoadSimFCSrefContextMenu.Text = 'SimFCS ref';
            app.SavedSessionMenu = uimenu(app.Load);
            app.SavedSessionMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadSavedSessionMenuSelected, true);
            app.SavedSessionMenu.Text = 'Saved Session';
            app.SaveMenu = uimenu(app.ContextMenu);
            app.SaveMenu.Text = 'Save';
            app.asSimFCSrefMenu = uimenu(app.SaveMenu);
            app.asSimFCSrefMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveSimFCSrefContextSelected, true);
            app.asSimFCSrefMenu.Text = 'as SimFCS ref';
            app.CurrentSessionMenu = uimenu(app.SaveMenu);
            app.CurrentSessionMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveCurrentSessionMenuSelected, true);
            app.CurrentSessionMenu.Text = 'Current Session';
            app.MoveMenu = uimenu(app.ContextMenu);
            app.MoveMenu.Separator = 'on';
            app.MoveMenu.Text = 'Move';
            app.TopMenu = uimenu(app.MoveMenu);
            app.TopMenu.MenuSelectedFcn = createCallbackFcn(app, @MoveTopMenuSelected, true);
            app.TopMenu.Tooltip = {'Move selected to top'};
            app.TopMenu.Text = 'Top';
            app.UpMenu = uimenu(app.MoveMenu);
            app.UpMenu.MenuSelectedFcn = createCallbackFcn(app, @MoveUpMenuSelected, true);
            app.UpMenu.Tooltip = {'Move selected one up'};
            app.UpMenu.Text = 'Up';
            app.DownMenu = uimenu(app.MoveMenu);
            app.DownMenu.MenuSelectedFcn = createCallbackFcn(app, @MoveDownMenuSelected, true);
            app.DownMenu.Tooltip = {'Move selected one down'};
            app.DownMenu.Text = 'Down';
            app.BotMenu = uimenu(app.MoveMenu);
            app.BotMenu.MenuSelectedFcn = createCallbackFcn(app, @MoveBotMenuSelected, true);
            app.BotMenu.Tooltip = {'Move selected to bot'};
            app.BotMenu.Text = 'Bot';
            app.EvenOddMenu = uimenu(app.MoveMenu);
            app.EvenOddMenu.MenuSelectedFcn = createCallbackFcn(app, @MoveEvenOdd, true);
            app.EvenOddMenu.Tooltip = {'List even first and odd after (deintercalate)'};
            app.EvenOddMenu.Text = 'Even/Odd';
            app.CheckMenu = uimenu(app.ContextMenu);
            app.CheckMenu.Text = 'Check';
            app.AllNoneMenu = uimenu(app.CheckMenu);
            app.AllNoneMenu.MenuSelectedFcn = createCallbackFcn(app, @CheckedAllNoneMenuSelected, true);
            app.AllNoneMenu.Separator = 'on';
            app.AllNoneMenu.Text = 'All/None';
            app.InvertMenu = uimenu(app.CheckMenu);
            app.InvertMenu.MenuSelectedFcn = createCallbackFcn(app, @CheckedInvertMenuSelected, true);
            app.InvertMenu.Tooltip = {'Check unchecked and uncheck checked'};
            app.InvertMenu.Text = 'Invert';
            app.AlternateMenu = uimenu(app.CheckMenu);
            app.AlternateMenu.MenuSelectedFcn = createCallbackFcn(app, @CheckAlternateMenuSelected, true);
            app.AlternateMenu.Text = 'Alternate';
            app.ClearMenu = uimenu(app.ContextMenu);
            app.ClearMenu.Text = 'Clear';
            app.AllMenu = uimenu(app.ClearMenu);
            app.AllMenu.MenuSelectedFcn = createCallbackFcn(app, @ClearAllContextSelected, true);
            app.AllMenu.Text = 'All';
            app.UncheckedMenu = uimenu(app.ClearMenu);
            app.UncheckedMenu.MenuSelectedFcn = createCallbackFcn(app, @ClearUncheckedMenuSelected, true);
            app.UncheckedMenu.Text = 'Unchecked';
            % Assign app.ContextMenu
            app.Tree.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.GSLabUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GSLab

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GSLabUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GSLabUIFigure)
        end
    end
end